classdef DAT_20221011 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                       matlab.ui.Figure
        Button_help                matlab.ui.control.Button
        Button_tools_Spectroscopy  matlab.ui.control.Button
        Button_toolkit_on          matlab.ui.control.Button
        Button_file_whatsin        matlab.ui.control.Button
        Reports_Status             matlab.ui.control.TextArea
        MessageLabel               matlab.ui.control.Label
        Reports_files_List         matlab.ui.control.TextArea
        SelectedfileLabel          matlab.ui.control.Label
        Button_tools_Statistics    matlab.ui.control.Button
        Button_tools_Plot          matlab.ui.control.Button
        Button_files_Upload        matlab.ui.control.Button
        Button_files_Select        matlab.ui.control.Button
    end

    % properties shared within the app 
    % ---------------------------------------------------------------------
    properties (Access = private)
        % -----------------------------------------------------------------
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        import_file_names % imported files - name
        import_file_path % imported files - path
        dataset % imported datasets 
        dataset_bckup % shadow copy of imported datasets 
        list_expected % expected variables 
        tools % app features 
        bigTable % file summary table 
        list_2ignore % list of variables to ignore in building plotter 
        list_special_vids % ----
        help % help pages 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % -----------------------------------------------------------------
    end % end of properties 
    % ---------------------------------------------------------------------
    methods (Access = private)
        % -----------------------------------------------------------------
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %	                SPECIAL LISTs
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % -----------------------------------------------------------------
        function app = list_2ignore_do(app) 
            % variables to ignore
            %app.list_2ignore = 'empty';
            app.list_2ignore = "Unix Timestamp, " + ...
                "batch id, " + ...
                "batch name, " + ...
                "eyecon histogram - numeric max, " + ...
                "eyecon histogram - numeric min, " + ...
                "eyecon histogram - numeric nbins, " + ...
                "eyecon histogram - volumetric max, " + ...
                "eyecon histogram - volumetric min, " + ...
                "eyecon histogram - volumetric nbins, " + ...
                "eyecon image path, " + ...
                "fluid bed alarm occured ac, " + ...
                "fluid bed process mode on is ready ac, " + ...
                "fluid bed process mode on sp ac, " + ...
                "fluid bed process mode on sp do not use, " + ...
                "fluid bed remote on ac, " + ...
                "fluid bed remote on is ready ac, " + ...
                "fluid bed shake mode sp, " + ...
                "fluid bed shake mode sp ac, " + ...
                "fluid bed watchdog counter from plc ac, " + ...
                "fluid bed watchdog counter to plc sp, " + ...
                "fluid bed watchdog from plc ac, " + ...
                "fluid bed watchdog to plc sp, " + ...
                "Nozzle airflow sensor units," + ...
                "results path" + ...
                "scales connection alive," + ...
                "thermoalert connection state,";
        end % end function 
        % -----------------------------------------------------------------
        function app = list_expected_do(app) 
            % default tags 
            app.list_expected = struct();
            item = 1;
            app.list_expected(item).tag{1} = 'Time';
            app.list_expected(item).tag{2} = 'Stamp';
            app.list_expected(item).description = 'This is a human readable time stamp.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'Unix';
            app.list_expected(item).tag{2} = 'Timestamp';
            app.list_expected(item).description = 'This is a machine readable time stamp. To convert to human readable time use: datestr(x/86400 + 719529)';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'batch';
            app.list_expected(item).tag{2} = 'id';
            app.list_expected(item).description = 'This is the batch id generated by system.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'batch';
            app.list_expected(item).tag{2} = 'name';
            app.list_expected(item).description = 'This is the batch name supplied by user once running an experiment.';
            item = item + 1;%
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'Dn';
            app.list_expected(item).description = 'The numeric density as measured by eyecon.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'Dv';
            app.list_expected(item).description = 'The volumetric density as measured by eyecon.';
            item = item + 1;%
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'hg';
            app.list_expected(item).tag{3} = 'num';
            app.list_expected(item).tag{4} = 'max';
            app.list_expected(item).description = 'The maximum value in the numeric histogram as measured by eyecon.';
            item = item + 1;%
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'hg';
            app.list_expected(item).tag{3} = 'num';
            app.list_expected(item).tag{4} = 'min';
            app.list_expected(item).description = 'The minimum value in the numeric histogram as measured by eyecon.';
 	        item = item + 1;%
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'hg';
            app.list_expected(item).tag{3} = 'num';
            app.list_expected(item).tag{4} = 'nbins';
            app.list_expected(item).description = 'The number of bins used in the numeric histogram as measured by eyecon.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'hg';
            app.list_expected(item).tag{3} = 'vol';
            app.list_expected(item).tag{4} = 'max';
            app.list_expected(item).description = 'The maximum value in the volumetric histogram as measured by eyecon.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'hg';
            app.list_expected(item).tag{3} = 'vol';
            app.list_expected(item).tag{4} = 'min';
            app.list_expected(item).description = 'The minimum value in the volumetric histogram as measured by eyecon.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'hg';
            app.list_expected(item).tag{3} = 'vol';
            app.list_expected(item).tag{4} = 'nbins';
            app.list_expected(item).description = 'The number of bins used in the volumetric histogram as measured by eyecon.';
            item = item + 1;%
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'image';
            app.list_expected(item).tag{3} = 'path';
            app.list_expected(item).description = 'The path to image file as captured by eyecon?';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'numeric';
            app.list_expected(item).tag{3} = 'histogram';
            app.list_expected(item).description = 'The numeric histogram as measured by eyecon.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'eyecon';
            app.list_expected(item).tag{2} = 'volumetric';
            app.list_expected(item).tag{3} = 'histogram';
            app.list_expected(item).description = 'The volumetric histogram as measured by eyecon.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'airflow';
            app.list_expected(item).tag{3} = 'bypass';
            app.list_expected(item).description = 'The bypass airflow in fluid bed.';
            item = item + 1;
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'airflow';
            app.list_expected(item).description = 'The airflow in fluid bed.';
            item = item + 1;%
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'alarm';
            app.list_expected(item).tag{3} = 'occured';
            app.list_expected(item).description = 'If alarm occured, yes (1) or no (0).';
	        item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'atomising';
            app.list_expected(item).tag{3} = 'pressure';
            app.list_expected(item).description = 'The atomising pressure in fluid bed.';
	        item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'exhaust';
            app.list_expected(item).tag{3} = 'temp';
            app.list_expected(item).description = 'The exhaust temperature in fluid bed.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'filter';
            app.list_expected(item).tag{3} = 'pressure';
            app.list_expected(item).tag{4} = 'differential';
            app.list_expected(item).description = 'The differential pressure in fluid bed.';
            item = item + 1;%
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'inlet';
            app.list_expected(item).tag{3} = 'air';
            app.list_expected(item).tag{4} = 'temp';
            app.list_expected(item).description = 'The inlet airflow temperature in fluid bed.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'main';
            app.list_expected(item).tag{3} = 'heater';
            app.list_expected(item).tag{4} = 'air';
            app.list_expected(item).tag{5} = 'temp';
            app.list_expected(item).description = 'The main heater airflow temperature in fluid bed.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'main';
            app.list_expected(item).tag{3} = 'heater';
            app.list_expected(item).tag{4} = 'temp';
            app.list_expected(item).description = 'The main heater airflow temperature in fluid bed.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'phase';
            app.list_expected(item).description = 'NONE';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'process';
            app.list_expected(item).tag{3} = 'mode';
            app.list_expected(item).description = 'NONE';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'product';
            app.list_expected(item).tag{3} = 'pressure';
            app.list_expected(item).tag{4} = 'differential';
            app.list_expected(item).description = 'The product differential pressure in fluid bed.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'product';
            app.list_expected(item).tag{3} = 'temp';
            app.list_expected(item).tag{4} = 'ac';
            app.list_expected(item).description = 'The product temperature in fluid bed.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'remote';
            app.list_expected(item).tag{3} = 'on';
            app.list_expected(item).description = 'Remote access to fluid bed is on (1) or off (0).';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'shake';
            app.list_expected(item).tag{3} = 'mode';
            app.list_expected(item).description = 'NONE';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'shake';
            app.list_expected(item).tag{3} = 'pause';
            app.list_expected(item).description = 'The shake pause time to remove material from the filters, seconds.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'shake';
            app.list_expected(item).tag{3} = 'time';
            app.list_expected(item).description = 'The shake time to remove material from the filters, seconds.';
            item = item + 1;% 62;
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'spray';
            app.list_expected(item).tag{3} = 'pump';
            app.list_expected(item).description = 'The spray pump.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'fluid_bed';
            app.list_expected(item).tag{2} = 'watchdog';
            app.list_expected(item).description = 'NONE';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'level';
            app.list_expected(item).description = 'NONE';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'log';
            app.list_expected(item).description = 'NONE';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'multieye';
            app.list_expected(item).tag{2} = 'ch';
            app.list_expected(item).tag{3} = 'absorbance';
            app.list_expected(item).description = 'The absorbance as measured by multieye.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'multieye';
            app.list_expected(item).tag{2} = 'ch';
            app.list_expected(item).tag{3} = 'bl';
            app.list_expected(item).tag{4} = 'corr_bsorbance';
            app.list_expected(item).description = 'The corrected absorbance as measured by multieye.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'multieye';
            app.list_expected(item).tag{2} = 'ch';
            app.list_expected(item).tag{3} = 'intensity';
            app.list_expected(item).description = 'The intensity as measured by multieye.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'multieye';
            app.list_expected(item).tag{2} = 'ch';
            app.list_expected(item).tag{3} = 'reflectance';
            app.list_expected(item).description = 'The reflectance as measured by multieye.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'Nozzle';
            app.list_expected(item).tag{2} = 'airflow';
            app.list_expected(item).tag{3} = 'sensor';
            app.list_expected(item).description = 'The nozzle airflow in fluid bed probed by sensor.';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'results';
            app.list_expected(item).tag{2} = 'path';
            app.list_expected(item).description = 'NONE';
	        item = item + 1;% 
            app.list_expected(item).tag{1} = 'scales';
            app.list_expected(item).tag{2} = 'connection';
            app.list_expected(item).tag{3} = 'alive';
            app.list_expected(item).description = 'Connection is alive (1) or not (0).';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'scales';
            app.list_expected(item).tag{2} = 'start';
            app.list_expected(item).description = 'Scales is started (1) or not (0).';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'scales';
            app.list_expected(item).tag{2} = 'weight';
            app.list_expected(item).description = 'Measured weight.';
	        item = item + 1;% 
            app.list_expected(item).tag{1} = 'thermoalert';
            app.list_expected(item).tag{2} = 'connection';
            app.list_expected(item).tag{3} = 'state';
            app.list_expected(item).description = 'Connection is alive (1) or not (0).';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'thermoalert';
            app.list_expected(item).tag{2} = 'humidity';
            app.list_expected(item).description = 'Humidity';
            item = item + 1;% 
            app.list_expected(item).tag{1} = 'thermoalert';
            app.list_expected(item).tag{2} = 'temperature';
            app.list_expected(item).description = 'Temperature';
        end % end function 
        % -----------------------------------------------------------------        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %	                MAIN APP CONTROLs
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % -----------------------------------------------------------------
        function closeapp(app) 
            % termonating the app ?
            selection = uiconfirm(app.main,'Close App?',...
                'Confirm Close',...
                        'Icon','warning');
            switch selection
                case 'OK' % closes the app
                    close all force;
                    clc;
                    delete(app);
                case 'Cancel' % do nothing, return to app
                    app.Reports_Status.Value = 'User canceled closing app, App retuned to normal state. App ready to use.';
                    return
            end % end switch 
        end % end function 
        % -----------------------------------------------------------------
        function app = activate_toolkits(app) 
            % activates toolkits after ignore list is updated 
            app = clean(app); 
            app = features_plottool(app); 
            app = create_file_var(app);
            app = assign_node_data(app);
            app = features_nirtool(app);
            app.Button_tools_Plot.Enable = 'on';
            app.Button_tools_Spectroscopy.Enable = 'on';
            app.Reports_Status.Value = 'App ready to use.';
        end % end function  
        %------------------------------------------------------------------
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %	                FILE OPERATIONs
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % -----------------------------------------------------------------
        function [app, text] = files_what_types(app, operation) 
            % file types
            xlsx = 0;
            csv = 0;
            if iscell(app.import_file_names)
                [~, thismanyfiles] = size(app.import_file_names);
                for file = 1 : thismanyfiles
                    if contains(app.import_file_names{1,file}, '.xlsx')
                        xlsx = xlsx + 1;
                    end % end if
                    if contains(app.import_file_names{1,file}, '.csv')
                        csv = csv + 1;
                    end % end if 
                end % end for
                if (csv > 0) && (xlsx > 0)
                    text = ...
                        [operation, ' ', ...
                        num2str(csv), ' csv file(s) and ', ...
                        num2str(xlsx), ' xlsx file(s).',];
                end % end if 
                if (csv == 0) && (xlsx > 0)
                    text = ...
                        [operation, ' ', ...
                        num2str(xlsx), ' xlsx file(s).',];
                end % end if 
                if (csv > 0) && (xlsx == 0)
                    text = ...
                        [operation, ' ', ...
                        num2str(csv), ' csv file(s).',];
                end % end if 
            else 
                if contains(app.import_file_names, '.xlsx')
                    text = ...
                    [operation, ' 1 xlsx file.'];
                end % end if 
                if contains(app.import_file_names, '.csv')
                    text = ...
                    [operation, ' 1 csv file.'];
                end % end if 
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function [app, selected_file_list_text] = files_list_filenames(app) 
            % Reports_files_List
            if iscell(app.import_file_names)
                [~, thismanyfiles] = size(app.import_file_names);
                selected_file_list_text = cell(thismanyfiles,1);
                for file = 1 : thismanyfiles
                    selected_file_list_text{file, 1} = ...
                        app.import_file_names{1, file};
                end  % end for 
            else
                selected_file_list_text = string(app.import_file_names);
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function [app, text] = files_select(app) 
            % Button_files_Select
            app.Reports_Status.Value = ...
                'Waiting for user to select file(s) ....';
            [app.import_file_names, app.import_file_path,~] = ...
                uigetfile({'*.xlsx;*.csv',...
                'Excel files (*.xlsx, *.csv)'}, ...
                'Select .xlsx file(s) to import ....', ...
                'MultiSelect', 'on');
            if isequal(app.import_file_names, 0)
                text = ...
                    ['User canceled file(s) selection. ...' ...
                    'Click "Add file(s)" to add file(s) for import.'];
            else
                [app, text] = files_what_types(app, 'Selected');
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function app = files_upload_do(app) 
            % do Button_files_Upload
            if iscell(app.import_file_names)
                [~, thismanyfiles] = size(app.import_file_names);
                for file = 1 : thismanyfiles
                    [~, ~, app.dataset{1, file}] = ...
                        xlsread([app.import_file_path, ...
                        app.import_file_names{1,file}]);
                end % end for 
            else 
                [~, ~, app.dataset{1, 1}] = ...
                    xlsread([app.import_file_path, ...
                    app.import_file_names]);
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function app = files_upload(app) 
            % Button_files_Upload
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
                app.dataset{1, thismanyfiles} = struct();
            else
                thismanyfiles = 1;
                app.dataset = cell(1);
            end % end if 
            bar = uifigure;
            bar.Position = [500 500 500 125];
            bar.WindowStyle = 'modal';
            inbar = uiprogressdlg(bar,...
                'Title',['Importing ', ...
                    num2str(thismanyfiles), ...
                    ' file(s) ....'],...
                'Indeterminate','on');
            drawnow;
            app = files_upload_do(app);
            close(inbar);
            close(bar);  
        end % end function 
        % -----------------------------------------------------------------
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %	                APP FEATUREs  
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % -----------------------------------------------------------------
        function app = create_file_var(app) 
            % creates file(s) and variable(s) list
            app = list_2ignore_do(app);
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
            else
                thismanyfiles = 1;
            end % end if 
            for file = 1 : thismanyfiles % listing filenames and variables .... 
                [~ , variables] = size(app.dataset{1,file}); 
                fid = ['db_f_', num2str(file)];
                app.tools{1}.(fid) = uitreenode(app.tools{1}.database);
                if thismanyfiles == 1
                    app.tools{1}.(fid).Text = ...
                        ['Filename: ', app.import_file_names];
                else  
                    app.tools{1}.(fid).Text = ...
                        ['Filename: ', app.import_file_names{1, file}];
                end % end if 
                for variable = 1 : variables 
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                        vid = [fid,'_v_' num2str(variable)];
                        app.tools{1}.(vid) = ...
                            uitreenode(app.tools{1}.(fid));
                        app.tools{1}.(vid).Text = ...
                            app.dataset{1,file}{1,variable};
                        app.tools{1}.(vid).UserData = [file, variable];
                    end % end if 
                end % end for 
            end % end for 
        end % end function 
        % -----------------------------------------------------------------
        function app = assign_node_data(app) 
            % Button_files_Upload
            bar = uifigure;
            bar.Position = [500 500 500 125];
            bar.WindowStyle = 'modal';
            inbar = uiprogressdlg(bar,...
                'Title','Building database ....',...
                'Indeterminate','on');
            drawnow;
            app = assign_node_data_do(app);
            close(inbar);
            close(bar);  
        end % end function 
        % -----------------------------------------------------------------
        function app = assign_node_data_do(app) 
            % populates data to each node
            app.list_special_vids = struct();
            app.list_special_vids.eyecon{1,1} = 'names';
            app.list_special_vids.eyecon{1,2} = 'vids';
            app.list_special_vids.multieye{1,1} = 'names';
            app.list_special_vids.multieye{1,2} = 'vids';
            % 
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
            else
                thismanyfiles = 1;
            end % end if 
            for file = 1 : thismanyfiles % listing filenames and variables .... 
                [timestamps, variables] = size(app.dataset{1,file}); 
                fid = ['db_f_', num2str(file)];
                for variable = 1 : variables 
                    if (~contains(app.list_2ignore, app.dataset{1,file}{1,variable}))
                    vid = [fid,'_v_' num2str(variable)];
                        % -------------------------------------------------
                        if (contains(app.tools{1}.(vid).Text, 'Time Stamp')) % special case 1 : it's human time 
                            date = cell(timestamps-1,1);
                            hours = cell(timestamps-1,1);
                            minutes = cell(timestamps-1,1);
                            seconds = cell(timestamps-1,1);
                            current_time = cell(timestamps-1,1);
                            for timestamp = 2 : timestamps
                                date{timestamp-1, 1} = app.dataset{1,file}{timestamp,variable}(1:end-9);
                                time = app.dataset{1,file}{timestamp,variable}(end-7:end);
                                hours{timestamp-1, 1} = str2double(time(1:2));
                                minutes{timestamp-1, 1} = str2double(time(4:5));
                                seconds{timestamp-1, 1} = str2double(time(7:8));
                                current_time{timestamp-1,1} = hours{timestamp-1, 1}*3600 + minutes{timestamp-1, 1}*60 + seconds{timestamp-1, 1};
                                %current_time{timestamp-1,1} = duration{timestamp-1,1} - current_time{1,1};
                            end % end for 
                            app.tools{1}.(vid).NodeData = {"date", "hours", "minutes", "seconds", "current time"; date, hours, minutes, seconds, current_time};
                        % -------------------------------------------------
                        elseif (contains(app.tools{1}.(vid).Text, 'multieye')) % special case 2 : it's multieye sensor
                            % 
                            [row, ~] = size(app.list_special_vids.multieye);
                            app.list_special_vids.multieye{row+1,1} = app.tools{1}.(vid).Text;
                            app.list_special_vids.multieye{row+1,2} = vid;
                            % 
                            timestamp = 1; % this is label
                            source = app.dataset{1,file}{timestamp,variable};
                            splitted = regexp(source,',','split');
                            [~, thismany] = size(splitted);
                            label = zeros(thismany, 1);
                            value_1 = splitted{1,1};
                            value_1 = regexp(value_1,'[','split');
                            label(1,1) = str2double(value_1{1,2});
                            value_end = splitted{1,end};
                            value_end = regexp(value_end,']','split');
                            label(end,1) = str2double(value_end{1,1});
                            for element = 2 : (thismany-1)
                                label(element, 1) = str2double(splitted{1,element});
                            end % end for 
                            MAT = zeros(thismany, timestamps-1);
                            for timestamp = 2 : timestamps
                                if timestamp > 2
                                    source = app.dataset{1,file}{timestamp,variable};
                                    splitted = regexp(source,',','split');
                                    [~, thismanys] = size(splitted);
                                    dummy = zeros(thismanys, 1);
                                    value_1 = splitted{1,1};
                                    value_1 = regexp(value_1,'[','split');
                                    dummy(1,1) = str2double(value_1{1,2});
                                    value_end = splitted{1,end};
                                    value_end = regexp(value_end,']','split');
                                    dummy(end,1) = str2double(value_end{1,1});
                                    for element = 2 : (thismanys-1)
                                        dummy(element, 1) = str2double(splitted{1,element});
                                    end % end for 
                                    MAT(:, timestamp-1) = dummy; 
                                end % end if 
                            end % end for 
                            app.tools{1}.(vid).NodeData = {"labels",  "data"; label, MAT}; 
                        % -------------------------------------------------
                        elseif (contains(app.tools{1}.(vid).Text, 'eyecon numeric histogram') ...
                                || contains(app.tools{1}.(vid).Text, 'eyecon volumetric histogram')) % special case 2 : it's eyecon sensor 
                            % 
                            [row, ~] = size(app.list_special_vids.eyecon);
                            app.list_special_vids.eyecon{row+1,1} = app.tools{1}.(vid).Text;
                            app.list_special_vids.eyecon{row+1,2} = vid;
                            % 
                            timestamp = 1; % this is label
                            source = app.dataset{1,file}{timestamp,variable};
                            splitted = regexp(source,',','split');
                            [~, thismany] = size(splitted);
                            label = zeros(thismany, 1);
                            value_1 = splitted{1,1};
                            value_1 = regexp(value_1,'[','split');
                            label(1,1) = str2double(value_1{1,2});
                            value_end = splitted{1,end};
                            value_end = regexp(value_end,']','split');
                            label(end,1) = str2double(value_end{1,1});
                            for element = 2 : (thismany-1)
                                label(element, 1) = str2double(splitted{1,element});
                            end % end for 
                            MAT = zeros(thismany, timestamps - 1);
                            for timestamp = 2 : timestamps
                                source = app.dataset{1,file}{timestamp,variable};
                                splitted = regexp(source,',','split');
                                [~, thismanys] = size(splitted);
                                dummy = zeros(thismanys, 1);
                                value_1 = splitted{1,1};
                                value_1 = regexp(value_1,'[','split');
                                dummy(1,1) = str2double(value_1{1,2});
                                value_end = splitted{1,end};
                                value_end = regexp(value_end,']','split');
                                dummy(end,1) = str2double(value_end{1,1});
                                for element = 2 : (thismanys-1)
                                    dummy(element, 1) = str2double(splitted{1,element});
                                end % end for 
                                MAT(:, timestamp-1) = dummy;      
                            end % end for 
                            app.tools{1}.(vid).NodeData = {"labels",  "data"; label, MAT}; 
                        % -------------------------------------------------
                        % changed elseif to else
%                         elseif (contains(app.tools{1}.(vid).Text, 'eyecon numeric density') || ...
%                                 contains(app.tools{1}.(vid).Text, 'eyecon volumetric density') || ...
%                                 contains(app.tools{1}.(vid).Text, 'fluid bed') || ... 
%                                 contains(app.tools{1}.(vid).Text, 'thermoalert') || ... 
%                                 contains(app.tools{1}.(vid).Text, 'PHT sensors'))
                        else 
                            dummy = zeros(timestamps-1,1);   
                            for timestamp = 2 : timestamps
                                dummy(timestamp-1, 1) = app.dataset{1,file}{timestamp,variable};
                            end % end for 
                            app.tools{1}.(vid).NodeData = dummy;
                        end % end if 
                        % -------------------------------------------------
                    end % end if 
                end % end for 
            end % end for 
        end % function 
        % -----------------------------------------------------------------
        function app = features_plottool(app) % 1
            % creates plottool
            plotter.plottool = uifigure('Visible', 'off', WindowStyle = 'normal');
            plotter.plottool.CloseRequestFcn = @(src,event)close_plotter(src);
            plotter.plottool.AutoResizeChildren = 'off';
            plotter.plottool.Position = [100 100 1153 547];
            plotter.plottool.Name = 'Plot tool ';
            plotter.plottool.Resize = 'off';
            plotter.database = uitree(plotter.plottool, 'checkbox'); % create property database
            plotter.database.CheckedNodesChangedFcn = @nodechange;
            plotter.database.Position = [36 59 1106 441];
            plotter.Reports_varfile = uilabel(plotter.plottool); % Create Reports_varfile
            plotter.Reports_varfile.Position = [37 508 511 24];
            plotter.Reports_varfile.Text = 'Select file(s) and variable(s) for plotting';
            plotter.Reports_varfile.Tooltip = {'Shows imported files and the variables in each file. Select variable(s) for plotting.'};
            plotter.Button_plot_detailed = uibutton(plotter.plottool, 'state'); % Create Button_plot_detailed
            plotter.Button_plot_detailed.ValueChangedFcn = @pushed_Button_plot_detailed;
            plotter.Button_plot_detailed.Enable = 'off';
            plotter.Button_plot_detailed.Value = 0;
            plotter.Button_plot_detailed.Position = [986 18 157 30];
            plotter.Button_plot_detailed.Text = 'Go to detailed plot(s)';
            plotter.MessageTextAreaLabel = uilabel(plotter.plottool); % Creates Message Text Area Label
            plotter.MessageTextAreaLabel.FontAngle = 'italic';
            plotter.MessageTextAreaLabel.Position = [36 23 58 22];
            plotter.MessageTextAreaLabel.Text = 'Message:';
            plotter.Reports_Status = uitextarea(plotter.plottool); % Creates Reports_Status
            plotter.Reports_Status.Editable = 'off';
            plotter.Reports_Status.FontWeight = 'bold';
            plotter.Reports_Status.Value = {'Plot tool ready. Select variable(s) for plotting.'};
            plotter.Reports_Status.Position = [93 18 887 29];
            plotter.demon_clicks = struct();
            plotter.demon_clicks.x = [];
            plotter.demon_clicks.y = [];
            app.tools{1} = plotter;
            % -------------------------------------------------------------
            function close_plotter(fig) % close plotter  
                plotter.Reports_Status.Value = 'Closing plotter, waiting for user selection ....';
                msg = 'Closing plot tool?';
                title = 'Confirm close';
                selection = uiconfirm(fig,msg,title, ...
                    'Options',{'Close','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',2,'Icon','question');
                switch selection
                    case 'Close'
                        app.tools{1}.plottool.Visible = 'off';
                        plotter.Reports_Status.Value = 'Plot tool closed.';
                        return
                    case 'Cancel'
                        plotter.Reports_Status.Value = 'Closing plot tool canceled.';
                        return
                end % end switch 
            end % end function
            % -------------------------------------------------------------
            function nodechange(src, event) % checks selected nodes  
                style_var = uistyle('FontAngle','italic', 'FontColor','r', 'FontWeight','bold');
                if (~isempty(event.PreviousCheckedNodes)) 
                    removeStyle(app.tools{1}.database);
                end % end if
                if (~isempty(event.CheckedNodes)) 
                    plotter.eventCheckedNodes = event.CheckedNodes;
                    plotter.Button_plot_detailed.Enable = 'on';
                    [ticked_nodes, ~] = size(event.CheckedNodes);
                    plotter.Reports_Status.Value = ['# ', num2str(ticked_nodes), ' variable(s) currently selected.'];
                    for tick = 1 : ticked_nodes
                        if (~isempty(event.CheckedNodes(tick, 1).UserData))
                            file = event.CheckedNodes(tick, 1).UserData(1,1);
                            variable = event.CheckedNodes(tick, 1).UserData(1,2);
                            fid = ['db_f_', num2str(file)];
                            vid = [fid,'_v_' num2str(variable)];
                            if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                            if contains(app.tools{1}.(vid).Text, event.CheckedNodes(tick, 1).Text)
                                addStyle(app.tools{1}.database,style_var,'node',app.tools{1}.(vid));
                            end % end if 
                            end % end if 
                        end % end if 
                    end % end for
                else % is empty 
                    plotter.Button_plot_detailed.Enable = 'off';
                    plotter.Reports_Status.Value = 'No variable(s) selected. Select variable(s) to activate detailed plot(s) button again.';
                end % end if 
            end % end function - nodechange
            % -------------------------------------------------------------
            function pushed_Button_plot_detailed(src, event) 
                if plotter.Button_plot_detailed.Value == 1 % checks state of button % pushed 
                    plotter.demon_clicks.x = [];
                    plotter.demon_clicks.y = [];
                    plotter.Button_plot_detailed.Enable = 'off';
                    plotter.Button_plot_detailed.Value = 0;
                    plotter.Reports_Status.Value = 'Plot selected variable(s) in a new window.';
                    % opens plotted data in new figure 
                    indx_2D = 1;
                    indx_3D = 1;
                    indx_time = 1;
                    plot_3D_vid{indx_3D,1} = [];
                    plot_2D_vid{indx_2D,1} = [];
                    plot_time{indx_time,1} = [];
                    [ticked_nodes, ~] = size(plotter.eventCheckedNodes);
                    fileID = []; 
                    for tick = 1 : ticked_nodes 
                        % collecting info on what's selected 
                        if (~isempty(plotter.eventCheckedNodes(tick, 1).UserData))
                            file = plotter.eventCheckedNodes(tick, 1).UserData(1,1);
                            fileID = [fileID; file];
                            variable = plotter.eventCheckedNodes(tick, 1).UserData(1,2);
                            fid = ['db_f_', num2str(file)];
                            vid = [fid,'_v_' num2str(variable)];
                            if (contains(app.tools{1}.(vid).Text, 'Time'))  % special case 1 : it's human time
                                plot_time{indx_time,1} = vid;
                                indx_time = indx_time + 1;
                            elseif (contains(app.tools{1}.(vid).Text, 'multieye')) % special case 2 : it's multieye sensor
                                plot_3D_vid{indx_3D, 1} = vid;
                                indx_3D = indx_3D + 1;
                            elseif (contains(app.tools{1}.(vid).Text, 'eyecon numeric histogram') ...
                                    || contains(app.tools{1}.(vid).Text, 'eyecon volumetric histogram')) % special case 2 : it's eyecon sensor 
                                plot_3D_vid{indx_3D, 1} = vid;
                                indx_3D = indx_3D + 1;
                            else 
                                plot_2D_vid{indx_2D, 1} = vid;
                                indx_2D = indx_2D + 1;
                            end % end if
                        end % end if 
                    end % end for 
                    if (~isempty(fileID)) 
                        if diff(fileID)>0 % this means multi files 
                            % this means multi files 
                            % this means multi files 
                            % this means multi files 
                            disp('this means multi files: not coded yet')
                            % this means multi files 
                            % this means multi files 
                            % this means multi files 
                        else % this means single file 
                            file = fileID(1);
                            [plot_2ds, ~] = size(plot_2D_vid);
                            [plot_3ds, ~] = size(plot_3D_vid);
                            if isempty(plot_time{1,1}) % means user didn't select time 
                                variable = 1;
                                fid = ['db_f_', num2str(file)];
                                vid = [fid,'_v_' num2str(variable)];
                                plot_time{1,1} = vid;
                            end % end if
                            time_as_x = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,5});
                            time_as_x = time_as_x - time_as_x(1);
                            x = time_as_x;
                            %x = seconds(x);
                            if (~isempty(plot_2D_vid{1,1}))
                                fig_2D = uifigure('Visible', 'off', WindowStyle = 'normal');
                                fig_2D.CloseRequestFcn = @(src,event)close_fig(src);
                                fig_2D.Position = [100 100 1150 550];
                                fig_2D.Name = '2D plot of selected variables versus time (by default) ....';
                                p_2D = uipanel(fig_2D,'Position',[5 5 1000 545]);
                                p_info_2D = uipanel(fig_2D,'Position',[1010 5 130 545]);
                                p_info_2D_direction1 = uitextarea(p_info_2D);
                                p_info_2D_direction1.Editable = 'off';
                                p_info_2D_direction1.Value = 'Selection info:';
                                p_info_2D_direction1.Position = [5 515 120 25];
                                info_2D = uitextarea(p_info_2D);
                                info_2D.Editable = 'off';
                                info_2D.Value = 'Click on plot ....waiting....';
                                info_2D.FontWeight = 'bold';
                                info_2D.Position = [5 470 120 45];
                                p_info_2D_direction2 = uitextarea(p_info_2D);
                                p_info_2D_direction2.Editable = 'off';
                                p_info_2D_direction2.Value = 'Plot histograms?: ';
                                p_info_2D_direction2.Position = [5 445 120 25];
                                btn_eyecon_2D = uibutton(p_info_2D, 'push', 'Text', 'Eyecon  ');
                                btn_eyecon_2D.Position = [5 420 120 25];
                                btn_eyecon_2D.Enable = 'off';
                                btn_eyecon_2D.ButtonPushedFcn = @req_Eyecon;
                                btn_multieye_2D = uibutton(p_info_2D, 'push', 'Text', 'Multieye');
                                btn_multieye_2D.Position = [5 395 120 25];
                                btn_multieye_2D.Enable = 'off';
                                btn_multieye_2D.ButtonPushedFcn = @req_Multieye;
                                ax_2D = uiaxes(p_2D,'Position',[1 1 999 540], 'NextPlot','add');
                                hold(ax_2D, 'on');
                                for plot_2d = 1 : plot_2ds
                                    vid = plot_2D_vid{plot_2d, 1};
                                    plot(ax_2D, ...
                                        x, app.tools{1}.(vid).NodeData, ...
                                        'o', 'MarkerEdgeColor', ...
                                        [rand rand rand], 'DisplayName', ...
                                        app.tools{1}.(plot_2D_vid{plot_2d,1}).Text);
                                end % end for
                                xlabel(ax_2D, 'Time [sec]');
                                ylabel(ax_2D, 'Selected variable [values]');
                                xlim(ax_2D, [min(min(x)) max(max(x))]);
                                title(ax_2D, ['Batch name: ', app.dataset{1,file}{2,4}]);
                                hold(ax_2D, 'off');
                                legend(ax_2D)
                                fig_2D_dcm = datacursormode(fig_2D);
                                fig_2D_dcm.Enable = 'on'; 
                                fig_2D_dcm.UpdateFcn = @ClickedHere;
                                fig_2D.Visible = 'on';
                            end % end if 
                            if (~isempty(plot_3D_vid{1,1}))
                                for plot_3d = 1 : plot_3ds
                                    vid = plot_3D_vid{plot_3d, 1};
                                    fig_3D = uifigure('Visible', 'off', WindowStyle = 'normal');
                                    fig_3D.CloseRequestFcn = @(src,event)close_fig(src);
                                    fig_3D.Position = [100 100 1150 550];
                                    fig_3D.Name = '3D plot of selected variables versus time (by default) ....';
                                    p_3D = uipanel(fig_3D,'Position',[5 5 1000 545]);
                                    p_info_3D = uipanel(fig_3D,'Position',[1010 5 130 545]);
                                    p_info_3D_direction1 = uitextarea(p_info_3D);
                                    p_info_3D_direction1.Editable = 'off';
                                    p_info_3D_direction1.Value = 'Selection info:';
                                    p_info_3D_direction1.Position = [5 515 120 25];
                                    info_3D = uitextarea(p_info_3D);
                                    info_3D.Editable = 'off';
                                    info_3D.Value = 'Click on plot ....waiting....';
                                    info_3D.FontWeight = 'bold';
                                    info_3D.Position = [5 470 120 45];
                                    p_info_3D_direction2 = uitextarea(p_info_3D);
                                    p_info_3D_direction2.Editable = 'off';
                                    p_info_3D_direction2.Value = 'Plot histograms?: ';
                                    p_info_3D_direction2.Position = [5 445 120 25];
                                    btn_eyecon_3D = uibutton(p_info_3D, 'push', 'Text', 'Eyecon  ');
                                    btn_eyecon_3D.Position = [5 420 120 25];
                                    btn_eyecon_3D.Enable = 'off';
                                    btn_eyecon_3D.ButtonPushedFcn = @req_Eyecon;
                                    btn_multieye_3D = uibutton(p_info_3D, 'push', 'Text', 'Multieye');
                                    btn_multieye_3D.Position = [5 395 120 25];
                                    btn_multieye_3D.Enable = 'off';
                                    btn_multieye_3D.ButtonPushedFcn = @req_Multieye;
                                    ax_3D = uiaxes(p_3D,'Position',[1 1 999 540], 'View', [45 45]);
                                    hold(ax_3D, 'on');
                                    [thismany, timestamps] = size(app.tools{1}.(vid).NodeData{2,2}); 
                                    for this = 1 : thismany
                                        y = ones(timestamps,1) * app.tools{1}.(vid).NodeData{2,1}(this);
                                        z = app.tools{1}.(vid).NodeData{2,2}(this, :);
                                        plot3(ax_3D, ...
                                            x, y, z, '.', 'MarkerEdgeColor', ...
                                            [rand rand rand], ...
                                            'DisplayName', ...
                                            num2str(app.tools{1}.(vid).NodeData{2,1}(this)));
                                    end % end for 
                                    xlabel(ax_3D, 'Time [sec]');
                                    ylabel(ax_3D, 'Special feature of selected variable');
                                    zlabel(ax_3D, 'Selected variable [values]');
                                    xlim(ax_3D, [min(min(x)) max(max(x))]);
                                    plot_legend = regexp(app.tools{1}.(vid).Text,'[','split');
                                    plot_legend = plot_legend{1,1};
                                    title(ax_3D, {plot_legend; ['Batch name: ', app.dataset{1,file}{2,4}]});
                                    hold(ax_3D, 'off');
                                    fig_3D_dcm = datacursormode(fig_3D);
                                    fig_3D_dcm.Enable = 'on'; 
                                    fig_3D_dcm.UpdateFcn = @ClickedHere;
                                    fig_3D.Visible = 'on';
                                end % end for 
                            end % end if 
                        end % end if 
                    end % end if
                    % finished plotting 
                    dummy_x_old = plotter.demon_clicks.x;
                    if (isempty(plot_2D_vid{1,1})) % no 2d plot
                        while (~isempty(ax_3D) && isequal(fig_3D.Visible, 'on'))
                            if (~isequal(dummy_x_old, plotter.demon_clicks.x))
                                btn_eyecon_3D.Enable = 'on';
                                btn_multieye_3D.Enable = 'on';
                                dummy_x_old = plotter.demon_clicks.x;
                                idx = find (time_as_x == plotter.demon_clicks.x);
                                time_hrs = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,2});
                                time_hr = time_hrs(idx);
                                time_mins = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,3});
                                time_min = time_mins(idx);
                                time_secs = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,4});
                                time_sec = time_secs(idx);
                                info_3D.Value = {['Timestep: ', num2str(plotter.demon_clicks.x)]; ...
                                    [num2str(time_hr), ':', num2str(time_min), ':', num2str(time_sec)]};
                            end % end if
                            pause(0.01);
                        end % end if
                    elseif  (isempty(plot_3D_vid{1,1})) % no 3d plot
                        while (~isempty(ax_2D) && isequal(fig_2D.Visible, 'on'))
                            if (~isequal(dummy_x_old, plotter.demon_clicks.x))
                                btn_eyecon_2D.Enable = 'on';
                                btn_multieye_2D.Enable = 'on';
                                dummy_x_old = plotter.demon_clicks.x;
                                idx = find (time_as_x == plotter.demon_clicks.x);
                                time_hrs = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,2});
                                time_hr = time_hrs(idx);
                                time_mins = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,3});
                                time_min = time_mins(idx);
                                time_secs = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,4});
                                time_sec = time_secs(idx);
                                info_2D.Value = {['Timestep: ', num2str(plotter.demon_clicks.x)]; ...
                                    [num2str(time_hr), ':', num2str(time_min), ':', num2str(time_sec)]};
                            end % end if
                            pause(0.01);
                        end % end if
                    else % both 2d/3d plots
                        while (~isempty(ax_2D) && isequal(fig_2D.Visible, 'on')) || (~isempty(ax_3D) && isequal(fig_3D.Visible, 'on'))
                            if (~isequal(dummy_x_old, plotter.demon_clicks.x))
                                btn_eyecon_2D.Enable = 'on';
                                btn_multieye_2D.Enable = 'on';
                                btn_eyecon_3D.Enable = 'on';
                                btn_multieye_3D.Enable = 'on';
                                dummy_x_old = plotter.demon_clicks.x;
                                idx = find (time_as_x == plotter.demon_clicks.x);
                                time_hrs = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,2});
                                time_hr = time_hrs(idx);
                                time_mins = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,3});
                                time_min = time_mins(idx);
                                time_secs = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,4});
                                time_sec = time_secs(idx);
                                info_2D.Value = {['Timestep: ', num2str(plotter.demon_clicks.x)]; ...
                                    [num2str(time_hr), ':', num2str(time_min), ':', num2str(time_sec)]};
                                info_3D.Value = {['Timestep: ', num2str(plotter.demon_clicks.x)]; ...
                                    [num2str(time_hr), ':', num2str(time_min), ':', num2str(time_sec)]};
                            end % end if
                            pause(0.01);
                        end % end if
                    end % end if                    
                else % released 
                    plotter.Button_plot_detailed.Enable = 'on';
                end % end if 
                % ---------------------------------------------------------
                function req_Eyecon(src, event)
                    eyecon_at_timestamp_fig = figure('Visible','off');
                    eyecon_at_timestamp_fig.Name = ...
                        ['Eyecon measured spectra at timestamp: ', ...
                        num2str(time_hr), ':', ...
                        num2str(time_min), ':', num2str(time_sec)];
                    [vids, ~] = size(app.list_special_vids.eyecon);
                    if (isempty(plot_2D_vid{1,1})) % no 2d plot
                        all_vids = plot_3D_vid;
                    elseif  (isempty(plot_3D_vid{1,1})) % no 3d plot
                        all_vids = plot_2D_vid;
                    else % both 2d and 3d plot
                        all_vids = plot_2D_vid;
                    end % end if 
                    [vids_in_all_vids, ~] = size(all_vids); 
                    for vid_in_all_vids = 1 : vids_in_all_vids
                        dummy_2D_val = all_vids{vid_in_all_vids,1};
                        dummy_2D_indx_var_init = regexp(all_vids{vid_in_all_vids,1}, '_v_');
                        dummy_2D_indx_var_init = dummy_2D_indx_var_init - 1;
                        dummy_2D_indx_file_init = regexp(all_vids{vid_in_all_vids,1}, '_f_');
                        dummy_2D_indx_file_init = dummy_2D_indx_file_init + 3;
                        file = dummy_2D_val(dummy_2D_indx_file_init:dummy_2D_indx_var_init);
                        file = str2double(file);
                    end % end for
                    [~, variables] = size(app.dataset{1,file}); 
                    for variable = 1 : variables
                        if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                        go_fid = ['db_f_', num2str(file)];
                        go_vid = [go_fid,'_v_' num2str(variable)];
                        if (contains(app.tools{1}.(go_vid).Text, 'eyecon numeric histogram') ...
                                    || contains(app.tools{1}.(go_vid).Text, 'eyecon volumetric histogram')) % special case 2 : it's eyecon sensor 
                            xx = app.tools{1}.(go_vid).NodeData{2,1};
                            yy = app.tools{1}.(go_vid).NodeData{2,2};
                            yy = yy(:,  (idx-2));
                            displayname = app.tools{1}.(go_vid).Text;
                            plot(xx, yy, 'DisplayName', displayname(1:25))
                            hold on
                        end % end if 
                        end % end if 
                    end % end for 
                    hold off
                    xlabel('Eyecone fixed spectra')
                    ylabel('Eyecone histogram')
                    title(['Eyecon measured spectra at timestamp: ', ...
                        num2str(time_hr), ':', ...
                        num2str(time_min), ':', num2str(time_sec)]);
                    legend
                    eyecon_at_timestamp_fig.Visible = 'on';
                end  % end function 
                % ---------------------------------------------------------
                function req_Multieye(src, event)
                    multieye_at_timestamp_fig = figure('Visible','off');
                    multieye_at_timestamp_fig.Name = ...
                        ['Multieye measured spectra at timestamp: ', ...
                        num2str(time_hr), ':', ...
                        num2str(time_min), ':', num2str(time_sec)];
                    [vids, ~] = size(app.list_special_vids.multieye);
                    if (isempty(plot_2D_vid{1,1})) % no 2d plot
                        all_vids = plot_3D_vid;
                    elseif  (isempty(plot_3D_vid{1,1})) % no 3d plot
                        all_vids = plot_2D_vid;
                    else % both 2d and 3d plot
                        all_vids = plot_2D_vid;
                    end % end if
                    [vids_in_all_vids, ~] = size(all_vids); 
                    for vid_in_all_vids = 1 : vids_in_all_vids
                        dummy_3D_val = all_vids{vid_in_all_vids,1};
                        dummy_3D_indx_var_init = regexp(all_vids{vid_in_all_vids,1}, '_v_');
                        dummy_3D_indx_var_init = dummy_3D_indx_var_init - 1;
                        dummy_3D_indx_file_init = regexp(all_vids{vid_in_all_vids,1}, '_f_');
                        dummy_3D_indx_file_init = dummy_3D_indx_file_init + 3;
                        file = dummy_3D_val(dummy_3D_indx_file_init:dummy_3D_indx_var_init);
                        file = str2double(file);
                    end % end for
                    [~, variables] = size(app.dataset{1,file}); 
                    for variable = 1 : variables
                        if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                        go_fid = ['db_f_', num2str(file)];
                        go_vid = [go_fid,'_v_' num2str(variable)];
                        if (contains(app.tools{1}.(go_vid).Text, 'multieye')) % special case 2 : it's multieye sensor
                            xx = app.tools{1}.(go_vid).NodeData{2,1};
                            yy = app.tools{1}.(go_vid).NodeData{2,2};
                            yy = yy(:,  (idx-2));
                            displayname = app.tools{1}.(go_vid).Text;
                            plot(xx, yy, 'DisplayName', displayname(1:30))
                            hold on
                        end % end if 
                        end % end if 
                    end % end for 
                    hold off
                    xlabel('Multieye fixed spectra')
                    ylabel('Multieye histogram')
                    title(['Multieye measured spectra at timestamp: ', ...
                        num2str(time_hr), ':', ...
                        num2str(time_min), ':', num2str(time_sec)]);
                    legend
                    multieye_at_timestamp_fig.Visible = 'on';
                end % end function 
                % ---------------------------------------------------------
                function txt = ClickedHere(src, event)
                    plotter.demon_clicks.x = event.Position(1);
                    plotter.demon_clicks.y = event.Position(2);
                    txt = ['(timestamp: ' num2str(event.Position(1)) ', #value: ' num2str(event.Position(2)) ')'];
                end % end if
                % ---------------------------------------------------------
            end % end function - pushed_Button_plot_detailed
            % -------------------------------------------------------------
            function close_fig(fig) 
                % close, save or return to the figure 
                plotter.Reports_Status.Value = 'Closing figure, waiting for user selection ....';
                msg = 'Close figure without saving?';
                title = 'Confirm close';
                selection = uiconfirm(fig,msg,title, ...
                    'Options',{'Close','Save','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3, 'Icon','question');
                switch selection
                    case 'Close'
                        plotter.Reports_Status.Value = 'Closed figure without saving(!).';
                        delete(fig)
                        plotter.Reports_Status.Value = 'Plot tool is ready. Select variable(s) to activate detailed plot(s) button.';
                    case 'Save'
                        plotter.Reports_Status.Value = 'Closing figure with save, waiting for user to select file save location ....';
                        [~,~] = uiputfile('*.fig','Save figure');
                        plotter.Reports_Status.Value = 'Plot tool is ready. Select variable(s) to activate detailed plot(s) button.';
                    case 'Cancel'
                        plotter.Reports_Status.Value = 'Closing figure canceled, retuned to figure.';
                        return
                end % end switch
            end % end function
            % -------------------------------------------------------------
        end % end function - features_plottool
        % -----------------------------------------------------------------
        function app = features_varUser(app) % 2 
            varUser.varUser = uifigure('Visible', 'off');
            varUser.varUser.Position = [100 100 1067 644];
            varUser.varUser.Name = ['Reporting variables availibility ' ...
                'prior to further processing ....'];
            varUser.varUser.Scrollable = 'on';
            varUser.varUser.CloseRequestFcn = @(src,event)close_varUser(src);
            varUser.out = uitable(varUser.varUser);
            varUser.out.ColumnName = {'Variable tag'; ...
                'Short description of variable'; 'Filename'; 'Status'};
            varUser.out.RowName = {};
            varUser.out.ColumnEditable = [false false false false];
            varUser.out.ColumnSortable = [true false true true];
            varUser.out.Position = [21 17 1035 469];
            varUser.Directions = uilabel(varUser.varUser);
            varUser.Directions.FontWeight = 'bold';
            varUser.Directions.Position = [22 610 72 22];
            varUser.Directions.Text = 'Summary of selected file(s):';
            varUser.info = uitextarea(varUser.varUser);
            varUser.info.Editable = 'off';
            varUser.info.Position = [22 495 1036 107];
            varUser.info.Value = {''; 
                ['The list of variable(s) collected from the ' ...
                'user selected file(s) are shown bellow. ']; ''; 
                ['If a vaiable is expected but not found in the file(s), ' ...
                'It is indicated under "Status" tab. ']; '';};
            app.tools{2} = varUser;
            % ------------------------------------------------------------
            function close_varUser(fig) % close plotter  
                msg = ['Did you finish checking wether any avriable(s) ' ...
                    'is missing in selected file(s)?'];
                title = 'Confirm close';
                selection = uiconfirm(fig,msg,title, ...
                    'Options',{'Close summary window',...
                    'Abort the App','Cancel'}, ...
                    'DefaultOption',1,'CancelOption',3, 'Icon','question');
                switch selection
                    case 'Close summary window'
                        app.Reports_Status.Value = 'Closed summary file. Returned to main app.';
                        app.tools{2}.varUser.Visible = 'off';
                    case 'Abort the App'
                        app.Reports_Status.Value = 'Closed summary file. Killing the main app.';
                        app.tools{2}.varUser.Visible = 'off';
                        close all force;
                        clc;
                        delete(app);
                    case 'Cancel'
                        return
                end % end switch
            end % end function
            % ------------------------------------------------------------
        end % end of function 
        % -----------------------------------------------------------------
        function app = features_nirtool(app) % 4
            % -------------------------------------------------------------
            Methods{1,1} = 'Select analysis method'; % dafault 
            Methods{1,2} = 'MultiEye-NIR - EIOT(Extended Iterative Optimization Technology)';
            Methods{1,3} = 'MultiEye-NIR - MAK dVFS + PI-theory';
            Methods{1,4} = 'For Future Options';
            Methods{1,5} = 'For Future Options';
            % -------------------------------------------------------------
            Tasks{1,1} = 'Select a Task'; % dafault 
            Tasks{1,2} = {...
                'Chemical signatures (S, sI and rI)', ...
                'Process monitoring tool', ...
                'Estimate concentrations (with PSO)', ...
                'Future Options'};
            Tasks{1,3} = {...
                'Not Implemented 31.', ...
                'Not Implemented 32.', ...
                'Not Implemented 33.'};
            Tasks{1,4} = {...
                'Not Implemented 41.', ...
                'Not Implemented 42.', ...
                'Not Implemented 43.'};
            Tasks{1,5} = {...
                'Not Implemented 51.', ...
                'Not Implemented 52.', ...
                'Not Implemented 53.'};
            % -------------------------------------------------------------
            NIRtool.NIRtool = uifigure('Visible', 'off');
            NIRtool.NIRtool.AutoResizeChildren = 'off';
            NIRtool.NIRtool.Position = [100 100 884 860];
            NIRtool.NIRtool.Name = 'Spectroscopy tool';
            NIRtool.NIRtool.CloseRequestFcn = @(src,event)close_spectroscopytool(src);
            NIRtool.NIRtool.Resize = 'off';
            % -------------------------------------------------------------
            % for immediate outut : outQ
            NIRtool.outQ = uipanel('Parent', NIRtool.NIRtool, ...
                'Title', 'Most recent results appear here. Click on the panel to access separate plots.', ...
                'Position', [12 14 859 755]);
            NIRtool.outQ.AutoResizeChildren = 'off';
            NIRtool.outQ.ButtonDownFcn = @PanelClicked;
            % -------------------------------------------------------------
            % dropdowns containers 
            NIRtool.methodslist = uibuttongroup(NIRtool.NIRtool); 
            NIRtool.methodslist.Position = [11 777 860 37];
            NIRtool.methodslist.AutoResizeChildren = 'off';
            NIRtool.methodslist.Tooltip = {'Select analysis method.'};
            NIRtool.methodslist.FontWeight = 'bold';
            NIRtool.fileslist = uibuttongroup(NIRtool.NIRtool);
            NIRtool.fileslist.Position = [11 818 860 37];
            NIRtool.fileslist.AutoResizeChildren = 'off';
            NIRtool.fileslist.Tooltip = {'Select file to perform analysis.'};
            NIRtool.fileslist.FontWeight = 'bold';
            % -------------------------------------------------------------
            NIRtool.method = uidropdown(NIRtool.methodslist);
            NIRtool.method.Items = Methods;
            NIRtool.method.Tooltip = {'Select analysis method from dropdown list.'};
            NIRtool.method.Position = [4 4 409 23];
            NIRtool.method.Value = Methods{1,1};
            NIRtool.method.ValueChangedFcn = @(src,event)Selected_Method(src,event);
            % -------------------------------------------------------------
            NIRtool.task = uidropdown(NIRtool.methodslist);
            NIRtool.task.Items = {Tasks{1,1}};
            NIRtool.task.Tooltip = {'Select a task for selected method'};
            NIRtool.task.Position = [421 4 430 23];
            NIRtool.task.Value = Tasks{1,1};
            NIRtool.task.ValueChangedFcn = @(src,event)Selected_Task(src,event);
            % -------------------------------------------------------------
            NIRtool.filetxt = uilabel(NIRtool.fileslist);
            NIRtool.filetxt.FontWeight = 'bold';
            NIRtool.filetxt.Position = [13 6 91 22];
            NIRtool.filetxt.Text = 'Selected file:';
            % -------------------------------------------------------------
            NIRtool.f_list = uidropdown(NIRtool.fileslist);
            NIRtool.f_list.ValueChangedFcn = @(src,event)Selected_File(src,event);
            NIRtool.f_list.Items = {'Select a file'};
            NIRtool.f_list.Position = [120 11 717 23];
            NIRtool.f_list.Value = 'Select a file';
            NIRtool.f_list.Tooltip = {'Select a file from dropdown list of loaded file(s). '};
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
            else
                thismanyfiles = 1;
            end % end if 
            for file = 1 : thismanyfiles 
                if thismanyfiles > 1
                    NIRtool.f_list.Items{1, file + 1} = app.import_file_names{1, file};
                else 
                    NIRtool.f_list.Items{1, file + 1} = app.import_file_names;
                end % end if 
            end % end for 
            app.tools{4} = NIRtool;
            % -------------------------------------------------------------
            function close_spectroscopytool(fig) % close NIRtool  
                app.Reports_Status.Value = 'Closing NIRtool, waiting for user selection ....';
                msg = 'Closing NIR tool?';
                title = 'Confirm close';
                selection = uiconfirm(fig,msg,title, ...
                    'Options',{'Close','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',2,'Icon','question');
                switch selection
                    case 'Close'
                        sgtitle(NIRtool.outQ, '');
                        plt = subplot(1,1,1, 'Parent', NIRtool.outQ);
                        plot(plt, []);
                        app.tools{4}.NIRtool.Visible = 'off';
                        app.Reports_Status.Value = 'NIR tool closed.';
                        set(NIRtool.task,'Value', 'Select a Task');
                        set(NIRtool.method,'Value', 'Select analysis method');
                        set(NIRtool.f_list,'Value', 'Select a file');
                        return
                    case 'Cancel'
                        app.Reports_Status.Value = 'Closing NIR tool canceled.';
                        return
                end % end switch 
            end  % end function 
            % -------------------------------------------------------------
            function close_single_figs(src,event)   
                selection = questdlg('Close This Figure? (Remains accessible via clicking on the plots in Spectroscopy tool)', ...
                    'Close This Figure?','Yes','No', 'No');
                switch selection
                    case 'Yes'
                        NIRtool.single_figs.Visible = 'off';
                    case 'No'
                        return
                end % end switch
            end  % end function
            % -------------------------------------------------------------
            function PanelClicked(~,~,~)
                if isequal(NIRtool.single_figs.Visible, 'off')
                    NIRtool.single_figs.Visible = 'on';
                end % end if 
            end % end function 
            % -------------------------------------------------------------
            function Selected_File(src,event)
                if exist('reset','var') 
                    if (reset == 1) 
                        NIRtool.Selected_fileindx = [];
                        NIRtool.Selected_filename = [];
                    end % end if 
                end % end if
                reset = 1;
                if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
                else
                    thismanyfiles = 1;
                end % end if 
                if thismanyfiles == 1
                    reset = 0;
                    file = 1;
                    NIRtool.Selected_fileindx = file;
                    NIRtool.Selected_filename = app.import_file_names;
                else
                    for file = 1 : thismanyfiles 
                        if contains(event.Value, app.import_file_names{1, file})
                            NIRtool.Selected_fileindx = file;
                            NIRtool.Selected_filename = app.import_file_names{1, file};
                            reset = 0;
                            break; % found file index 
                        end % end if 
                    end % end for 
                end % end if 
            end % end function 
            % -------------------------------------------------------------
            function Selected_Method(src,event)
                Selected_Method_is = event.Value;
                [~, thismanymethods] = size(Methods);
                for thismethod = 1 : thismanymethods
                    if isequal(Methods{1, thismethod}, Selected_Method_is)
                        if (~isequal(Methods{1, 1}, Selected_Method_is))
                            NIRtool.task.Items = [Tasks{1,1}, Tasks{1,thismethod}];
                            NIRtool.task.Value = Tasks{1,1};
                        else
                            NIRtool.task.Items = {Tasks{1,1}};
                            NIRtool.task.Value = Tasks{1,1};
                        end % end if 
                    end % end if 
                end % end for 
            end % end function
            % -------------------------------------------------------------
            function Selected_Task(src,event)
                Selected_Task_is = event.Value;
                switch Selected_Task_is
                    case 'Chemical signatures (S, sI and rI)'
                        if (~isfield(app.tools{4},'EIOT'))
                            app = EIOT_init(app);
                        end % end if
                        app = EIOT_SrIsI(app);
                    case 'Process monitoring tool'
                        if (isfield(app.tools{4},'EIOT')) && (~isempty(app.tools{4}.EIOT.EIOT.Sprim))
                            app = EIOT_monitorProcess(app);
                        else 
                            ax = subplot(1,1,1,'Parent', NIRtool.outQ);
                            text(ax, 0.5, 0.5, 'First execute: >>> Chemical signature (S, sI and rI)');
                        end % end if 
                    case 'Estimate concentrations (with PSO)'
                        if (isfield(app.tools{4},'EIOT')) && (~isempty(app.tools{4}.EIOT.EIOT.Sprim))
                            app = EIOT_concentrations(app);
                        else 
                            ax = subplot(1,1,1,'Parent', NIRtool.outQ);
                            text(ax, 0.5, 0.5, 'First execute: >>> Chemical signature (S, sI and rI)');
                        end % end if
                    case 'Not Implemented.'
                        ax = subplot(1,1,1,'Parent', NIRtool.outQ);
                        text(ax, 0.5, 0.5, 'Not implemented yet.');
                    case 'Future Options'
                        ax = subplot(1,1,1,'Parent', NIRtool.outQ);
                        text(ax, 0.5, 0.5, 'Not implemented yet.');
                    otherwise  
                        ax = subplot(1,1,1,'Parent', NIRtool.outQ);
                        text(ax, 0.5, 0.5, 'Not found - contact development team.');
                end % end switch 
            end % end function 
            % -------------------------------------------------------------
            function app = EIOT_init(app)
                app.tools{4}.EIOT = struct(); 
                app.tools{4}.EIOT.Components = struct(); 
                app.tools{4}.EIOT.EIOT = struct(); 
                app = EIOT_dummy(app);
            end % end function 
            % -------------------------------------------------------------
            function app = EIOT_dummy(app)
                app.tools{4}.EIOT.Components.names = {'Pharmatose 200M Lactose', ...
                        'Microcrystalline cellulose PH-101', ...
                        'Polyvinylpyrrolidone (PVP) K90', 'Water', 'Product-Powder'};
            end % end 
            % -------------------------------------------------------------
            function app = EIOT_SrIsI(app)
                %---------------------------------------------------------- 
                app = list_2ignore_do(app);
                file = NIRtool.Selected_fileindx;
                [timestamps, variables] = size(app.dataset{1,file}); 
                [~, N] = size(app.tools{4}.EIOT.Components.names);
                N = N - 1;
                %----------------------------------------------------------
                % initialMass
                MCC = 500; %grams
                LACTOSE = 1000; %grams
                PVP = 58; %grams
                H2O = 1000; %grams ~= 1 Litre 
                SAMPLED = 25/1000; %25 mgr - removed to do LOD measure 
                %---------------------------------------------------------- 
                MASS = zeros(timestamps-1,N);%
                MASS(:,1) = ones(timestamps-1,1)*LACTOSE; % LACTOSE
                MASS(:,2) = ones(timestamps-1,1)*MCC; % MCC
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                        go_fid = ['db_f_', num2str(file)];
                        go_vid = [go_fid,'_v_' num2str(variable)];
                        if (contains(app.tools{1}.(go_vid).Text, 'fluid bed') && ...
                                contains(app.tools{1}.(go_vid).Text, 'spray pump') && ...
                                contains(app.tools{1}.(go_vid).Text, 'speed ac')) 
                            % this is fluid bed spray speed ac
                            Sprayed = app.tools{1}.(go_vid).NodeData;
                            Sprayed(isnan(Sprayed))=0;
                        end
                        if (contains(app.tools{1}.(go_vid).Text, 'LOD') && ...
                                contains(app.tools{1}.(go_vid).Text, 'Loss on drying')) 
                            % this is LOD (%) data
                            LOD = app.tools{1}.(go_vid).NodeData;
                            LOD(isnan(LOD))=0;
                        end
                        if (contains(app.tools{1}.(go_vid).Text, 'PHT sensors') && ...
                                contains(app.tools{1}.(go_vid).Text, 'intake abs humidity')) 
                            % this is PHT sensors intake abs humidity
                            Humidity_in = app.tools{1}.(go_vid).NodeData;
                            Humidity_in(isnan(Humidity_in))=0;
                        end
                        if (contains(app.tools{1}.(go_vid).Text, 'PHT sensors') && ...
                                contains(app.tools{1}.(go_vid).Text, 'outlet abs humidity')) 
                            % this is PHT sensors outlet abs humidity
                            Humidity_out = app.tools{1}.(go_vid).NodeData;
                            Humidity_out(isnan(Humidity_out))=0;
                        end
                        if (contains(app.tools{1}.(go_vid).Text, 'fluid bed') && ...
                                contains(app.tools{1}.(go_vid).Text, 'airflow rate ac')) 
                            % this is fluidbed airflow rate ac
                            Airflow = app.tools{1}.(go_vid).NodeData;
                            Airflow(isnan(Airflow))=0;
                            Airflow = Airflow / 1000; % liter to m3
                        end
                        if (contains(app.tools{1}.(go_vid).Text, 'fluid bed') && ...
                                contains(app.tools{1}.(go_vid).Text, 'spray pump speed ac')) 
                            % this is fluidbed airflow rate ac
                            SprayRate = app.tools{1}.(go_vid).NodeData;
                            SprayRate(isnan(SprayRate))=0;
                        end
                    end
                end 
                % Updating mass balances 
                % (PVP + H2O) = 0.0319 * SprayRate + 0.008 - from Sam
                Sprayed = 0.0319 * SprayRate + 0.008; 
                PVP_in = Sprayed * (PVP) / (PVP + H2O); 
                MASS(:,3) = PVP_in;
                H2O_in_spray = Sprayed * (H2O) / (PVP + H2O); 
                H2O_in_air = Humidity_in .* Airflow;
                H2O_out_air = Humidity_out .* Airflow;
                H2O_LOD = LOD*SAMPLED/100; % mass loss = moisture only 
                OUT = H2O_in_spray + H2O_in_air - H2O_out_air - H2O_LOD; % H2O
                OUT(OUT<0) = 0;
                MASS(:,4) = OUT;
                for i = 2:length(MASS(:,4))
                    MASS(i,3) = sum(MASS(i-1:i,3));
                    MASS(i,4) = sum(MASS(i-1:i,4));
                end
                app.tools{4}.EIOT.Components.masses = MASS; 
                app.tools{4}.EIOT.Components.fractions = ...
                    app.tools{4}.EIOT.Components.masses./...
                    cumsum(app.tools{4}.EIOT.Components.masses,2) ;
                instance = 0;
                %---------------------------------------------------------- 
                ax1 = subplot(2,2,1,'Parent', NIRtool.outQ);
                ax2 = subplot(2,2,2,'Parent', NIRtool.outQ);
                ax3 = subplot(2,2,3,'Parent', NIRtool.outQ);
                ax4 = subplot(2,2,4,'Parent', NIRtool.outQ);
                %---------------------------------------------------------- 
                NIRtool.single_figs = figure('Name', ...
                            ['EIOT in file : ', NIRtool.Selected_filename], ...
                            'Visible', 'off'); 
                NIRtool.single_figs.CloseRequestFcn = @close_single_figs;
                tabgp = uitabgroup(NIRtool.single_figs);
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                    go_fid = ['db_f_', num2str(file)];
                    go_vid = [go_fid,'_v_' num2str(variable)];
                    if (contains(app.tools{1}.(go_vid).Text, 'multieye') && ...
                            contains(app.tools{1}.(go_vid).Text, 'absorbance') && ...
                            ~contains(app.tools{1}.(go_vid).Text, 'correct'))% intensity >> absorbance
                        instance = instance + 1;
                        tab{1,instance} = uitab('Title',['Instance #', num2str(instance)], 'parent', tabgp);
                        single_ax1 = subplot(2,2,1,'Parent', tab{1,instance});
                        single_ax2 = subplot(2,2,2,'Parent', tab{1,instance});
                        single_ax3 = subplot(2,2,3,'Parent', tab{1,instance});
                        single_ax4 = subplot(2,2,4,'Parent', tab{1,instance});
                        NIR = app.tools{1}.(go_vid).NodeData{2,2} ;%* 1000; % until a fix is found
                        Spectra = app.tools{1}.(go_vid).NodeData{2,1};
                        app.tools{4}.EIOT.EIOT.DM = NIR';
                        %------------------------------------------------------
                        app.tools{4}.EIOT.EIOT.C = app.tools{4}.EIOT.Components.fractions; 
                        app.tools{4}.EIOT.EIOT.Sprim = ...
                            inv(app.tools{4}.EIOT.EIOT.C' * app.tools{4}.EIOT.EIOT.C) ...
                            * (app.tools{4}.EIOT.EIOT.C' * app.tools{4}.EIOT.EIOT.DM);
                        app.tools{4}.EIOT.EIOT.epsCH = app.tools{4}.EIOT.EIOT.DM - ...
                            (app.tools{4}.EIOT.EIOT.C * app.tools{4}.EIOT.EIOT.Sprim);
                        app.tools{4}.EIOT.EIOT.sI = mean(abs(app.tools{4}.EIOT.EIOT.epsCH),1);
                        app.tools{4}.EIOT.EIOT.rI = (app.tools{4}.EIOT.EIOT.epsCH * app.tools{4}.EIOT.EIOT.sI') ...
                            * inv(app.tools{4}.EIOT.EIOT.sI * app.tools{4}.EIOT.EIOT.sI');
                        app.tools{4}.EIOT.EIOT.rI = mean(app.tools{4}.EIOT.EIOT.rI,1);
                        %--------------------------------------------------
                        for n = 1 : N
                            plot(ax1, Spectra, app.tools{4}.EIOT.EIOT.Sprim(n,:), 'DisplayName', app.tools{4}.EIOT.Components.names{1,n});
                            hold(ax1,'on');
                            plot(single_ax1, Spectra, app.tools{4}.EIOT.EIOT.Sprim(n,:), 'DisplayName', app.tools{4}.EIOT.Components.names{1,n});
                            hold(single_ax1,'on');
                        end % end for 
                        hold(ax1,'off');
                        hold(single_ax1,'off');
                        xlabel(ax1,'Spectrum', 'FontSize', 8);
                        xlabel(single_ax1,'Spectrum');
                        title(ax1,'Signatures of pure components (S'')', 'FontSize', 8);
                        title(single_ax1,'Signatures of pure components (S'')');
                        lgd = legend(ax1);
                        lgd.FontSize = 6;
                        legend(single_ax1);
                        %--------------------------------------------------
                        for n = 1 : N
                            plot(ax2, Spectra, (app.tools{4}.EIOT.EIOT.Sprim(n,:) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))) ...
                                / (max(app.tools{4}.EIOT.EIOT.Sprim(n,:)) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))), ...
                                'DisplayName', app.tools{4}.EIOT.Components.names{1,n});
                            hold(ax2,'on');
                            plot(single_ax2, Spectra, (app.tools{4}.EIOT.EIOT.Sprim(n,:) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))) ...
                                / (max(app.tools{4}.EIOT.EIOT.Sprim(n,:)) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))), ...
                                'DisplayName', app.tools{4}.EIOT.Components.names{1,n});
                            hold(single_ax2,'on');
                        end % end for 
                        hold(ax2,'off');
                        hold(single_ax2,'off');
                        xlabel(ax2,'Spectrum', 'FontSize', 8);
                        xlabel(single_ax2,'Spectrum');
                        title(ax2,'Nondimensionalized Signatures of pure components (S'')', 'FontSize', 8);
                        title(single_ax2,'Nondimensionalized Signatures of pure components (S'')');
                        lgd = legend(ax2);
                        lgd.FontSize = 6;
                        legend(single_ax2);
                        %--------------------------------------------------
                        Resolution = floor(timestamps / N);
                        [signals, ~] = size(app.tools{4}.EIOT.EIOT.epsCH);
                        for signal = 1 : Resolution: signals
                            plot(ax3, Spectra, app.tools{4}.EIOT.EIOT.epsCH(signal,:), 'DisplayName', ...
                                ['Signal #', num2str(signal), ' | water (gr) =', num2str(MASS(signal,4))]);
                            hold(ax3,'on');
                            plot(single_ax3, Spectra, app.tools{4}.EIOT.EIOT.epsCH(signal,:), 'DisplayName', ...
                                ['Signal #', num2str(signal), ' | water (gr) =', num2str(MASS(signal,4))]);
                            hold(single_ax3,'on');
                        end % end for 
                        hold(ax3,'on');
                        plot(ax3, Spectra, app.tools{4}.EIOT.EIOT.sI, '-', 'DisplayName', 's_{I}')
                        hold(single_ax3,'on');
                        plot(single_ax3, Spectra, app.tools{4}.EIOT.EIOT.sI, '-', 'DisplayName', 's_{I}')
                        xlabel(ax3,'Spectrum', 'FontSize', 8);
                        xlabel(single_ax3,'Spectrum');
                        title(ax3,'Residues (\epsilon_{ch}) in Signals', 'FontSize', 8);
                        title(single_ax3,'Residues (\epsilon_{ch}) in Signals');
                        hold(ax3,'off');
                        hold(single_ax3,'off');
                        lgd = legend(ax3);
                        lgd.FontSize = 6;
                        legend(single_ax3);
                        %--------------------------------------------------
                        [signals, ~] = size(app.tools{4}.EIOT.EIOT.epsCH);
                        for signal = 1 : Resolution : signals
                            plot(ax4, Spectra, (app.tools{4}.EIOT.EIOT.epsCH(signal,:) - min(app.tools{4}.EIOT.EIOT.epsCH(signal,:))) ...
                                / (max(app.tools{4}.EIOT.EIOT.epsCH(signal,:)) - min(app.tools{4}.EIOT.EIOT.epsCH(signal,:))), ...
                                'DisplayName', ['Signal #', num2str(signal), ' | water (gr) =', num2str(MASS(signal,4))]);
                            hold(ax4,'on');
                            plot(single_ax4, Spectra, (app.tools{4}.EIOT.EIOT.epsCH(signal,:) - min(app.tools{4}.EIOT.EIOT.epsCH(signal,:))) ...
                                / (max(app.tools{4}.EIOT.EIOT.epsCH(signal,:)) - min(app.tools{4}.EIOT.EIOT.epsCH(signal,:))), ...
                                'DisplayName', ['Signal #', num2str(signal), ' | water (gr) =', num2str(MASS(signal,4))]);
                            hold(single_ax4,'on');
                        end % end for 
                        hold(ax4,'on');
                        plot(ax4, Spectra, (app.tools{4}.EIOT.EIOT.sI - min(app.tools{4}.EIOT.EIOT.sI)) / ...
                            (max(app.tools{4}.EIOT.EIOT.sI) - min(app.tools{4}.EIOT.EIOT.sI)), ...
                            '-', 'DisplayName', 's_{I}')
                        hold(single_ax4,'on');
                        plot(single_ax4, Spectra, (app.tools{4}.EIOT.EIOT.sI - min(app.tools{4}.EIOT.EIOT.sI)) / ...
                            (max(app.tools{4}.EIOT.EIOT.sI) - min(app.tools{4}.EIOT.EIOT.sI)), ...
                            '-', 'DisplayName', 's_{I}')
                        xlabel(ax4,'Spectrum', 'FontSize', 8);
                        xlabel(single_ax4,'Spectrum');
                        title(ax4,'Nondimensionalized Residues (\epsilon_{ch}) in Signals', 'FontSize', 8);
                        title(single_ax4,'Nondimensionalized Residues (\epsilon_{ch}) in Signals');
                        hold(ax4,'off');
                        hold(single_ax4,'off');
                        lgd = legend(ax4);
                        lgd.FontSize = 6;
                        legend(single_ax4);
                        %
                        figure()
                        for specs = 1 : 50: length(app.tools{4}.EIOT.EIOT.epsCH(signal,:))
                            plot(app.tools{4}.EIOT.EIOT.epsCH(:,specs), MASS(:, 4), 'DisplayName', ['@ ', num2str(specs)])
                            hold on
                        end
                        ylabel('water content (gr)')
                        xlabel('\epsilon_{ch} in Signals')

                        %
                        xx = [];
                        yy1 = [];
                        yy2 = [];
                        for ttime = 1 : 10: length(app.tools{4}.EIOT.EIOT.epsCH(:,1))
                            xx = [xx, ttime];
                            yy1 = [yy1, mean(app.tools{4}.EIOT.EIOT.epsCH(ttime,:))];
                            yy2 = [yy2, MASS(ttime, 4)];
                        end 
                        figure 
                        [hAx,hLine1,hLine2] = plotyy(xx,yy1,xx,yy2);
                        title('\epsilon_{ch} and moisture')
                        xlabel('timestamp')
                        ylabel(hAx(1),'\epsilon_{ch} in Signals') % left y-axis 
                        ylabel(hAx(2),'water content (gr)') % right y-axis
                        hLine1.LineStyle = '--';
                        hLine2.LineStyle = ':';
                        figure 
                        plot(yy2, yy1)
                        ylabel('\epsilon_{ch} in Signals')
                        xlabel('water content (gr)')
                        %
                        %{
                        %--------------------------------------------------
                        sgtitle(NIRtool.outQ, {['Component [n=1:',num2str(N),...
                            '] and product mass (gr) in signals (with r_{I} = ',num2str(app.tools{4}.EIOT.EIOT.rI{INIT,1}),')',':'], ...
                            ['#1: ', num2str(app.tools{4}.EIOT.Components.masses{INIT,1})], ...
                            ['#2: ', num2str(app.tools{4}.EIOT.Components.masses{END,1})],}, 'FontSize', 8, 'HorizontalAlignment', 'left');
                        sgtitle(tab{1,instance}, {['Component [n=1:',num2str(N),...
                            '] and product mass (gr) in signals (with r_{I} = ',num2str(app.tools{4}.EIOT.EIOT.rI{INIT,1}),')',':'], ...
                            ['#1: ', num2str(app.tools{4}.EIOT.Components.masses{INIT,1})], ...
                            ['#2: ', num2str(app.tools{4}.EIOT.Components.masses{END,1})],}, 'HorizontalAlignment', 'left');
                        %--------------------------------------------------
                        %}
                    end % end if 
                    end % end if ignore_list 
                end % end for 
                if instance < 1
                    ax = subplot(1,1,1,'Parent', NIRtool.outQ);
                    text(ax, 0.1, 0.5, ['NIR not found in file: ', NIRtool.Selected_filename]);
                end % end if 
            end % end function 

            %{
            function app = EIOT_SrIsI(app)
                SprayRate = 0.001;
                app = list_2ignore_do(app);
                file = NIRtool.Selected_fileindx;
                [timestamps, variables] = size(app.dataset{1,file}); 
                INIT = 1;
                END = timestamps;
                [~, N] = size(app.tools{4}.EIOT.Components.names);
                N = N - 1;
                %---------------------------------------------------------- 
                app.tools{4}.EIOT.Components.masses{INIT,1} = [1000, 500, 5.8*SprayRate, 1000*SprayRate, 0.001]; % initial masses fed to FBD
                app.tools{4}.EIOT.Components.fractions{INIT,1} = app.tools{4}.EIOT.Components.masses{INIT,1};
                app.tools{4}.EIOT.Components.fractions{INIT,1}(1:N) = ...
                    app.tools{4}.EIOT.Components.masses{INIT,1}(1:N) ...
                    / sum(app.tools{4}.EIOT.Components.masses{INIT,1}(1:N));
                app.tools{4}.EIOT.Components.masses{END,1} = ...
                    [0.01, 0.01, 0.01, 0.001, ...
                    sum(app.tools{4}.EIOT.Components.masses{INIT,1}(1:N-1))]; % final masses
                app.tools{4}.EIOT.Components.fractions{END,1} = ...
                    app.tools{4}.EIOT.Components.masses{END,1}; 
                app.tools{4}.EIOT.Components.fractions{END,1}(1:N) = ...
                    app.tools{4}.EIOT.Components.masses{END,1}(1:N) ...
                    / sum(app.tools{4}.EIOT.Components.masses{END,1}(1:N)); 
                instance = 0;
                %---------------------------------------------------------- 
                ax1 = subplot(2,2,1,'Parent', NIRtool.outQ);
                ax2 = subplot(2,2,2,'Parent', NIRtool.outQ);
                ax3 = subplot(2,2,3,'Parent', NIRtool.outQ);
                ax4 = subplot(2,2,4,'Parent', NIRtool.outQ);
                %---------------------------------------------------------- 
                NIRtool.single_figs = figure('Name', ...
                            ['EIOT in file : ', NIRtool.Selected_filename], ...
                            'Visible', 'off'); 
                NIRtool.single_figs.CloseRequestFcn = @close_single_figs;
                tabgp = uitabgroup(NIRtool.single_figs);
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                    go_fid = ['db_f_', num2str(file)];
                    go_vid = [go_fid,'_v_' num2str(variable)];
                    if (contains(app.tools{1}.(go_vid).Text, 'multieye') && ...
                            contains(app.tools{1}.(go_vid).Text, 'intensity')) 
                        instance = instance + 1;
                        tab{1,instance} = uitab('Title',['Instance #', num2str(instance)], 'parent', tabgp);
                        single_ax1 = subplot(2,2,1,'Parent', tab{1,instance});
                        single_ax2 = subplot(2,2,2,'Parent', tab{1,instance});
                        single_ax3 = subplot(2,2,3,'Parent', tab{1,instance});
                        single_ax4 = subplot(2,2,4,'Parent', tab{1,instance});
                        NIR = app.tools{1}.(go_vid).NodeData{2,2};
                        Spectra = app.tools{1}.(go_vid).NodeData{2,1};
                        app.tools{4}.EIOT.EIOT.DM = [NIR(:, 2)'; NIR(:, end)'];
                        %------------------------------------------------------
                        app.tools{4}.EIOT.EIOT.C = [
                            app.tools{4}.EIOT.Components.fractions{INIT,1}(1:N); 
                            app.tools{4}.EIOT.Components.fractions{END,1}(1:N)];
                        app.tools{4}.EIOT.EIOT.Sprim = ...
                            (inv((app.tools{4}.EIOT.EIOT.C') ...
                            * app.tools{4}.EIOT.EIOT.C) ...
                            * (app.tools{4}.EIOT.EIOT.C')) ...
                            * app.tools{4}.EIOT.EIOT.DM; 
                        app.tools{4}.EIOT.EIOT.epsCH = app.tools{4}.EIOT.EIOT.DM - ...
                            (app.tools{4}.EIOT.EIOT.C * app.tools{4}.EIOT.EIOT.Sprim);
                        app.tools{4}.EIOT.EIOT.sI = mean(abs(app.tools{4}.EIOT.EIOT.epsCH),1);
                        app.tools{4}.EIOT.EIOT.rI{INIT,1} = (app.tools{4}.EIOT.EIOT.epsCH ...
                            * app.tools{4}.EIOT.EIOT.sI') ...
                            * inv(app.tools{4}.EIOT.EIOT.sI * app.tools{4}.EIOT.EIOT.sI');
                        app.tools{4}.EIOT.EIOT.rI{INIT,1} = mean(app.tools{4}.EIOT.EIOT.rI{INIT,1});
                        %--------------------------------------------------
                        for n = 1 : N
                            plot(ax1, Spectra, app.tools{4}.EIOT.EIOT.Sprim(n,:), 'DisplayName', app.tools{4}.EIOT.Components.names{1,n});
                            hold(ax1,'on');
                            plot(single_ax1, Spectra, app.tools{4}.EIOT.EIOT.Sprim(n,:), 'DisplayName', app.tools{4}.EIOT.Components.names{1,n});
                            hold(single_ax1,'on');
                        end % end for 
                        hold(ax1,'off');
                        hold(single_ax1,'off');
                        xlabel(ax1,'Spectrum', 'FontSize', 8);
                        xlabel(single_ax1,'Spectrum');
                        title(ax1,'Signatures of pure components (S'')', 'FontSize', 8);
                        title(single_ax1,'Signatures of pure components (S'')');
                        lgd = legend(ax1);
                        lgd.FontSize = 6;
                        legend(single_ax1);
                        %--------------------------------------------------
                        for n = 1 : N
                            plot(ax2, Spectra, (app.tools{4}.EIOT.EIOT.Sprim(n,:) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))) ...
                                / (max(app.tools{4}.EIOT.EIOT.Sprim(n,:)) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))), ...
                                'DisplayName', app.tools{4}.EIOT.Components.names{1,n});
                            hold(ax2,'on');
                            plot(single_ax2, Spectra, (app.tools{4}.EIOT.EIOT.Sprim(n,:) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))) ...
                                / (max(app.tools{4}.EIOT.EIOT.Sprim(n,:)) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))), ...
                                'DisplayName', app.tools{4}.EIOT.Components.names{1,n});
                            hold(single_ax2,'on');
                        end % end for 
                        hold(ax2,'off');
                        hold(single_ax2,'off');
                        xlabel(ax2,'Spectrum', 'FontSize', 8);
                        xlabel(single_ax2,'Spectrum');
                        title(ax2,'Nondimensionalized Signatures of pure components (S'')', 'FontSize', 8);
                        title(single_ax2,'Nondimensionalized Signatures of pure components (S'')');
                        lgd = legend(ax2);
                        lgd.FontSize = 6;
                        legend(single_ax2);
                        %--------------------------------------------------
                        [signals, ~] = size(app.tools{4}.EIOT.EIOT.epsCH);
                        for signal = 1 : signals
                            plot(ax3, Spectra, app.tools{4}.EIOT.EIOT.epsCH(signal,:), 'DisplayName', ...
                                ['Signal #', num2str(signal)]);
                            hold(ax3,'on');
                            plot(single_ax3, Spectra, app.tools{4}.EIOT.EIOT.epsCH(signal,:), 'DisplayName', ...
                                ['Signal #', num2str(signal)]);
                            hold(single_ax3,'on');
                        end % end for 
                        hold(ax3,'on');
                        plot(ax3, Spectra, app.tools{4}.EIOT.EIOT.sI, '-', 'DisplayName', 's_{I}')
                        hold(single_ax3,'on');
                        plot(single_ax3, Spectra, app.tools{4}.EIOT.EIOT.sI, '-', 'DisplayName', 's_{I}')
                        xlabel(ax3,'Spectrum', 'FontSize', 8);
                        xlabel(single_ax3,'Spectrum');
                        title(ax3,'Residues (\epsilon_{ch}) in Signals', 'FontSize', 8);
                        title(single_ax3,'Residues (\epsilon_{ch}) in Signals');
                        hold(ax3,'off');
                        hold(single_ax3,'off');
                        lgd = legend(ax3);
                        lgd.FontSize = 6;
                        legend(single_ax3);
                        %--------------------------------------------------
                        [signals, ~] = size(app.tools{4}.EIOT.EIOT.epsCH);
                        for signal = 1 : signals
                            plot(ax4, Spectra, (app.tools{4}.EIOT.EIOT.epsCH(signal,:) - min(app.tools{4}.EIOT.EIOT.epsCH(signal,:))) ...
                                / (max(app.tools{4}.EIOT.EIOT.epsCH(signal,:)) - min(app.tools{4}.EIOT.EIOT.epsCH(signal,:))), ...
                                'DisplayName', ['Signal #', num2str(signal)]);
                            hold(ax4,'on');
                            plot(single_ax4, Spectra, (app.tools{4}.EIOT.EIOT.epsCH(signal,:) - min(app.tools{4}.EIOT.EIOT.epsCH(signal,:))) ...
                                / (max(app.tools{4}.EIOT.EIOT.epsCH(signal,:)) - min(app.tools{4}.EIOT.EIOT.epsCH(signal,:))), ...
                                'DisplayName', ['Signal #', num2str(signal)]);
                            hold(single_ax4,'on');
                        end % end for 
                        hold(ax4,'on');
                        plot(ax4, Spectra, (app.tools{4}.EIOT.EIOT.sI - min(app.tools{4}.EIOT.EIOT.sI)) / ...
                            (max(app.tools{4}.EIOT.EIOT.sI) - min(app.tools{4}.EIOT.EIOT.sI)), ...
                            '-', 'DisplayName', 's_{I}')
                        hold(single_ax4,'on');
                        plot(single_ax4, Spectra, (app.tools{4}.EIOT.EIOT.sI - min(app.tools{4}.EIOT.EIOT.sI)) / ...
                            (max(app.tools{4}.EIOT.EIOT.sI) - min(app.tools{4}.EIOT.EIOT.sI)), ...
                            '-', 'DisplayName', 's_{I}')
                        xlabel(ax4,'Spectrum', 'FontSize', 8);
                        xlabel(single_ax4,'Spectrum');
                        title(ax4,'Nondimensionalized Residues (\epsilon_{ch}) in Signals', 'FontSize', 8);
                        title(single_ax4,'Nondimensionalized Residues (\epsilon_{ch}) in Signals');
                        hold(ax4,'off');
                        hold(single_ax4,'off');
                        lgd = legend(ax4);
                        lgd.FontSize = 6;
                        legend(single_ax4);
                        %--------------------------------------------------
                        sgtitle(NIRtool.outQ, {['Component [n=1:',num2str(N),...
                            '] and product mass (gr) in signals (with r_{I} = ',num2str(app.tools{4}.EIOT.EIOT.rI{INIT,1}),')',':'], ...
                            ['#1: ', num2str(app.tools{4}.EIOT.Components.masses{INIT,1})], ...
                            ['#2: ', num2str(app.tools{4}.EIOT.Components.masses{END,1})],}, 'FontSize', 8, 'HorizontalAlignment', 'left');
                        sgtitle(tab{1,instance}, {['Component [n=1:',num2str(N),...
                            '] and product mass (gr) in signals (with r_{I} = ',num2str(app.tools{4}.EIOT.EIOT.rI{INIT,1}),')',':'], ...
                            ['#1: ', num2str(app.tools{4}.EIOT.Components.masses{INIT,1})], ...
                            ['#2: ', num2str(app.tools{4}.EIOT.Components.masses{END,1})],}, 'HorizontalAlignment', 'left');
                        %--------------------------------------------------
                    end % end if 
                    end % end if ignore_list 
                end % end for 
                if instance < 1
                    ax = subplot(1,1,1,'Parent', NIRtool.outQ);
                    text(ax, 0.1, 0.5, ['NIR not found in file: ', NIRtool.Selected_filename]);
                end % end if 
            end % end function 
            %}
            % -------------------------------------------------------------
            %{
            function app = EIOT_FilterS_norm(app)
                app = list_2ignore_do(app);
                file = NIRtool.Selected_fileindx;
                [timestamps, variables] = size(app.dataset{1,file}); 
                [~, N] = size(app.tools{4}.EIOT.Components.names);
                N = N - 1;
                resolution = floor(timestamps / N);
                instance = 0;
                %---------------------------------------------------------- 
                NIRtool.single_figs = figure('Name', ...
                            ['EIOT in file : ', NIRtool.Selected_filename], ...
                            'Visible', 'off'); 
                NIRtool.single_figs.CloseRequestFcn = @close_single_figs;
                tabgp = uitabgroup(NIRtool.single_figs);
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                    go_fid = ['db_f_', num2str(file)];
                    go_vid = [go_fid,'_v_' num2str(variable)];
                    if (contains(app.tools{1}.(go_vid).Text, 'multieye') && ...
                            contains(app.tools{1}.(go_vid).Text, 'intensity')) 
                        instance = instance + 1;
                        tab{1,instance} = uitab('Title',['Instance #', num2str(instance)], 'parent', tabgp);
                        NIR = app.tools{1}.(go_vid).NodeData{2,2};
                        Spectra = app.tools{1}.(go_vid).NodeData{2,1};
                        for n = 1 : N
                            v = (app.tools{4}.EIOT.EIOT.Sprim(n,:) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:))) ...
                                    / (max(app.tools{4}.EIOT.EIOT.Sprim(n,:)) - min(app.tools{4}.EIOT.EIOT.Sprim(n,:)));
                            plt1 = subplot(2,2, n, 'Parent', NIRtool.outQ);
                            plt2 = subplot(2,2, n, 'Parent', tab{1,instance});
                            for timestamp = 2 : resolution : timestamps-1
                                u = (NIR(:, timestamp) - min(NIR(:, timestamp))) ...
                                    / (max(NIR(:, timestamp)) - min(NIR(:, timestamp)));
                                plot(plt1, Spectra, filter(u, v, Spectra), ...
                                    'DisplayName', ['timestamp = ', num2str(timestamp)]);
                                hold(plt1, 'on');
                                plot(plt2, Spectra, filter(u, v, Spectra), ...
                                    'DisplayName', ['timestamp = ', num2str(timestamp)]);
                                hold(plt2, 'on');
                            end % end for
                            hold(plt1, 'off');
                            xlabel(plt1,'Spectrum', 'FontSize',8);
                            ylabel(plt1,'Filtered value', 'FontSize',8); 
                            title(plt1,['Filtered for ', app.tools{4}.EIOT.Components.names{1,n}], 'FontSize',8);
                            legend(plt1, 'FontSize',8)
                            hold(plt2, 'off');
                            xlabel(plt2,'Spectrum');
                            ylabel(plt2,'Filtered value'); 
                            title(plt2,['Filtered for ', app.tools{4}.EIOT.Components.names{1,n}]);
                            legend(plt2)
                        end % end for 
                    end % end if 
                    end %end if ignorelist 
                end % end for 
            end % end function 
            %}
            % -------------------------------------------------------------
            %{
            function app = EIOT_FilterS(app)
                app = list_2ignore_do(app);
                file = NIRtool.Selected_fileindx;
                [timestamps, variables] = size(app.dataset{1,file}); 
                [~, N] = size(app.tools{4}.EIOT.Components.names);
                N = N - 1;
                resolution = floor(timestamps / N);
                instance = 0;
                %---------------------------------------------------------- 
                NIRtool.single_figs = figure('Name', ...
                            ['EIOT in file : ', NIRtool.Selected_filename], ...
                            'Visible', 'off'); 
                NIRtool.single_figs.CloseRequestFcn = @close_single_figs;
                tabgp = uitabgroup(NIRtool.single_figs);
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                    go_fid = ['db_f_', num2str(file)];
                    go_vid = [go_fid,'_v_' num2str(variable)];
                    if (contains(app.tools{1}.(go_vid).Text, 'multieye') && ...
                            contains(app.tools{1}.(go_vid).Text, 'intensity')) 
                        instance = instance + 1;
                        tab{1,instance} = uitab('Title',['Instance #', num2str(instance)], 'parent', tabgp);
                        NIR = app.tools{1}.(go_vid).NodeData{2,2};
                        Spectra = app.tools{1}.(go_vid).NodeData{2,1};
                        for n = 1 : N
                            v = app.tools{4}.EIOT.EIOT.Sprim(n,:);
                            plt1 = subplot(2,2, n, 'Parent', NIRtool.outQ);
                            plt2 = subplot(2,2, n, 'Parent', tab{1,instance});
                            for timestamp = 2 : resolution : timestamps-1
                                u = NIR(:, timestamp);
                                plot(plt1, Spectra, filter(u, v, Spectra), ...
                                    'DisplayName', ['timestamp = ', num2str(timestamp)]);
                                hold(plt1, 'on');
                                plot(plt2, Spectra, filter(u, v, Spectra), ...
                                    'DisplayName', ['timestamp = ', num2str(timestamp)]);
                                hold(plt2, 'on');
                            end % end for
                            hold(plt1, 'off');
                            xlabel(plt1,'Spectrum', 'FontSize',8);
                            ylabel(plt1,'Filtered value', 'FontSize',8); 
                            title(plt1,['Filtered for ', app.tools{4}.EIOT.Components.names{1,n}], 'FontSize',8);
                            legend(plt1, 'FontSize',8)
                            hold(plt2, 'off');
                            xlabel(plt2,'Spectrum');
                            ylabel(plt2,'Filtered value'); 
                            title(plt2,['Filtered for ', app.tools{4}.EIOT.Components.names{1,n}]);
                            legend(plt2)
                        end % end for 
                    end % end if 
                    end % end if ignorelist 
                end % end for 
            end % end function 
            %}
            % -------------------------------------------------------------
            function app = EIOT_concentrations(app)
                app = list_2ignore_do(app);
                sgtitle(NIRtool.outQ, {''}, 'FontSize', 8);
                plt = subplot(1,1,1, 'Parent', NIRtool.outQ);
                text(plt, 0.1, 0.5, 'Long process, wait for results to appear ....', 'FontWeight', 'bold');
                drawnow;
                %---------------------------------------------------------
                plt1 = subplot(2,2,[1,2], 'Parent', NIRtool.outQ);
                plt2 = subplot(2,2,3, 'Parent', NIRtool.outQ);
                plt3 = subplot(2,2,4, 'Parent', NIRtool.outQ);
                %---------------------------------------------------------- 
                file = NIRtool.Selected_fileindx;
                [timestamps, variables] = size(app.dataset{1,file}); 
                [~, N] = size(app.tools{4}.EIOT.Components.names);
                N = N - 1;
                instance = 0;
                %---------------------------------------------------------- 
                NIRtool.single_figs = figure('Name', ...
                            ['EIOT in file : ', NIRtool.Selected_filename], ...
                            'Visible', 'off'); 
                tabgp = uitabgroup(NIRtool.single_figs);
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                    go_fid = ['db_f_', num2str(file)];
                    go_vid = [go_fid,'_v_' num2str(variable)];
                    if (contains(app.tools{1}.(go_vid).Text, 'multieye') && ...
                            contains(app.tools{1}.(go_vid).Text, 'absorbance')) % intnsity >> absorbance  
                        instance = instance + 1;
                        tab{1,instance} = uitab('Title',['Instance #', num2str(instance)], 'parent', tabgp);
                        %--------------------------------------------------
                        plt1_single = subplot(2,2,[1,2], 'Parent', tab{1,instance});
                        plt2_single = subplot(2,2,3, 'Parent', tab{1,instance});
                        plt3_single = subplot(2,2,4, 'Parent', tab{1,instance});
                        %--------------------------------------------------
                        NIR = app.tools{1}.(go_vid).NodeData{2,2}; 
                        cValues = zeros(timestamps, N);
                        riValues = zeros(timestamps, 1);
                        riValues(1,1) = app.tools{4}.EIOT.EIOT.rI{1,1};
                        cValues(1, :) = app.tools{4}.EIOT.Components.fractions{1,1}(1:N);
                        for timestamp = 2 : timestamps
                            [app, PSO] = Calculate_Concentrations_PSO(app, NIR, N, timestamp, timestamps, plt1, plt1_single);
                            app.tools{4}.EIOT.Components.fractions{timestamp,1}(1:N) = PSO.Found(1:N);
                            app.tools{4}.EIOT.EIOT.rI{timestamp,1} = PSO.Found(end);
                            riValues(timestamp,1) = PSO.Found(end);
                            cValues(timestamp, :) = PSO.Found(1:N);
                            %----------------------------------------------
                            for n = 1 : N
                            plot(plt2, 1:timestamp, cValues(1:timestamp,n), '-', 'DisplayName', app.tools{4}.EIOT. Components.names{1,n});
                            hold(plt2, 'on');
                            end 
                            hold(plt2, 'off');
                            xlabel(plt2, 'timestamp','FontSize',8)
                            ylabel(plt2, 'mass fractions','FontSize',8)
                            title(plt2, 'mass fraction profiles','FontSize',8)
                            axis(plt2, [0 inf 0 1]);
                            legend(plt2)
                            plot(plt3, 1:timestamp, riValues(1:timestamp,1))
                            xlabel(plt3, 'timestamp','FontSize',8)
                            ylabel(plt3, 'r_{I}','FontSize',8)
                            title(plt3, 'r_{I} variations','FontSize',8)
                            %----------------------------------------------
                            for n = 1 : N
                            plot(plt2_single, 1:timestamp, cValues(1:timestamp,n), '-', 'DisplayName', app.tools{4}.EIOT. Components.names{1,n});
                            hold(plt2_single, 'on');
                            end 
                            hold(plt2_single, 'off');
                            xlabel(plt2_single, 'timestamp','FontSize',8)
                            ylabel(plt2_single, 'mass fractions','FontSize',8)
                            title(plt2_single, 'mass fraction profiles','FontSize',8)
                            axis(plt2_single, [0 inf 0 1]);
                            legend(plt2_single)
                            plot(plt3_single, 1:timestamp, riValues(1:timestamp,1))
                            xlabel(plt3_single, 'timestamp','FontSize',8)
                            ylabel(plt3_single, 'r_{I}','FontSize',8)
                            title(plt3_single, 'r_{I} variations','FontSize',8)
                            %----------------------------------------------
                        end % end for 
                    end  % end if 
                    end % end if ignorelist 
                end % end for 
                NIRtool.single_figs.CloseRequestFcn = @close_single_figs;
            end % end function 
            % -------------------------------------------------------------
            function [app, PSO] = Calculate_Concentrations_PSO(app, NIR, N, timestamp, timestamps, plt1, plt1_single)
                PSO.MaxIt = 100; 
                PSO.nPop = 250; % Population Size (Swarm Size)
                PSO.nObjFun = 1; % only 1 objective === Error 
                PSO.nVar = (N + 1); % N component + rI 
                PSO.VarSize = [1 PSO.nVar];
                for n = 1 : N
                    clip = 0.2;
                    dxH = app.tools{4}.EIOT.Components.fractions{timestamp-1,1}(n) * (1 + clip);
                    if dxH >= 1
                        dxH = 999e-3;
                    end
                    dxL = app.tools{4}.EIOT.Components.fractions{timestamp-1,1}(n) * (1 - clip);
                    if dxL <= 0
                        dxL = 1e-3;
                    end
                    PSO.VarMin(n) = dxL;
                    PSO.VarMax(n) = dxH;
                end % end for 
                PSO.VarMin(end+1) = -1; % Lower Bound of rI
                PSO.VarMax(end+1) = 1; % Upper Bound of rI
                %----------------------------------------------------------
                PSO.phi(1) = 2.05; % Constriction Coefficients
                PSO.phi(2) = 2.05;
                PSO.PHI = sum(PSO.phi);
                PSO.chi = 2/(PSO.PHI-2+sqrt(PSO.PHI^2-4*PSO.PHI));
                PSO.w = PSO.chi; % Inertia Weight
                PSO.wdamp = 0.1; % Inertia Weight Damping Ratio
                PSO.c(1) = PSO.chi * PSO.phi(1); % Personal Learning Coefficient
                PSO.c(2) = PSO.chi * PSO.phi(2); % Global Learning Coefficient
                PSO.VelMax = 0.01 * (PSO.VarMax - PSO.VarMin);
                PSO.VelMin = -PSO.VelMax;
                PSO.empty_particle.Position = [];
                PSO.empty_particle.Fun = [];
                PSO.empty_particle.Velocity = [];
                PSO.empty_particle.Best.Position = [];
                PSO.empty_particle.Best.Fun = [];
                PSO.particle = repmat(PSO.empty_particle, PSO.nPop,1);
                PSO.GlobalBest.Fun = ones(PSO.nObjFun, 1) * inf; 
                %----------------------------------------------------------
                dm = NIR(:, timestamp);
                %----------------------------------------------------------
                for i = 1 : PSO.nPop % initializaion 
                    PSO.particle(i).Position = unifrnd(PSO.VarMin, PSO.VarMax, PSO.VarSize); % Initialize Position
                    PSO.particle(i).Position(1:N) = PSO.particle(i).Position(1:N) / sum(PSO.particle(i).Position(1:N)); % force mass balance 
                    app.tools{4}.EIOT.Components.fractions{timestamp,1}(1:N) = PSO.particle(i).Position(1:N);
                    PSO.particle(i).Best.Position = PSO.particle(i).Position; % Update Personal Best
                    PSO.particle(i).Velocity = zeros(PSO.VarSize); % Initialize Velocity
                    app.tools{4}.EIOT.EIOT.rI{timestamp,1} = PSO.particle(i).Position(end);
                    % 
                    dm_bar = (app.tools{4}.EIOT.EIOT.Sprim') * (app.tools{4}.EIOT.Components.fractions{timestamp,1}(1:N)') ...
                        + (app.tools{4}.EIOT.EIOT.sI') * app.tools{4}.EIOT.EIOT.rI{timestamp,1};
                    Err = dm - dm_bar;
                    ERR = abs(Err' * Err);
                    check_flag = 0;
                    for nobjfun = 1 : PSO.nObjFun
                        PSO.particle(i).Fun(nobjfun, 1) = ERR; % 
                        PSO.particle(i).Best.Fun(nobjfun, 1) = PSO.particle(i).Fun(nobjfun, 1);
                        if PSO.particle(i).Best.Fun(nobjfun, 1) < PSO.GlobalBest.Fun(nobjfun, 1)
                            check_flag = check_flag + 1;
                        end % end if 
                    end % end for 
                    if check_flag >= PSO.nObjFun
                        PSO.GlobalBest = PSO.particle(i).Best; % Update Global Best
                    end % end if 
                end % end for 
                %----------------------------------------------------------
                errors_Iter = zeros(1,PSO.MaxIt);
                errors_Pops = zeros(1,PSO.nPop);
                for it= 1 : PSO.MaxIt % PSO Main Loop
                    for i = 1 : PSO.nPop
                        PSO.particle(i).Velocity = PSO.w * PSO.particle(i).Velocity ...
                            + PSO.c(1) * rand(PSO.VarSize) .* (PSO.particle(i).Best.Position - PSO.particle(i).Position)...
                            + PSO.c(2) * rand(PSO.VarSize) .* (PSO.GlobalBest.Position - PSO.particle(i).Position); % Update Velocity
                        PSO.particle(i).Velocity(1:N) = max(PSO.particle(i).Velocity(1:N), PSO.VelMin(1:N));
                        PSO.particle(i).Velocity(end) = max(PSO.particle(i).Velocity(end), PSO.VelMin(end)); 
                        PSO.particle(i).Velocity(1:N) = min(PSO.particle(i).Velocity(1:N), PSO.VelMax(1:N));
                        PSO.particle(i).Velocity(end) = min(PSO.particle(i).Velocity(end), PSO.VelMax(end));
                        PSO.particle(i).Position = PSO.particle(i).Position + PSO.particle(i).Velocity; % Update Position
                        PSO.particle(i).Position(1:N) = PSO.particle(i).Position(1:N) / sum(PSO.particle(i).Position(1:N)); % force mass balance 
                        PSO.IsOutside = (PSO.particle(i).Position < PSO.VarMin | PSO.particle(i).Position > PSO.VarMax); % Velocity Mirror Effect
                        PSO.particle(i).Velocity(PSO.IsOutside) = -PSO.particle(i).Velocity(PSO.IsOutside);
                        PSO.particle(i).Position(1:N) = max(PSO.particle(i).Position(1:N), PSO.VarMin(1:N)); 
                        PSO.particle(i).Position(1:N) = min(PSO.particle(i).Position(1:N), PSO.VarMax(1:N));
                        PSO.particle(i).Position(end) = max(PSO.particle(i).Position(end), PSO.VarMin(end)); 
                        PSO.particle(i).Position(end) = min(PSO.particle(i).Position(end), PSO.VarMax(end));
                        % Evaluation
                        app.tools{4}.EIOT.Components.fractions{timestamp,1}(1:N) = PSO.particle(i).Position(1:N);
                        app.tools{4}.EIOT.EIOT.rI{timestamp,1} = PSO.particle(i).Position(end);
                        dm_bar = (app.tools{4}.EIOT.EIOT.Sprim') * (app.tools{4}.EIOT.Components.fractions{timestamp,1}(1:N)') ...
                            + (app.tools{4}.EIOT.EIOT.sI') * app.tools{4}.EIOT.EIOT.rI{timestamp,1};
                        Err = dm - dm_bar;
                        %ERR = 1 / abs(Err' * Err);
                        ERR = abs(Err' * Err);
                        errors_Pops(1,i) = ERR;
                        for nobjfun = 1 : PSO.nObjFun
                            PSO.particle(i).Fun(nobjfun, 1) = ERR; % 
                            if  PSO.particle(i).Fun(nobjfun, 1) <= PSO.particle(i).Best.Fun(nobjfun, 1)
                                PSO.particle(i).Best.Fun(nobjfun, 1) = PSO.particle(i).Fun(nobjfun, 1);
                            end % end if 
                        end % end for 
                        check_flag = 0;
                        for nobjfun = 1 : PSO.nObjFun
                            if PSO.particle(i).Best.Fun(nobjfun, 1) < PSO.GlobalBest.Fun(nobjfun, 1)
                                check_flag = check_flag + 1;
                            end % end if 
                        end % end for 
                        if check_flag >= PSO.nObjFun
                            PSO.GlobalBest = PSO.particle(i).Best; % Update Global Best
                        end % end if 
                    end % end for
                    %------------------------------------------------------
                    errors_Iter(1, it) = sum(errors_Pops) / PSO.nPop;
                    plot(plt1, 1:it, errors_Iter(1, 1:it), 'o');
                    xlabel(plt1, ['PSO iterations (out of ', num2str(PSO.MaxIt), ')'],'FontSize',8)
                    ylabel(plt1, 'Error value','FontSize',8)
                    title(plt1, ['PSO with ', num2str(PSO.nPop),' particles, timestamp : ', ...
                        num2str(timestamp), ' (out of ', num2str(timestamps), ')'],'FontSize',8)
                    axis(plt1, [0 PSO.MaxIt -inf inf]);
                    drawnow
                    %------------------------------------------------------
                    plot(plt1_single, 1:it, errors_Iter(1, 1:it), 'o');
                    xlabel(plt1_single, ['PSO iterations (out of ', num2str(PSO.MaxIt), ')'],'FontSize',8)
                    ylabel(plt1_single, 'Error value','FontSize',8)
                    title(plt1_single, ['PSO with ', num2str(PSO.nPop),' particles, timestamp : ', ...
                        num2str(timestamp), ' (out of ', num2str(timestamps), ')'],'FontSize',8)
                    axis(plt1_single, [0 PSO.MaxIt -inf inf]);
                    %------------------------------------------------------
                    PSO.w = PSO.w * PSO.wdamp;
                end % end for 
                %----------------------------------------------------------
                PSO.Found = PSO.GlobalBest.Position;
            end % end function 
            % -------------------------------------------------------------
            %{
            function eyecon_10_histogram_numeric(app)
                app = list_2ignore_do(app);
                file = NIRtool.Selected_fileindx;
                [timestamps, variables] = size(app.dataset{1,file}); 
                resolution = ceil(timestamps / 10);
                plts = length(2 : resolution : timestamps);
                nbins = plts;
                %---------------------------------------------------------- 
                instance = 0;
                %---------------------------------------------------------- 
                NIRtool.single_figs = figure('Name', ...
                            ['EyeCON histograms in file : ', NIRtool.Selected_filename], ...
                            'Visible', 'off'); 
                NIRtool.single_figs.CloseRequestFcn = @close_single_figs;
                sgtitle(NIRtool.outQ, {'Histograms (numeric) - normalized based on probability'})
                tabgp = uitabgroup(NIRtool.single_figs);
                for plti = 1 : plts
                    plt(plti) = subplot(ceil(plts/2), 2, plti,'Parent', NIRtool.outQ);
                end % end for 
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                    go_fid = ['db_f_', num2str(file)];
                    go_vid = [go_fid,'_v_' num2str(variable)];
                    if (contains(app.tools{1}.(go_vid).Text, 'eyecon') && ...
                            contains(app.tools{1}.(go_vid).Text, 'numeric') && ...
                            contains(app.tools{1}.(go_vid).Text, 'histogram')) 
                        instance = instance + 1;
                        tab{1,instance} = uitab('Title',['Instance #', num2str(instance)], 'parent', tabgp);
                        for plti = 1 : plts
                            plt_single(plti) = subplot(ceil(plts/2), 2, plti,'Parent', tab{1,instance});
                        end % end for 
                        %eyeCON_x = app.tools{1}.(go_vid).NodeData{2,1};
                        eyeCON_y = app.tools{1}.(go_vid).NodeData{2,2};
                        plti = 0;
                        for timestamp = 10 : resolution : timestamps
                            plti = plti + 1;
                            histogram(plt(plti), eyeCON_y(:, timestamp), nbins, 'Normalization', 'probability'); %eyeCON_x, 
                            histogram(plt_single(plti), eyeCON_y(:, timestamp), nbins, 'Normalization', 'probability'); %eyeCON_x, 
                            if plti == (plts-1)
                            xlabel(plt(plti), '', 'FontSize', 8)
                            ylabel(plt(plti), '', 'FontSize', 8)
                            xlabel(plt_single(plti), '', 'FontSize', 8)
                            ylabel(plt_single(plti), '', 'FontSize', 8)
                            end
                            title(plt(plti), ['timestamp : ', num2str(timestamp)])
                            title(plt_single(plti), ['timestamp : ', num2str(timestamp)])
                        end % end for 
                    end % end if 
                    end % end if 
                end % end for 
            end % end function 
            %}
            % -------------------------------------------------------------
            %{
            function eyecon_10_histogram_volumetric(app)
            app = list_2ignore_do(app);
                file = NIRtool.Selected_fileindx;
                [timestamps, variables] = size(app.dataset{1,file}); 
                resolution = ceil(timestamps / 10);
                plts = length(2 : resolution : timestamps);
                nbins = plts;
                %---------------------------------------------------------- 
                instance = 0;
                %---------------------------------------------------------- 
                NIRtool.single_figs = figure('Name', ...
                            ['EyeCON histograms in file : ', NIRtool.Selected_filename], ...
                            'Visible', 'off'); 
                NIRtool.single_figs.CloseRequestFcn = @close_single_figs;
                sgtitle(NIRtool.outQ, {'Histograms (volumetric) - normalized based on probability'})
                tabgp = uitabgroup(NIRtool.single_figs);
                for plti = 1 : plts
                    plt(plti) = subplot(ceil(plts/2), 2, plti,'Parent', NIRtool.outQ);
                end % end for 
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                    go_fid = ['db_f_', num2str(file)];
                    go_vid = [go_fid,'_v_' num2str(variable)];
                    if (contains(app.tools{1}.(go_vid).Text, 'eyecon') && ...
                            contains(app.tools{1}.(go_vid).Text, 'volumetric') && ...
                            contains(app.tools{1}.(go_vid).Text, 'histogram')) 
                        instance = instance + 1;
                        tab{1,instance} = uitab('Title',['Instance #', num2str(instance)], 'parent', tabgp);
                        for plti = 1 : plts
                            plt_single(plti) = subplot(ceil(plts/2), 2, plti,'Parent', tab{1,instance});
                        end % end for 
                        %eyeCON_x = app.tools{1}.(go_vid).NodeData{2,1};
                        eyeCON_y = app.tools{1}.(go_vid).NodeData{2,2};
                        plti = 0;
                        for timestamp = 10 : resolution : timestamps
                            plti = plti + 1;
                            histogram(plt(plti), eyeCON_y(:, timestamp), nbins, 'Normalization', 'probability'); %eyeCON_x, 
                            histogram(plt_single(plti), eyeCON_y(:, timestamp), nbins, 'Normalization', 'probability'); %eyeCON_x, 
                            if plti == (plts-1)
                            xlabel(plt(plti), '', 'FontSize', 8)
                            ylabel(plt(plti), '', 'FontSize', 8)
                            xlabel(plt_single(plti), '', 'FontSize', 8)
                            ylabel(plt_single(plti), '', 'FontSize', 8)
                            end
                            title(plt(plti), ['timestamp : ', num2str(timestamp)])
                            title(plt_single(plti), ['timestamp : ', num2str(timestamp)])
                        end % end for 
                    end % end if 
                    end % end if 
                end % end for 
            end % end function 
            %}
            % -------------------------------------------------------------
            function app = EIOT_monitorProcess(app)
                app = list_2ignore_do(app);
                file = NIRtool.Selected_fileindx;
                [timestamps, variables] = size(app.dataset{1,file}); 
                [~, N] = size(app.tools{4}.EIOT.Components.names);
                N = N - 1;
                resolution = 2; % every seconds 
                instance = 0;
                %---------------------------------------------------------- 
                NIRtool.single_figs = figure('Name', ...
                            ['EIOT in file : ', NIRtool.Selected_filename], ...
                            'Visible', 'off'); 
                NIRtool.single_figs.CloseRequestFcn = @close_single_figs;
                tabgp = uitabgroup(NIRtool.single_figs);
                for variable = 1 : variables
                    if (~contains(app.list_2ignore, ...
                            app.dataset{1,file}{1,variable}))
                    go_fid = ['db_f_', num2str(file)];
                    go_vid = [go_fid,'_v_' num2str(variable)];
                    % for eyecon data plots 
                    if (contains(app.tools{1}.(go_vid).Text, 'eyecon') && ...
                            contains(app.tools{1}.(go_vid).Text, 'histogram') && ...
                            contains(app.tools{1}.(go_vid).Text, 'numeric'))
                        eyecone_histogram_num = app.tools{1}.(go_vid).NodeData{2,2};
                    end % end if 
                    if (contains(app.tools{1}.(go_vid).Text, 'eyecon') && ...
                            contains(app.tools{1}.(go_vid).Text, 'histogram') && ...
                            contains(app.tools{1}.(go_vid).Text, 'volumetric'))
                        eyecone_histogram_vol = app.tools{1}.(go_vid).NodeData{2,2};
                    end
                    % for multieye data plots 
                    if (contains(app.tools{1}.(go_vid).Text, 'multieye') && ...
                            contains(app.tools{1}.(go_vid).Text, 'intensity'))
                        multieye_intensity = app.tools{1}.(go_vid).NodeData{2,2};
                    end % end if 
                    if (contains(app.tools{1}.(go_vid).Text, 'multieye') && ...
                            contains(app.tools{1}.(go_vid).Text, 'absorbance'))
                        multieye_absorbance = app.tools{1}.(go_vid).NodeData{2,2};
                    end
                    % for spray rate vs LOD


                    if (contains(app.tools{1}.(go_vid).Text, 'fluid bed') && ...
                            contains(app.tools{1}.(go_vid).Text, 'airflow rate ac'))
                        if ~exist('fluid_bed_temperatures', "var")
                            fluid_bed_temperatures{1,1} = go_vid;
                        else 
                            fluid_bed_temperatures{1,end+1} = go_vid;
                        end % end if 
                    end % end if 

                    if (contains(app.tools{1}.(go_vid).Text, 'fluid bed') && ...
                            contains(app.tools{1}.(go_vid).Text, 'spray'))
                        if ~exist('fluid_bed_sprays', "var")
                            fluid_bed_sprays{1,1} = go_vid;
                        else 
                            fluid_bed_sprays{1,end+1} = go_vid;
                        end % end if 
                    end % end if 


                    if (contains(app.tools{1}.(go_vid).Text, 'multieye') && ...
                            contains(app.tools{1}.(go_vid).Text, 'intensity')) % intensity >> absorbance
                        instance = instance + 1;
                        tab{1,instance} = uitab('Title',['Instance #', num2str(instance)], 'parent', tabgp);
                        NIR = app.tools{1}.(go_vid).NodeData{2,2};
                        Spectra = app.tools{1}.(go_vid).NodeData{2,1};
                        
                        plt_eyecon_window = figure('Name', 'EeyCON histograms', 'Visible', 'off');
                        plt_eyecon_vol = subplot(2,1,1, 'Parent', plt_eyecon_window);
                        plt_eyecon_num = subplot(2,1,2, 'Parent', plt_eyecon_window);

                        if exist('fluid_bed_temperatures', "var")
                            [~, fluid_bed_temp_many] = size(fluid_bed_temperatures);
                            plt_fluidbed_temperature_window = figure('Name', 'Temperature data', 'Visible', 'off');
                            for fluid_bed_temp_this = 1 : fluid_bed_temp_many
                                plt_fluid_bed_temp_this(fluid_bed_temp_this) = subplot(fluid_bed_temp_many,1,fluid_bed_temp_this, 'Parent', plt_fluidbed_temperature_window);
                            end % end for 
                        end % end if 


                        if exist('fluid_bed_sprays', "var")
                            [~, fluid_bed_sprays_many] = size(fluid_bed_sprays);
                            plt_fluid_bed_sprays_window = figure('Name', 'Spray data', 'Visible', 'off');
                            for fluid_bed_sprays_this = 1 : fluid_bed_sprays_many
                                plt_fluid_bed_sprays_this(fluid_bed_sprays_this) = subplot(fluid_bed_sprays_many,1,fluid_bed_sprays_this, 'Parent', plt_fluid_bed_sprays_window);
                            end % end for 
                        end % end if 


                        for timestamp = 2 : resolution : timestamps-1

                            if exist('fluid_bed_temperatures', "var")
                                plt_fluidbed_temperature_window.Visible = 'on';
                                for fluid_bed_temp_this = 1 : fluid_bed_temp_many
                                    plot(plt_fluid_bed_temp_this(fluid_bed_temp_this), app.tools{1}.(fluid_bed_temperatures{1,fluid_bed_temp_this}).NodeData(1:timestamp))
                                    hold(plt_fluid_bed_temp_this(fluid_bed_temp_this),"on"); 
                                    title(plt_fluid_bed_temp_this(fluid_bed_temp_this),[app.tools{1}.(fluid_bed_temperatures{1,fluid_bed_temp_this}).Text, ', timestamp = ', num2str(timestamp)], 'FontSize',8);
                                end % end for 
                            end % end if 

                            if exist('fluid_bed_sprays', "var")
                                plt_fluid_bed_sprays_window.Visible = 'on';
                                for fluid_bed_sprays_this = 1 : fluid_bed_sprays_many
                                    plot(plt_fluid_bed_sprays_this(fluid_bed_sprays_this), app.tools{1}.(fluid_bed_sprays{1,fluid_bed_sprays_this}).NodeData(1:timestamp))
                                    hold(plt_fluid_bed_sprays_this(fluid_bed_sprays_this),"on"); 
                                    title(plt_fluid_bed_sprays_this(fluid_bed_sprays_this),[app.tools{1}.(fluid_bed_sprays{1,fluid_bed_sprays_this}).Text, ', timestamp = ', num2str(timestamp)], 'FontSize',8);
                                end % end for 
                            end % end if 


                            if exist('eyecone_histogram_num', "var")
                                plt_eyecon_window.Visible = 'on';
                                plot(plt_eyecon_num, eyecone_histogram_num(:, timestamp));
                                hold(plt_eyecon_num,"on"); 
                                title(plt_eyecon_num,['Numeric histogram, timestamp = ', num2str(timestamp)], 'FontSize',8);
                            end % end if 
                            if exist('eyecone_histogram_vol', "var")
                                plt_eyecon_window.Visible = 'on';
                                plot(plt_eyecon_vol, eyecone_histogram_vol(:, timestamp));
                                hold(plt_eyecon_vol,"on"); 
                                title(plt_eyecon_vol,['Volumetric histogram, timestamp = ', num2str(timestamp)], 'FontSize',8);
                            end % end if 

                            for n = 1 : N
                                v = app.tools{4}.EIOT.EIOT.Sprim(n,:);
                                plt1 = subplot(2,2, n, 'Parent', NIRtool.outQ);
                                plt2 = subplot(2,2, n, 'Parent', tab{1,instance});
                                u = NIR(:, timestamp);
                                plot(plt1, Spectra, filter(Spectra, v, u), ...
                                    'DisplayName', ['timestamp = ', num2str(timestamp)]);%filter(u, v, Spectra)
                                xlabel(plt1,'Spectrum', 'FontSize',8);
                                ylabel(plt1,'Filtered value', 'FontSize',8); 
                                title(plt1,['Filtered for ', app.tools{4}.EIOT.Components.names{1,n}, ', timestamp = ', num2str(timestamp)], 'FontSize',8);
                                hold(plt1, 'on');
                                plot(plt2, Spectra, filter(Spectra, v, u), ...
                                    'DisplayName', ['timestamp = ', num2str(timestamp)]);
                                xlabel(plt2,'Spectrum');
                                ylabel(plt2,'Filtered value'); 
                                title(plt2,['Filtered for ', app.tools{4}.EIOT.Components.names{1,n}, ', timestamp = ', num2str(timestamp)]);
                                hold(plt2, 'on');
                            end  % end for 
                            pause(0.5) % waits 10 sec 
                        end  % end for
                    end   % end if 
                    end  % end if ignorelist 
                end  % end for 


            end % end function 
            % -------------------------------------------------------------
        end  % end function 
        % -----------------------------------------------------------------
        function app = check_availability(app) 
            s1 = uistyle('BackgroundColor','yellow');
            app.bigTable = cell(1, 4);
            app = list_expected_do(app);
            [~, thismanytags] = size(app.list_expected); 
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
            else
                thismanyfiles = 1;
            end % end if
            row = 0;
            for file = 1 : thismanyfiles
                [~, variables] = size(app.dataset_bckup{1,file});
                flags = zeros(variables, thismanytags);
                for variable = 1 : variables
                    row = row + 1;
                    app.bigTable{row,1} = app.dataset_bckup{1,file}{1,variable};
                    app.bigTable{row,4} = 'Not Found';
                    if iscell(app.import_file_names) 
                        app.bigTable{row,3} = app.import_file_names{1,file};
                    else 
                        app.bigTable{row,3} = app.import_file_names;
                    end % end if 
                    for item = 1 : thismanytags
                        if iscell(app.list_expected(item).tag) 
                            [~, ele] = size(app.list_expected(item).tag);
                            counter = 0;
                            for el = 1 : ele
                                if contains(app.dataset_bckup{1,file}{1,variable}, app.list_expected(item).tag{el})
                                    counter = counter + 1;
                                end % end if 
                            end % end for  
                            if counter == ele
                                app.bigTable{row,2} = app.list_expected(item).description; 
                                flags(variable, item) = 1;
                                app.bigTable{row,4} = 'OK';
                                break;
                            end % end for
                        else
                            if contains(app.dataset_bckup{1,file}{1,variable}, app.list_expected(item).tag)
                                app.bigTable{row,2} = app.list_expected(item).description;
                                app.bigTable{row,4} = 'OK';
                                flags(variable, item) = 1;
                                break; 
                            end % end if 
                        end % end if 
                    end % end for 
                end  % end for 
                % check for not founds 
                d1 = cumsum(flags, 1);
                d11 = d1(end, :);
                [~, idxs] = (find(d11 == 0)); 
                for idx = 1 : length(idxs)
                    item = idxs(idx);
                    row = row + 1;
                    app.bigTable{row,4} = 'Not Found';
                    app.bigTable{row,2} = app.list_expected(item).description; 
                    if iscell(app.import_file_names) 
                        app.bigTable{row,3} = app.import_file_names{1,file};
                    else 
                        app.bigTable{row,3} = app.import_file_names;
                    end % end if 
                    if iscell(app.list_expected(item).tag) 
                        [~, ele] = size(app.list_expected(item).tag);
                        text = [];
                        for el = 1 : ele
                            text = [text, ' ', app.list_expected(item).tag{el}];
                        end % end for 
                        app.bigTable{row,1} = text;
                    else
                        app.bigTable{row,1} = app.list_expected(item).tag;
                    end % end if 
                    addStyle(app.tools{2}.out,s1,'row',row);
                end % end for 
            end % end for 
        end % function 
        % -----------------------------------------------------------------
        function app = user_manuals(app) % 3
            help.help.add_files = uifigure('Visible', 'off'); % PAGE 1 - add_file
            help.help.add_files.CloseRequestFcn = @(src,event)close_page_1(src);
            help.help.add_files.Position = [250 -50 793 1813];
            help.help.add_files.Name = 'User Manual - Add File(s)';
            help.help.add_files.Scrollable = 'on';
            help.help.add_files_02 = uiimage(help.help.add_files);
            help.help.add_files_02.Position = [16 1509 773 239];
            help.help.add_files_02.ImageSource = 'main_gui.PNG';
            help.help.add_files_00 = uitextarea(help.help.add_files);
            help.help.add_files_00.Editable = 'off';
            help.help.add_files_00.FontWeight = 'bold';
            help.help.add_files_00.Position = [16 1782 773 23];
            help.help.add_files_00.Value = {'User Manuals ::: Add file(s)'};
            help.help.add_files_01 = uitextarea(help.help.add_files);
            help.help.add_files_01.Editable = 'off';
            help.help.add_files_01.FontWeight = 'bold';
            help.help.add_files_01.Position = [16 1753 773 25];
            help.help.add_files_01.Value = {['Execute DATool.exe once ' ...
                'installed to lauch the main app window: ']};
            help.help.add_files_03 = uitextarea(help.help.add_files);
            help.help.add_files_03.Editable = 'off';
            help.help.add_files_03.FontWeight = 'bold';
            help.help.add_files_03.Position = [16 1482 773 23];
            help.help.add_files_03.Value = {['Select "Add file(s)" to ' ...
                'select .csv file(s): ']};
            help.help.add_files_04 = uiimage(help.help.add_files);
            help.help.add_files_04.Position = [16 1042 773 433];
            help.help.add_files_04.ImageSource = 'add_files_1.PNG';
            help.help.add_files_05 = uitextarea(help.help.add_files);
            help.help.add_files_05.Editable = 'off';
            help.help.add_files_05.FontWeight = 'bold';
            help.help.add_files_05.Position = [17 1008 773 23];
            help.help.add_files_05.Value = {['By confirming, the selected ' ...
                'files will be listed for importing: ']};
            help.help.add_files_06 = uiimage(help.help.add_files);
            help.help.add_files_06.Position = [18 761 773 239];
            help.help.add_files_06.ImageSource = 'add_files_2.PNG';
            help.help.add_files_07 = uitextarea(help.help.add_files);
            help.help.add_files_07.Editable = 'off';
            help.help.add_files_07.FontWeight = 'bold';
            help.help.add_files_07.Position = [17 730 773 23];
            help.help.add_files_07.Value = {['Click "Upload file(s)" to ' ...
                'read and import file(s):']};
            help.help.add_files_08 = uiimage(help.help.add_files);
            help.help.add_files_08.Position = [17 290 773 433];
            help.help.add_files_08.ImageSource = 'upload_files_1.PNG';
            help.help.add_files_9 = uitextarea(help.help.add_files);
            help.help.add_files_9.Editable = 'off';
            help.help.add_files_9.FontWeight = 'bold';
            help.help.add_files_9.Position = [18 256 773 23];
            help.help.add_files_9.Value = {['Once file(s) import is ' ...
                'complete, the "File(s) summary" and "Activate toolkit" ' ...
                'features are enabled:']};
            help.help.add_files_10 = uiimage(help.help.add_files);
            help.help.add_files_10.Position = [18 8 773 239];
            help.help.add_files_10.ImageSource = 'upload_files_2.PNG';
            % -------------------------------------------------------------
            help.help.files_summary = uifigure('Visible', 'off'); % PAGE 2 - file summary 
            help.help.files_summary.CloseRequestFcn = @(src,event)close_page_2(src);
            help.help.files_summary.Position = [250 -50 803 1644];
            help.help.files_summary.Name = 'User Manual - File(s) Summary';
            help.help.files_summary.Scrollable = 'on';
            help.help.files_summary_02 = uiimage(help.help.files_summary);
            help.help.files_summary_02.Position = [16 887 773 674];
            help.help.files_summary_02.ImageSource = 'summary_1.PNG';
            help.help.files_summary_00 = uitextarea(help.help.files_summary);
            help.help.files_summary_00.Editable = 'off';
            help.help.files_summary_00.FontWeight = 'bold';
            help.help.files_summary_00.Position = [16 1613 773 23];
            help.help.files_summary_00.Value = {'User Manuals ::: File(s) summary '};
            help.help.files_summary_01 = uitextarea(help.help.files_summary);
            help.help.files_summary_01.Editable = 'off';
            help.help.files_summary_01.FontWeight = 'bold';
            help.help.files_summary_01.Position = [16 1568 773 40];
            help.help.files_summary_01.Value = {['Once file(s) import ' ...
                'is complete, the "File(s) summary" feature can be ' ...
                'used prior to toolkit activation. Here, user can ' ...
                'check what variable(s) are missing and may abort or ' ...
                'continue with toolkit activation. ' ...
                'Onces "File(s) summary" is clicked, a report is genrated as:']};
            help.help.files_summary_03 = uitextarea(help.help.files_summary);
            help.help.files_summary_03.Editable = 'off';
            help.help.files_summary_03.FontWeight = 'bold';
            help.help.files_summary_03.Position = [17 849 773 23];
            help.help.files_summary_03.Value = {['Once closing the window, ' ...
                'a confirmation dialoge box appears: ']};
            help.help.files_summary_04 = uiimage(help.help.files_summary);
            help.help.files_summary_04.Position = [18 347 773 494];
            help.help.files_summary_04.ImageSource = 'summary_2.PNG';
            help.help.files_summary_05 = uitextarea(help.help.files_summary);
            help.help.files_summary_05.Editable = 'off';
            help.help.files_summary_05.FontWeight = 'bold';
            help.help.files_summary_05.Position = [18 291 773 47];
            help.help.files_summary_05.Value = {['Select to ' ...
                '"Abort the App" is there''s data missing ' ...
                'which are vital for further processing. ' ...
                'This will close the DA toolkit. ']; ['If everything is' ...
                ' in order, select "Close summary window". ']};
            help.help.files_summary_06 = uiimage(help.help.files_summary);
            help.help.files_summary_06.Position = [19 46 773 239];
            help.help.files_summary_06.ImageSource = 'summary_3.PNG';
            help.help.files_summary_07 = uitextarea(help.help.files_summary);
            help.help.files_summary_07.Editable = 'off';
            help.help.files_summary_07.FontWeight = 'bold';
            help.help.files_summary_07.Position = [20 11 773 25];
            help.help.files_summary_07.Value = {'This feature is accessible all the time. '};
            % -------------------------------------------------------------
            help.help.activation = uifigure('Visible', 'off'); % PAGE 3 - Building database / Activating toolkits  
            help.help.activation.CloseRequestFcn = @(src,event)close_page_3(src);
            help.help.activation.Position = [250 100 803 761];
            help.help.activation.Name = 'User Manual - Activation';
            help.help.activation.Scrollable = 'on';
            help.help.activation_00 = uitextarea(help.help.activation);
            help.help.activation_00.Editable = 'off';
            help.help.activation_00.FontWeight = 'bold';
            help.help.activation_00.Position = [16 730 773 23];
            help.help.activation_00.Value = {'User Manuals ::: Toolkits activation '};
            help.help.activation_02 = uitextarea(help.help.activation);
            help.help.activation_02.Editable = 'off';
            help.help.activation_02.FontWeight = 'bold';
            help.help.activation_02.Position = [17 633 773 37];
            help.help.activation_02.Value = {['Once file(s) import is ' ...
                'complete, the "Activate toolkit" feature can be ' ...
                'used to build the database and to toolkit activation.' ...
                ' ']; 'Once clicked, a progress bar appears and shows the progress: '};
            help.help.activation_04 = uitextarea(help.help.activation);
            help.help.activation_04.Editable = 'off';
            help.help.activation_04.FontWeight = 'bold';
            help.help.activation_04.Position = [17 238 773 23];
            help.help.activation_04.Value = {['Once the activation ' ...
                'process is complete, the available toolkits are enabled and can be used: ']};
            help.help.activation_05 = uiimage(help.help.activation);
            help.help.activation_05.Position = [18 9 773 223];
            help.help.activation_05.ImageSource = 'activating_2.PNG';
            help.help.activation_01 = uitextarea(help.help.activation);
            help.help.activation_01.Editable = 'off';
            help.help.activation_01.FontSize = 14;
            help.help.activation_01.FontWeight = 'bold';
            help.help.activation_01.FontColor = [1 0 0];
            help.help.activation_01.Position = [17 677 773 46];
            help.help.activation_01.Value = {['Depending on the number ' ...
                'of file(s) and deposited data, this step takes a while. ' ...
                'App will block user interactions until the process is complete.']};
            help.help.activation_03 = uiimage(help.help.activation);
            help.help.activation_03.Position = [19 277 773 348];
            help.help.activation_03.ImageSource = 'activating_1.PNG';
            % -------------------------------------------------------------
            help.help.wp_00 = uifigure('Visible', 'off'); % PAGE 0 - Welcome 
            help.help.wp_00.CloseRequestFcn = @(src,event)close_page_0(src);
            help.help.wp_00.Position = [100 100 823 316];
            help.help.wp_00.Name = 'Help Welcome Page';
            help.help.wp_03 = uilabel(help.help.wp_00);
            help.help.wp_03.FontWeight = 'bold';
            help.help.wp_03.Position = [10 287 897 22];
            help.help.wp_03.Text = 'DATool ::: User Manual';
            help.help.wp_02 = uilabel(help.help.wp_00);
            help.help.wp_02.FontWeight = 'bold';
            help.help.wp_02.Position = [10 257 897 22];
            help.help.wp_02.Text = ['Here you can find materials on how ' ...
                'to use the DATool app. Click on desired part(s) of ' ...
                'the GUI image bellow to open related help/maual file(s). '];
            help.help.wp_01 = uiimage(help.help.wp_00);
            help.help.wp_01.Position = [123 3 582 246];
            help.help.wp_01.ImageSource = 'main_gui.PNG';
            % -------------------------------------------------------------
            help.help.lnk_files_add = uihyperlink(help.help.wp_00);
            help.help.lnk_files_add.HyperlinkClickedFcn = @(src, event)lnk_Clicked(src, event);
            help.help.lnk_files_add.BackgroundColor = [0.9412 0.9412 0.9412];
            help.help.lnk_files_add.HorizontalAlignment = 'center';
            help.help.lnk_files_add.FontSize = 11;
            help.help.lnk_files_add.FontColor = [0 0 0];
            help.help.lnk_files_add.Position = [578 166 85 13];
            help.help.lnk_files_add.Text = 'Add file(s)';
            help.help.lnk_files_upload = uihyperlink(help.help.wp_00);
            help.help.lnk_files_upload.HyperlinkClickedFcn = @(src, event)lnk_Clicked(src, event);
            help.help.lnk_files_upload.BackgroundColor = [0.9412 0.9412 0.9412];
            help.help.lnk_files_upload.HorizontalAlignment = 'center';
            help.help.lnk_files_upload.FontSize = 11;
            help.help.lnk_files_upload.FontColor = [0 0 0];
            help.help.lnk_files_upload.Position = [578 142 85 13];
            help.help.lnk_files_upload.Text = 'Upload file(s)';
            help.help.lnk_files_summary = uihyperlink(help.help.wp_00);
            help.help.lnk_files_summary.HyperlinkClickedFcn = @(src, event)lnk_Clicked(src, event);
            help.help.lnk_files_summary.BackgroundColor = [0.9412 0.9412 0.9412];
            help.help.lnk_files_summary.HorizontalAlignment = 'center';
            help.help.lnk_files_summary.FontSize = 11;
            help.help.lnk_files_summary.FontColor = [0 0 0];
            help.help.lnk_files_summary.Position = [579 116 85 15];
            help.help.lnk_files_summary.Text = 'File(s) summary';
            help.help.lnk_activate = uihyperlink(help.help.wp_00);
            help.help.lnk_activate.HyperlinkClickedFcn = @(src, event)lnk_Clicked(src, event);
            help.help.lnk_activate.BackgroundColor = [0.9412 0.9412 0.9412];
            help.help.lnk_activate.HorizontalAlignment = 'center';
            help.help.lnk_activate.FontSize = 11;
            help.help.lnk_activate.FontColor = [0 0 0];
            help.help.lnk_activate.Position = [580 92 83 15];
            help.help.lnk_activate.Text = 'Activate toolkit';
            help.help.lnk_tool_plot = uihyperlink(help.help.wp_00);
            help.help.lnk_tool_plot.HyperlinkClickedFcn = @(src, event)lnk_Clicked(src, event);
            help.help.lnk_tool_plot.BackgroundColor = [0.9412 0.9412 0.9412];
            help.help.lnk_tool_plot.HorizontalAlignment = 'center';
            help.help.lnk_tool_plot.FontSize = 11;
            help.help.lnk_tool_plot.FontColor = [0 0 0];
            help.help.lnk_tool_plot.Position = [168 62 44 12];
            help.help.lnk_tool_plot.Text = 'Plot tool';
            help.help.lnk_tool_spectroscopy = uihyperlink(help.help.wp_00);
            help.help.lnk_tool_spectroscopy.HyperlinkClickedFcn = @(src, event)lnk_Clicked(src, event);
            help.help.lnk_tool_spectroscopy.BackgroundColor = [0.9412 0.9412 0.9412];
            help.help.lnk_tool_spectroscopy.VisitedColor = [0.502 0 0.502];
            help.help.lnk_tool_spectroscopy.HorizontalAlignment = 'center';
            help.help.lnk_tool_spectroscopy.FontSize = 10.5;
            help.help.lnk_tool_spectroscopy.FontColor = [0 0 0];
            help.help.lnk_tool_spectroscopy.Position = [320,62,95,14];
            help.help.lnk_tool_spectroscopy.Text = 'Spectroscopy tool';
            % -------------------------------------------------------------
            help.help.wp_00.Visible = 'on';
            % -------------------------------------------------------------
            function lnk_Clicked(src, event)
                switch event.Source.Text
                    case 'Add file(s)'
                        help.help.add_files.Visible = 'on';
                    case 'Upload file(s)'
                        help.help.add_files.Visible = 'on';
                    case 'File(s) summary'
                        help.help.files_summary.Visible = 'on';
                    case 'Activate toolkit'
                        help.help.activation.Visible = 'on';
                    otherwise
                        %
                end % end switch 
            end
            % -------------------------------------------------------------
            function close_page_0(fig) %
                help.help.wp_00.Visible = 'off';
            end  % end function 
            function close_page_1(fig) %
                help.help.add_files.Visible = 'off';
            end  % end function 
            function close_page_2(fig) %
                help.help.files_summary.Visible = 'off';
            end  % end function 
            function close_page_3(fig) %
                help.help.activation.Visible = 'off';
            end  % end function 
            % -------------------------------------------------------------
            app.tools{3} = help;
        end % end function 
        % -----------------------------------------------------------------
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %	                UTILITIES 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % -----------------------------------------------------------------
        function parsed = parser_comma(~, source) 
            % extracts numbers from a mixed string+number+...
            splitted = regexp(source,',','split');
            dummy = splitted{1,1};
            dummy = dummy(2:end);
            splitted{1,1} = dummy;
            dummy = splitted{1,end};
            dummy = dummy(1:end-1);
            splitted{1,end} = dummy;
            [~, thismany] = size(splitted);
            dummy = zeros(1, thismany);
            for i = 1 : thismany
                dummy(1,i) = str2double(splitted{1,i});
            end % end for 
            parsed = dummy;
        end % end function 
        % -----------------------------------------------------------------
        function app = remove_UTC(app, file_indx) 
            % removes UTC in selected files 
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [row , ~] = size(app.dataset{1,file});
                for i = 2 : row  
                    app.dataset{1,file}{i,1} = ...
                        erase(app.dataset{1,file}{i,1}, ...
                        ' GMT+0000 (Coordinated Universal Time)');
                end % end for 
            end % end for 
        end % end function 
        % -----------------------------------------------------------------
        function app = replace_NA_with_NaN(app, file_indx) 
            % replaces N/A with NaN in selected files 
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [row , col] = size(app.dataset{1,file});
                for i = 1 : row  
                    for j = 1 : col
                        if ischar(app.dataset{1,file}{i,j})
                            if contains(app.dataset{1,file}{i,j}, 'N/A') 
                                app.dataset{1,file}{i,j} = nan;
                            else 
                                continue
                            end % end if 
                        else
                            continue
                        end % end if 
                    end % end for 
                end % end for 
            end % end for 
        end % end function       
        % -----------------------------------------------------------------
        function app = replace_underscore_with_space(app, file_indx) 
            % replaces _ with space in selected files 
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [~ , col] = size(app.dataset{1,file});
                for j = 1 : col
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, '_', ' ');
                end % end for 
            end % end for 
        end % end function       
        % -----------------------------------------------------------------
        function app = replace_dot_with_space(app, file_indx) 
            % replaces . with space in selected files 
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [~ , col] = size(app.dataset{1,file});
                for j = 1 : col
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, '.', ' ');
                end % end for 
            end % end for 
        end % end function       
        % -----------------------------------------------------------------
        function app = rename_tags(app, file_indx)
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [~ , col] = size(app.dataset{1,file});
                for j = 1 : col
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ...
                        'Dn', 'numeric density ');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ...
                        'Dv', 'volumetric density ');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ...
                        'hg num', 'histogram - numeric');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ...
                        'hg vol', 'histogram - volumetric');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ...
                        ' temp ', ' temperature ');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ...
                        ' ch', ' channel ');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ...
                        ' bl corr bsorbance', ' corrected absorbance ');
                end % end for 
            end % end for 
        end % end function 
        % -----------------------------------------------------------------
        function app = clean(app) 
            % cleans tag names and N/A
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
                file_indx = 1 : thismanyfiles;
            else
                file_indx = 1;
            end % end if 
            app = remove_UTC(app, file_indx); 
            app = replace_NA_with_NaN(app, file_indx);
            app = replace_underscore_with_space(app, file_indx);
            app = replace_dot_with_space(app, file_indx); 
            app = rename_tags(app, file_indx);
        end % end function 
        % -----------------------------------------------------------------
        function [app, export2WStext] = export2WS(app) 
            % export datasets to worspace?
            selection = uiconfirm(app.main,...
                'Make dataset(s) available in MATLAB Workspace?',...
                'Sending to Workspace ...',...
                        'Icon','question');
            switch selection
                case 'OK'
                    export2WStext = 'on';
                case 'Cancel'
                    export2WStext = 'off';
            end % end switch
        end % end function - export2WS
        % -----------------------------------------------------------------
        function app = export2WS_do(app) 
            % export to WS - do
            app.Reports_Status.Value = 'Waiting for user selection ....';
            [app, export2WStext] = export2WS(app); 
            % prompt user to see if should export to WS 
            if isequal(export2WStext, 'on')
                assignin('base','dataset', app.dataset);
                app.Reports_Status.Value = 'Exported dataset(s) to WS.';
            else
                app.Reports_Status.Value = ...
                    'Canceled dataset(s) export to WS.'; % do nothin 
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end % end methods
    % 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_files_Select
        function app_button_files_select_pushed(app, event)
             [app, text] = files_select(app);
             app.Reports_Status.Value = text;
             if contains(text, 'cancel')
                 app.Reports_files_List.Value = 'no file(s) selected! Click "Add files(s)" button to add file(s).';
                 app.Reports_Status.Value = text;
                 app.Button_files_Upload.Enable = 'off';
             else
                 [app, app.Reports_files_List.Value] = files_list_filenames(app);
                 app.Reports_Status.Value = [text, ' Click "Upload file(s)" to import data.'];
                 app.Button_files_Upload.Enable = 'on';
             end % end if 
             app.Button_file_whatsin.Enable = 'off';
             app.Button_toolkit_on.Enable = 'off';
             if (~isempty(app.tools)) % 
                 app.Button_tools_Plot.Enable = 'off';
                 app.Button_tools_Spectroscopy.Enable = 'off';
             end % end if 
        end

        % Button pushed function: Button_files_Upload
        function app_button_files_upload_pushed(app, event)
            app.Reports_Status.Value = 'Importing file(s) ....';
            app.Button_files_Upload.Enable = 'off';
            pause(0.001);
            app = files_upload(app);
            [app, text] = files_what_types(app, 'Imported');
            app.Reports_Status.Value = text;
            app.Reports_Status.Value = 'Use "File(s) summary" to see tags availability, otherwise "Activate toolkits".';
            app.dataset_bckup = app.dataset; % shadow copy - backup
            app.Button_files_Upload.Enable = 'off';
            app.Button_files_Select.Enable = 'on';
            app.Button_file_whatsin.Enable = 'on';
            app.Button_toolkit_on.Enable = 'on';
        end

        % Close request function: main
        function app_main_close(app, event)
            app.Reports_Status.Value = 'Closing app, waiting for user confirmation ....';
            closeapp(app);
        end

        % Button pushed function: Button_tools_Plot
        function app_main_tools_plotter_pushed(app, event)
            app.tools{1}.plottool.Visible = 'on'; % plot tool button : Show the figure after all components are create 
            app.Reports_Status.Value = 'Plot tool in-use ....';
            while (ishandle(app.tools{1}.plottool)) % if plotter is active
                pause(0.001);
                if isequal(app.tools{1}.plottool.Visible, 'off')
                    app.Reports_Status.Value = 'Closed plot tool, returned to main app. App ready to use.';
                    break
                end % end if 
            end % end while 
            app.Reports_Status.Value = 'Closed plot tool, returned to main app. App ready to use.';
        end

        % Button pushed function: Button_file_whatsin
        function app_button_files_whatsin_pushed(app, event)
            app.Button_files_Upload.Enable = 'off';
            app.Button_files_Select.Enable = 'on';
            app.Button_file_whatsin.Enable = 'on';
            app.Reports_Status.Value = 'File(s) summary in use ....';
            app = features_varUser(app); 
            app = check_availability(app);
            app.tools{2}.out.Data = app.bigTable; 
            app.tools{2}.varUser.Visible = 'on';
        end

        % Button pushed function: Button_toolkit_on
        function app_button_toolkit_on_pushed(app, event)
            app.Button_files_Upload.Enable = 'off';
            app.Button_files_Select.Enable = 'on';
            app.Button_file_whatsin.Enable = 'on';
            app.Button_toolkit_on.Enable = 'off';
            app.Reports_Status.Value = 'Activating toolkits ....';
            pause(0.001);
            app = list_2ignore_do(app); 
            app = activate_toolkits(app);
        end

        % Button pushed function: Button_tools_Spectroscopy
        function app_button_tools_spectroscopy_pushed(app, event)
            % empty for now   
            app.Reports_Status.Value = 'spectroscopy tool in use ....';
            app.tools{4}.NIRtool.Visible = 'on';
            while (ishandle(app.tools{4}.NIRtool)) % if is active
                pause(0.001);
                if isequal(app.tools{4}.NIRtool.Visible, 'off')
                    app.Reports_Status.Value = 'Closed spectroscopy tool, returned to main app. App ready to use.';
                    break
                end % end if 
            end % end while 
            app.Reports_Status.Value = 'Closed spectroscopy tool, returned to main app. App ready to use.';
        end

        % Button pushed function: Button_tools_Statistics
        function app_button_tools_statistics_pushed(app, event)
            % empty for now  
        end

        % Button pushed function: Button_help
        function app_button_help_pushed(app, event)
            app = user_manuals(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'Data Analytics Tool';
            app.main.CloseRequestFcn = createCallbackFcn(app, @app_main_close, true);
            app.Button_files_Select = uibutton(app.main, 'push');
            app.Button_files_Select.ButtonPushedFcn = createCallbackFcn(app, @app_button_files_select_pushed, true);
            app.Button_files_Select.Tooltip = {'Opens a new window to select files. '};
            app.Button_files_Select.Text = 'Add file(s)';
            app.Button_files_Upload = uibutton(app.main, 'push');
            app.Button_files_Upload.ButtonPushedFcn = createCallbackFcn(app, @app_button_files_upload_pushed, true);
            app.Button_files_Upload.Enable = 'off';
            app.Button_files_Upload.Tooltip = {'Click to import selected files. '};
            app.Button_files_Upload.Text = 'Upload file(s)';
            app.Button_tools_Plot = uibutton(app.main, 'push');
            app.Button_tools_Plot.ButtonPushedFcn = createCallbackFcn(app, @app_main_tools_plotter_pushed, true);
            app.Button_tools_Plot.Enable = 'off';
            app.Button_tools_Plot.Tooltip = {'Opens plot tool window. '};
            app.Button_tools_Plot.Text = 'Plot tool';
            app.Button_tools_Statistics = uibutton(app.main, 'push');
            app.Button_tools_Statistics.ButtonPushedFcn = createCallbackFcn(app, @app_button_tools_statistics_pushed, true);
            app.Button_tools_Statistics.Enable = 'off';
            app.Button_tools_Statistics.Tooltip = {'Opens statistics tool window for Multivariate statistical process control methods (MSPC)'};
            app.Button_tools_Statistics.Text = 'Statistics tool';
            app.SelectedfileLabel = uilabel(app.main);
            app.SelectedfileLabel.Text = 'List of selected file(s):';
            app.Reports_files_List = uitextarea(app.main);
            app.Reports_files_List.Editable = 'off';
            app.Reports_files_List.Value = {'No file(s) selected!'};
            app.MessageLabel = uilabel(app.main);
            app.MessageLabel.Text = 'Message:';
            app.Reports_Status = uitextarea(app.main);
            app.Reports_Status.Editable = 'off';
            app.Reports_Status.Value = {'Click "Select file(s)" button to select and add file(s).'};
            app.Button_file_whatsin = uibutton(app.main, 'push');
            app.Button_file_whatsin.ButtonPushedFcn = createCallbackFcn(app, @app_button_files_whatsin_pushed, true);
            app.Button_file_whatsin.Enable = 'off';
            app.Button_file_whatsin.Tooltip = {'Click to import selected files. '};
            app.Button_file_whatsin.Text = 'File(s) summary';
            app.Button_toolkit_on = uibutton(app.main, 'push');
            app.Button_toolkit_on.ButtonPushedFcn = createCallbackFcn(app, @app_button_toolkit_on_pushed, true);
            app.Button_toolkit_on.Enable = 'off';
            app.Button_toolkit_on.Tooltip = {'Click to import selected files. '};
            app.Button_toolkit_on.Text = 'Activate toolkit';
            app.Button_tools_Spectroscopy = uibutton(app.main, 'push');
            app.Button_tools_Spectroscopy.ButtonPushedFcn = createCallbackFcn(app, @app_button_tools_spectroscopy_pushed, true);
            app.Button_tools_Spectroscopy.Enable = 'off';
            app.Button_tools_Spectroscopy.Tooltip = {'Opens statistics tool window. '};
            app.Button_tools_Spectroscopy.Text = 'Spectroscopy tool';
            app.Button_help = uibutton(app.main, 'push');
            app.Button_help.ButtonPushedFcn = createCallbackFcn(app, @app_button_help_pushed, true);
            app.Button_help.Tooltip = {'Opens a help materials.  '};
            app.Button_help.Text = 'User Manuals';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAT_20221011

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end