classdef FBD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FBDtoolkitUI  matlab.ui.Figure
        File          matlab.ui.container.Menu
        LoadFile      matlab.ui.container.Menu
        Quit          matlab.ui.container.Menu
        Tools         matlab.ui.container.Menu
        Export2WS     matlab.ui.container.Menu
        Lights        matlab.ui.container.Menu
        Content       matlab.ui.container.Menu
        Help          matlab.ui.container.Menu
        Manuals       matlab.ui.container.Menu
        Contributors  matlab.ui.container.Menu
        GO_multieye   matlab.ui.control.Button
        GO_eyecon     matlab.ui.control.Button
        multieye      matlab.ui.control.Lamp
        eyecon        matlab.ui.control.Lamp
        Image         matlab.ui.control.Image
    end

    properties (Access = private)
        dataset % to store and share the dataset within the app 
        dataset_in % dataset with no postprocessing 
        default_tags % default descriptors - prebuilt 
        filename % name of file loaded 
        filepath % path of file loaded 
        summary_file % report file 
        summary_path % report file path 
        slaves % slave sub apps 
    end
    % ---------------------------------------------------------------------
    methods (Access = private)
        % -----------------------------------------------------------------
        function app = tags_default(app) % default tags 
            app.default_tags = struct;
            item = 1;
            app.default_tags(item).tag = "Time Stamp";
            app.default_tags(item).description = "STRING: This is a human readable time stamp.";
            app.default_tags(item).sample = "Tue Sep 14 2021 10:56:21 GMT+0000 (Coordinated Universal Time)";
            app.default_tags(item).extra = "NONE";
            item = 2;
            app.default_tags(item).tag = "Unix Timestamp";
            app.default_tags(item).description = "DOUBLE: This a machine readable time stamp. To convert to human readable time use: datestr(x/86400 + 719529)";
            app.default_tags(item).sample = 1631616981;
            app.default_tags(item).extra = "NONE";
            item = 3;
            app.default_tags(item).tag = "batch_id";
            app.default_tags(item).description = "STRING: This the batch id generated by system.";
            app.default_tags(item).sample = "2ee1b122-2315-493f-a84f-064172cd3244";
            app.default_tags(item).extra = "NONE";
            item = 4;
            app.default_tags(item).tag = "batch_name";
            app.default_tags(item).description = "STRING: This the batch name supplied by user once running an experiment.";
            app.default_tags(item).sample = "Tuesday Test";
            app.default_tags(item).extra = "NONE";
            item = 5;
            app.default_tags(item).tag = "eyecon.Dn10";
            app.default_tags(item).description = "DOUBLE: The numeric density for size 10 as measured by eyecon.";
            app.default_tags(item).sample = 106.607;
            app.default_tags(item).extra = "NONE";
            item = 6;
            app.default_tags(item).tag = 'eyecon.Dn50';
            app.default_tags(item).description = "DOUBLE: The numeric density for size 50 as measured by eyecon.";
            app.default_tags(item).sample = 122.728;
            app.default_tags(item).extra = "NONE";
            item = 7;
            app.default_tags(item).tag = "eyecon.Dn90";
            app.default_tags(item).description = "DOUBLE: The numeric density for size 90 as measured by eyecon.";
            app.default_tags(item).sample = 197.0303;
            app.default_tags(item).extra = "NONE";
            item = 8;
            app.default_tags(item).tag = "eyecon.Dv10";
            app.default_tags(item).description = "DOUBLE: The volumetric density for size 10 as measured by eyecon.";
            app.default_tags(item).sample = 114.093;
            app.default_tags(item).extra = "NONE";
            item = 9;
            app.default_tags(item).tag = "eyecon.Dv25";
            app.default_tags(item).description = "DOUBLE: The volumetric density for size 25 as measured by eyecon.";
            app.default_tags(item).sample = 126.8984669;
            app.default_tags(item).extra = "NONE";
            item = 10;
            app.default_tags(item).tag = "eyecon.Dv50";
            app.default_tags(item).description = "DOUBLE: The volumetric density for size 50 as measured by eyecon.";
            app.default_tags(item).sample = 176.9824752;
            app.default_tags(item).extra = "NONE";
            item = 11;
            app.default_tags(item).tag = "eyecon.Dv75";
            app.default_tags(item).description = "DOUBLE: The volumetric density for size 75 as measured by eyecon.";
            app.default_tags(item).sample = 223.6239943;
            app.default_tags(item).extra = "NONE";
            item = 12;
            app.default_tags(item).tag = "eyecon.Dv90";
            app.default_tags(item).description = "DOUBLE: The volumetric density for size 90 as measured by eyecon.";
            app.default_tags(item).sample = 289.779927;
            app.default_tags(item).extra = "NONE";
            item = 13;
            app.default_tags(item).tag = "eyecon.hg_num_max";
            app.default_tags(item).description = "DOUBLE: The maximum value in the numeric histogram as measured by eyecon.";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).extra = "NONE";
            item = 14;
            app.default_tags(item).tag = "eyecon.hg_num_min";
            app.default_tags(item).description = "DOUBLE: The minimum value in the numeric histogram as measured by eyecon.";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).extra = "NONE";
 	        item = 15;
            app.default_tags(item).tag = "eyecon.hg_num_nbins";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "DOUBLE: The number of bins used in the numeric histogram as measured by eyecon.";
            app.default_tags(item).extra = "NONE";
            item = 16;
            app.default_tags(item).tag = "eyecon.hg_vol_max";
            app.default_tags(item).description = "DOUBLE: The maximum value in the volumetric histogram as measured by eyecon.";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).extra = "NONE";
            item = 17;
            app.default_tags(item).tag = "eyecon.hg_vol_min";
            app.default_tags(item).description = "DOUBLE: The minimum value in the volumetric histogram as measured by eyecon.";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).extra = "NONE";
            item = 18;
            app.default_tags(item).tag = "eyecon.hg_vol_nbins";
            app.default_tags(item).description = "DOUBLE: The number of bins used in the volumetric histogram as measured by eyecon.";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).extra = "NONE";
            item = 19;
            app.default_tags(item).tag = "eyecon.image_path";
            app.default_tags(item).description = "STRING: path to image file as captured by eyecon?";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).extra = "NONE";
            item = 20;
            app.default_tags(item).tag = "eyecon.numeric.histogram";
            app.default_tags(item).description = "VECTOR DOUBLE: The numeric histogram as measured by eyecon. See default_tags.extra.";
            app.default_tags(item).sample = [0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
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                0,0,0,0,0,0];
            app.default_tags(item).extra = [100,101,103,104,105,...
                107,108,109,111,112,113,114,116,117,118,120,121,...
                122,124,125,126,127,129,130,131,133,134,135,137,...
                138,139,140,142,143,144,146,147,148,150,151,152,...
                153,155,156,157,159,160,161,163,164,165,166,168,...
                169,170,172,173,174,176,177,178,179,181,182,183,...
                185,186,187,189,190,191,192,194,195,196,198,199,...
                200,202,203,204,205,207,208,209,211,212,213,215,...
                216,217,218,220,221,222,224,225,226,228,229,230,...
                231,233,234,235,237,238,239,241,242,243,244,246,...
                247,248,250,251,252,254,255,256,257,259,260,261,...
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                1395,1396,1398,1399];
            item = 21;
            app.default_tags(item).tag = "eyecon.volumetric.histogram";
            app.default_tags(item).description = "VECTOR DOUBLE: The volumetric histogram as measured by eyecon. See default_tags.extra.";
            app.default_tags(item).sample = [0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
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                0,0,0,0,0,0];
            app.default_tags(item).extra = [100,101,103,104,105,...
                107,108,109,111,112,113,114,116,117,118,120,...
                121,122,124,125,126,127,129,130,131,133,134,...
                135,137,138,139,140,142,143,144,146,147,148,...
                150,151,152,153,155,156,157,159,160,161,163,...
                164,165,166,168,169,170,172,173,174,176,177,...
                178,179,181,182,183,185,186,187,189,190,191,...
                192,194,195,196,198,199,200,202,203,204,205,...
                207,208,209,211,212,213,215,216,217,218,220,...
                221,222,224,225,226,228,229,230,231,233,234,...
                235,237,238,239,241,242,243,244,246,247,248,...
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                1392,1394,1395,1396,1398,1399];
            item = 22;
            app.default_tags(item).tag = "fluid_bed.airflow_bypass_pid_out_ac";
            app.default_tags(item).sample = 9.779999733;
            app.default_tags(item).description = "DOUBLE: The bypass airflow in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 23;
            app.default_tags(item).tag = "fluid_bed.airflow_on_sp";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: The airflow in fluid bed, on (1) or off (0).";
            app.default_tags(item).extra = "NONE";
            item = 24;
            app.default_tags(item).tag = "fluid_bed.airflow_on_sp_ac";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: The airflow in fluid bed, on (1) or off (0).";
            app.default_tags(item).extra = "NONE";
            item = 25;
            app.default_tags(item).tag = "fluid_bed.airflow_pid_out_ac";
            app.default_tags(item).sample = 30.20000076;
            app.default_tags(item).description = "DOUBLE: The airflow in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 26;
            app.default_tags(item).tag = "fluid_bed.airflow_rate_ac";
            app.default_tags(item).sample = 14;
            app.default_tags(item).description = "DOUBLE: The airflow rate in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 27;
            app.default_tags(item).tag = "fluid_bed.airflow_rate_sp";
            app.default_tags(item).sample = 78;
            app.default_tags(item).description = "DOUBLE: The airflow rate in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 28; 
            app.default_tags(item).tag = "fluid_bed.airflow_rate_sp_ac";
            app.default_tags(item).sample = 25;
            app.default_tags(item).description = "DOUBLE: The airflow rate in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 29;
            app.default_tags(item).tag = "fluid_bed.alarm_occured_ac";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "DOUBLE: If alarm occured, yes (1) or no (0).";
            app.default_tags(item).extra = "NONE";
	        item = 30;
            app.default_tags(item).tag = "fluid_bed.atomising_pressure_ac";
            app.default_tags(item).sample = 0.200000003;
            app.default_tags(item).description = "DOUBLE: The atomising pressure in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 31;
            app.default_tags(item).tag = "fluid_bed.atomising_pressure_pid_out_ac";
            app.default_tags(item).sample = 8.460000038;
            app.default_tags(item).description = "DOUBLE: The atomising pressure in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 32;
            app.default_tags(item).tag = "fluid_bed.atomising_pressure_sp";
            app.default_tags(item).sample = 1.5;
            app.default_tags(item).description = "DOUBLE: The atomising pressure in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 33;
            app.default_tags(item).tag = "fluid_bed.atomising_pressure_sp_ac";
            app.default_tags(item).sample = 1.5;
            app.default_tags(item).description = "DOUBLE: The atomising pressure in fluid bed.";
            app.default_tags(item).extra = "NONE";
	        item = 34;
            app.default_tags(item).tag = "fluid_bed.exhaust_temp_ac";
            app.default_tags(item).sample = 22.39999962;
            app.default_tags(item).description = "DOUBLE: The exhaust temperature in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 35;
            app.default_tags(item).tag = "fluid_bed.filter_pressure_differential_ac";
            app.default_tags(item).sample = -42;
            app.default_tags(item).description = "DOUBLE SIGNED: The differential pressure in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 36;
            app.default_tags(item).tag = "fluid_bed.inlet_air_temp_ac";
            app.default_tags(item).sample = 22.10000038;
            app.default_tags(item).description = "DOUBLE: The inlet airflow temperature in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 37;
            app.default_tags(item).tag = "fluid_bed.inlet_air_temp_pid_out_ac";
            app.default_tags(item).sample = 99.58999634;
            app.default_tags(item).description = "DOUBLE: The inlet airflow temperature in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 38;
            app.default_tags(item).tag = "fluid_bed.inlet_air_temp_sp";
            app.default_tags(item).sample = 59;
            app.default_tags(item).description = "DOUBLE: The inlet airflow temperature in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 39;
            app.default_tags(item).tag = "fluid_bed.main_heater_air_temp_pid_out_ac";
            app.default_tags(item).sample = 98.06999969;
            app.default_tags(item).description = "DOUBLE: The main heater airflow temperature in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 40;
            app.default_tags(item).tag = "fluid_bed.main_heater_air_temp_sp_ac";
            app.default_tags(item).sample = 85;
            app.default_tags(item).description = "DOUBLE: The main heater airflow temperature in fluid bed.";
            app.default_tags(item).extra = "NONE";
	        item = 41;
            app.default_tags(item).tag = "fluid_bed.main_heater_temp_ac";
            app.default_tags(item).sample = 22.10000038;
            app.default_tags(item).description = "DOUBLE: The main heater airflow temperature in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 42;
            app.default_tags(item).tag = "fluid_bed.phase_number_ac";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
	        item = 43;
            app.default_tags(item).tag = "fluid_bed.phase_time_hours_ac";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 44;
            app.default_tags(item).tag = "fluid_bed.phase_time_minutes_ac";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 45;
            app.default_tags(item).tag = "fluid_bed.phase_time_secs_ac";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 46;
            app.default_tags(item).tag = "fluid_bed.phase_type_ac";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 47;
            app.default_tags(item).tag = "fluid_bed.process_mode_on_is_ready_ac";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 48;
            app.default_tags(item).tag = "fluid_bed.process_mode_on_sp_ac";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
	        item = 49;
            app.default_tags(item).tag = "fluid_bed.process_mode_on_sp_do_not_use";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 50;
            app.default_tags(item).tag = "fluid_bed.product_pressure_differential_ac";
            app.default_tags(item).sample = 119;
            app.default_tags(item).description = "DOUBLE: The product differential pressure in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 51;
            app.default_tags(item).tag = "fluid_bed.product_temp_ac";
            app.default_tags(item).sample = 22.70000076;
            app.default_tags(item).description = "DOUBLE: The product temperature in fluid bed.";
            app.default_tags(item).extra = "NONE";
            item = 52;
            app.default_tags(item).tag = "fluid_bed.remote_on_ac";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: Remote access to fluid bed is on (1) or off (0).";
            app.default_tags(item).extra = "NONE";
            item = 53;
            app.default_tags(item).tag = "fluid_bed.remote_on_is_ready_ac";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: Remote client to fluid bed is ready (1) or not (0).";
            app.default_tags(item).extra = "NONE";
            item = 54;
            app.default_tags(item).tag = "fluid_bed.shake_mode_sp";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 55;
            app.default_tags(item).tag = "fluid_bed.shake_mode_sp_ac";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 56;
            app.default_tags(item).tag = "fluid_bed.shake_pause_ac";
            app.default_tags(item).sample = 11;
            app.default_tags(item).description = "DOUBLE: The shake pause time to remove material from the filters, seconds.";
            app.default_tags(item).extra = "NONE";
            item = 57;
            app.default_tags(item).tag = "fluid_bed.shake_pause_sp";
            app.default_tags(item).sample = 13;
            app.default_tags(item).description = "DOUBLE: The shake pause time to remove material from the filters, seconds.";
            app.default_tags(item).extra = "NONE";
            item = 58;
            app.default_tags(item).tag = "fluid_bed.shake_pause_sp_ac";
            app.default_tags(item).sample = 13;
            app.default_tags(item).description = "DOUBLE: The shake pause time to remove material from the filters, seconds.";
            app.default_tags(item).extra = "NONE";
            item = 59;
            app.default_tags(item).tag = "fluid_bed.shake_time_ac";
            app.default_tags(item).sample = 2;
            app.default_tags(item).description = "DOUBLE: The shake time to remove material from the filters, seconds.";
            app.default_tags(item).extra = "NONE";
            item = 60;
            app.default_tags(item).tag = "fluid_bed.shake_time_sp";
            app.default_tags(item).sample = 3;
            app.default_tags(item).description = "DOUBLE: The shake time to remove material from the filters, seconds.";
            app.default_tags(item).extra = "NONE";
            item = 61;
            app.default_tags(item).tag = "fluid_bed.shake_time_sp_ac";
            app.default_tags(item).sample = 3;
            app.default_tags(item).description = "DOUBLE: The shake time to remove material from the filters, seconds.";
            app.default_tags(item).extra = "NONE";
            item = 62;
            app.default_tags(item).tag = "fluid_bed.spray_pump_on_is_ready_ac";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: The spray pump is ready (1) or not (0).";
            app.default_tags(item).extra = "NONE";
            item = 63;
            app.default_tags(item).tag = "fluid_bed.spray_pump_on_sp";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: The spray pump.";
            app.default_tags(item).extra = "NONE";
            item = 64;
            app.default_tags(item).tag = "fluid_bed.spray_pump_on_sp_ac";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: The spray pump.";
            app.default_tags(item).extra = "NONE";
	        item = 65;
            app.default_tags(item).tag = "fluid_bed.spray_pump_speed_ac";
            app.default_tags(item).sample = 20;
            app.default_tags(item).description = "DOUBLE: The spray pump speed.";
            app.default_tags(item).extra = "NONE";
            item = 66;
            app.default_tags(item).tag = "fluid_bed.spray_pump_speed_sp";
            app.default_tags(item).sample = 20;
            app.default_tags(item).description = "DOUBLE: The spray pump speed.";
            app.default_tags(item).extra = "NONE";
            item = 67;
            app.default_tags(item).tag = "fluid_bed.spray_pump_speed_sp_ac";
            app.default_tags(item).sample = 20;
            app.default_tags(item).description = "DOUBLE: The spray pump speed.";
            app.default_tags(item).extra = "NONE";
            item = 68;
            app.default_tags(item).tag = "fluid_bed.watchdog_counter_from_plc_ac";
            app.default_tags(item).sample = 42;
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 69;
            app.default_tags(item).tag = "fluid_bed.watchdog_counter_to_plc_sp";
            app.default_tags(item).sample = 42;
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 70;
            app.default_tags(item).tag = "fluid_bed.watchdog_from_plc_ac";
            app.default_tags(item).sample = 36;
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 71;
            app.default_tags(item).tag = "fluid_bed.watchdog_to_plc_sp";
            app.default_tags(item).sample = 38;
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 72;
            app.default_tags(item).tag = "level";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 73;
            app.default_tags(item).tag = "log";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 74;
            app.default_tags(item).tag = "multieye.ch1_absorbance";
            app.default_tags(item).sample = [0.21606098115444183,...
                0.21059884130954742,0.20671992003917694,...
                0.20473340153694153,0.20083267986774445,...
                0.20120388269424438,0.19299721717834473,...
                0.19367028772830963,0.1875637024641037,...
                0.18754717707633972,0.18351627886295319,...
                0.18067574501037598,0.16987620294094086,...
                0.17689809203147888,0.17060258984565735,...
                0.17016670107841492,0.16612783074378967,...
                0.16591857373714447,0.16572557389736176,...
                0.16105401515960693,0.16238272190093994,...
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                0.09449922293424606];
            app.default_tags(item).description = "VECTOR DOUBLE: The absorbance from channel 1 as measured by multieye. See default_tags.extra";
            app.default_tags(item).extra = [1067,1071,1076,1081,...
                1085,1090,1095,1100,1104,1109,1114,1118,1123,...
                1128,1132,1137,1142,1146,1151,1155,1160,1165,...
                1169,1174,1179,1183,1188,1192,1197,1202,1206,...
                1211,1215,1220,1224,1229,1234,1238,1243,1247,...
                1252,1256,1261,1265,1270,1274,1279,1283,1288,...
                1292,1297,1301,1306,1310,1315,1319,1324,1328,...
                1333,1337,1342,1346,1350,1355,1359,1364,1368,...
                1373,1377,1381,1386,1390,1395,1399,1403,1408,...
                1412,1417,1421,1425,1430,1434,1438,1443,1447,...
                1451,1456,1460,1464,1469,1473,1477,1482,1486,...
                1490,1495,1499,1503,1508,1512,1516,1520,1525,...
                1529,1533,1538,1542,1546,1550,1555,1559,1563,...
                1567,1571,1576,1580,1584,1588,1593,1597,1601,...
                1605,1609,1614,1618,1622,1626,1630,1634,1639,...
                1643,1647,1651,1655,1659,1663,1667,1672,1676,...
                1680,1684,1688,1692,1696,1700,1704,1708,1712,...
                1717,1721,1725,1729,1733,1737,1741,1745,1749,...
                1753,1757,1761,1765,1769,1773,1777,1781,1785,...
                1789,1793,1797,1801,1804,1808,1812,1816,1820,...
                1824,1828,1832,1836,1840,1844,1847,1851,1855,...
                1859,1863,1867,1871,1874,1878,1882,1886,1890,...
                1893,1897,1901,1905,1909,1912,1916,1920,1924,...
                1927,1931,1935,1938,1942,1946,1949,1953,1957,...
                1960,1964,1968,1971,1975,1979,1982,1986,1990,...
                1993,1997,2000,2004,2007,2011,2014,2018,2022,...
                2025,2029,2032,2036,2039,2043,2046,2049,2053,...
                2056,2060,2063,2067,2070,2073,2077,2080,2083,...
                2087,2090,2094,2097,2100,2103,2107,2110,2113];
            item = 75;
            app.default_tags(item).tag = "multieye.ch1_bl_corr_bsorbance";
            app.default_tags(item).description = "VECTOR DOUBLE: The corrected absorbance from channel 1 as measured by multieye. See default_tags.extra";
            app.default_tags(item).sample = [0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,...
                0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0];
            app.default_tags(item).extra = [1067,1071,1076,1081,...
                1085,1090,1095,1100,1104,1109,1114,1118,1123,...
                1128,1132,1137,1142,1146,1151,1155,1160,1165,...
                1169,1174,1179,1183,1188,1192,1197,1202,1206,...
                1211,1215,1220,1224,1229,1234,1238,1243,1247,...
                1252,1256,1261,1265,1270,1274,1279,1283,1288,...
                1292,1297,1301,1306,1310,1315,1319,1324,1328,...
                1333,1337,1342,1346,1350,1355,1359,1364,1368,...
                1373,1377,1381,1386,1390,1395,1399,1403,1408,...
                1412,1417,1421,1425,1430,1434,1438,1443,1447,...
                1451,1456,1460,1464,1469,1473,1477,1482,1486,...
                1490,1495,1499,1503,1508,1512,1516,1520,1525,...
                1529,1533,1538,1542,1546,1550,1555,1559,1563,...
                1567,1571,1576,1580,1584,1588,1593,1597,1601,...
                1605,1609,1614,1618,1622,1626,1630,1634,1639,...
                1643,1647,1651,1655,1659,1663,1667,1672,1676,...
                1680,1684,1688,1692,1696,1700,1704,1708,1712,...
                1717,1721,1725,1729,1733,1737,1741,1745,1749,...
                1753,1757,1761,1765,1769,1773,1777,1781,1785,...
                1789,1793,1797,1801,1804,1808,1812,1816,1820,...
                1824,1828,1832,1836,1840,1844,1847,1851,1855,...
                1859,1863,1867,1871,1874,1878,1882,1886,1890,...
                1893,1897,1901,1905,1909,1912,1916,1920,1924,...
                1927,1931,1935,1938,1942,1946,1949,1953,1957,...
                1960,1964,1968,1971,1975,1979,1982,1986,1990,...
                1993,1997,2000,2004,2007,2011,2014,2018,2022,...
                2025,2029,2032,2036,2039,2043,2046,2049,2053,...
                2056,2060,2063,2067,2070,2073,2077,2080,2083,...
                2087,2090,2094,2097,2100,2103,2107,2110,2113];
            item = 76;
            app.default_tags(item).tag = "multieye.ch1_intensity";
            app.default_tags(item).description = "VECTOR DOUBLE: The intensity from channel 1 as measured by multieye. See default_tags.extra";
            app.default_tags(item).sample = [2611,2740,2860,...
                3127,3173,3344,3463,3360,3894,3930,4209,4208,...
                4304,4605,4772,4994,5211,5211,5325,5391,5472,...
                5537,5776,6026,6064,5691,5901,4489,6163,6010,...
                5738,5593,5694,5662,5479,5402,5113,4828,4857,...
                4668,4535,4273,4112,4085,4175,3982,4290,4039,...
                4271,4490,4571,4679,5014,5448,5465,5467,6022,...
                6661,7113,7708,7881,8072,8676,9422,9528,9882,...
                9968,10401,10163,10635,10476,11040,11581,11768,...
                11608,11925,11994,12839,13002,11785,13521,13814,...
                14036,14470,14438,14339,15259,15358,15269,15591,...
                14885,16065,16035,15483,15070,16757,16697,17689,...
                16840,17461,17371,17186,18063,18042,18140,18059,...
                18084,17208,18202,17891,18290,17853,17493,18023,...
                17996,18154,17604,17200,17894,16452,17222,16622,...
                16191,15602,14897,15903,15104,15187,14484,15368,...
                14029,14608,14146,13570,14048,13586,12241,12900,...
                12656,12645,11987,11694,11867,11431,11206,11269,...
                10678,10614,10265,9985,9989,9467,9531,9551,9045,...
                9208,9258,8954,8840,8659,8491,8649,8042,8258,...
                8205,8275,7453,8006,7687,7817,7945,7518,7500,...
                7411,7469,7574,7277,7494,7453,7239,7102,7165,...
                7137,6944,6964,6990,6706,6780,6821,6967,6892,...
                6591,6751,6940,6724,6780,7028,6977,6827,6812,...
                6849,6853,7223,7183,7091,6923,7337,7289,7464,...
                7450,7855,7499,7743,7950,7768,8223,8232,8348,...
                8794,8739,8692,8675,8789,9002,8927,8945,9340,...
                9528,9824,9956,9711,9869,10065,10213,10013,...
                10251,9696,10328,10348,10568,10587,10082,9840,...
                10134,9640,9328,9157,8816,8448,7729,8079,8381,...
                8069,7774,7938,7748];
            app.default_tags(item).extra = [1067,1071,1076,1081,...
                1085,...
                1090,1095,1100,1104,1109,1114,1118,1123,...
                1128,1132,1137,1142,1146,1151,1155,1160,...
                1165,1169,1174,1179,1183,1188,1192,1197,1202,...
                1206,1211,1215,1220,1224,1229,1234,1238,1243,...
                1247,1252,1256,1261,1265,1270,1274,1279,1283,...
                1288,1292,1297,1301,1306,1310,1315,1319,1324,...
                1328,1333,1337,1342,1346,1350,1355,1359,1364,...
                1368,1373,1377,1381,1386,1390,1395,1399,1403,...
                1408,1412,1417,1421,1425,1430,1434,1438,1443,...
                1447,1451,1456,1460,1464,1469,1473,1477,1482,...
                1486,1490,1495,1499,1503,1508,1512,1516,1520,...
                1525,1529,1533,1538,1542,1546,1550,1555,1559,...
                1563,1567,1571,1576,1580,1584,1588,1593,1597,...
                1601,1605,1609,1614,1618,1622,1626,1630,1634,...
                1639,1643,1647,1651,1655,1659,1663,1667,1672,...
                1676,1680,1684,1688,1692,1696,1700,1704,1708,...
                1712,1717,1721,1725,1729,1733,1737,1741,1745,...
                1749,1753,1757,1761,1765,1769,1773,1777,1781,...
                1785,1789,1793,1797,1801,1804,1808,1812,1816,...
                1820,1824,1828,1832,1836,1840,1844,1847,1851,...
                1855,1859,1863,1867,1871,1874,1878,1882,1886,...
                1890,1893,1897,1901,1905,1909,1912,1916,1920,...
                1924,1927,1931,1935,1938,1942,1946,1949,1953,...
                1957,1960,1964,1968,1971,1975,1979,1982,1986,...
                1990,1993,1997,2000,2004,2007,2011,2014,2018,...
                2022,2025,2029,2032,2036,2039,2043,2046,2049,...
                2053,2056,2060,2063,2067,2070,2073,2077,2080,...
                2083,2087,2090,2094,2097,2100,2103,2107,2110,2113];
            item = 77;
            app.default_tags(item).tag = "multieye.ch1_reflectance";
            app.default_tags(item).description = "VECTOR DOUBLE: The reflectance from channel 1 as measured by multieye. See default_tags.extra";
            app.default_tags(item).sample = [0.6089807748794556,...
                0.6148552298545837,0.6152294278144836,...
                0.6247139573097229,0.6271775364875793,...
                0.6344823837280273,0.6384585499763489,...
                0.6367960572242737,0.6499541997909546,...
                0.6507920622825623,0.6556777358055115,...
                0.6623535752296448,0.6725590825080872,...
                0.6636941432952881,0.6745799779891968,...
                0.6754178404808044,0.6816137433052063,...
                0.6805089116096497,0.6854686737060547,...
                0.6886236667633057,0.6899300813674927,...
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                0.8035197257995605];
            app.default_tags(item).extra = [1067,1071,1076,1081,...
                1085,1090,1095,1100,1104,1109,1114,1118,1123,...
                1128,1132,1137,1142,1146,1151,1155,1160,1165,...
                1169,1174,1179,1183,1188,1192,1197,1202,1206,...
                1211,1215,1220,1224,1229,1234,1238,1243,1247,...
                1252,1256,1261,1265,1270,1274,1279,1283,...
                1288,1292,1297,1301,1306,1310,1315,1319,...
                1324,1328,1333,1337,1342,1346,1350,1355,...
                1359,1364,1368,1373,1377,1381,1386,1390,...
                1395,1399,1403,1408,1412,1417,1421,1425,...
                1430,1434,1438,1443,1447,1451,1456,1460,...
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                2090,2094,2097,2100,2103,2107,2110,2113];
            item = 78;
            app.default_tags(item).tag = "Nozzle_airflow_sensor.currentAirflowRate";
            app.default_tags(item).description = "DOUBLE SIGNED: The nozzle airflow in fluid bed probed by sensor.";
            app.default_tags(item).sample = -0.378174007;
            app.default_tags(item).extra = "NONE";
            item = 79;
            app.default_tags(item).tag = "Nozzle_airflow_sensor.units";
            app.default_tags(item).sample = "N/A";
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
            item = 80;
            app.default_tags(item).tag = "results_path";
            app.default_tags(item).sample = 10.239588;
            app.default_tags(item).description = "NONE";
            app.default_tags(item).extra = "NONE";
	        item = 81;
            app.default_tags(item).tag = "scales.connection_alive";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: Connection is alive (1) or not (0).";
            app.default_tags(item).extra = "NONE";
            item = 82; 
            app.default_tags(item).tag = "scales.start";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: Scales is started (1) or not (0).";
            app.default_tags(item).extra = "NONE";
            item = 83; 
            app.default_tags(item).tag = "scales.weight";
            app.default_tags(item).sample = 49.90000153;
            app.default_tags(item).description = "DOUBLE: Measured weight.";
            app.default_tags(item).extra = "NONE";
	        item = 84;
            app.default_tags(item).tag = "thermoalert.connection_state";
            app.default_tags(item).sample = 1;
            app.default_tags(item).description = "DOUBLE: Connection is alive (1) or not (0).";
            app.default_tags(item).extra = "NONE";
            item = 85; 
            app.default_tags(item).tag = "thermoalert.humidity";
            app.default_tags(item).sample = 55;
            app.default_tags(item).description = "DOUBLE: Humidity";
            app.default_tags(item).extra = "NONE";
            item = 86; 
            app.default_tags(item).tag = "thermoalert.temperature";
            app.default_tags(item).sample = 23.79;
            app.default_tags(item).description = "DOUBLE: Temperature";
            app.default_tags(item).extra = "NONE";
        end
        % -----------------------------------------------------------------
        function [app, source] = parse_numbers(app, source) % extracts numbers from a mixed string+number+...
            dummy = regexp(source,'(\-)?\d+(\.)?(\d+)?','match');
            for i = 1 : max(size(dummy))
                dummy{1,i} = str2double(dummy{1,i});
            end
            source = dummy;
        end
        % -----------------------------------------------------------------
        function [app, where] = locate_var(app, what) % checks if the requested descriptor exists/available 
            [~, col] = size(app.dataset);  
            found = 0;
            for where = 1 : col  
                flag = 0;
                for inwhat = 1 : length(what)
                    if contains(app.dataset{1,where}, what(inwhat))
                        flag = flag + 1;
                    end
                end
                if ((flag / length(what)) - 1) >= 0
                    found = 1;
                    break
                end
            end
            if (found == 0)
                where = [];
            end
        end
        % -----------------------------------------------------------------
        function [app, source] = NAwithNaN(app, source) % replaces N/A with NaN 
            if isempty(source) % source empty, full cleaning is requested 
                [row , col] = size(app.dataset); 
                for i = 1 : row  
                    for j = 1 : col
                        if contains(app.dataset{i,j}, 'N/A') %if ~(isempty(strfind(app.dataset{i,j}, 'N/A')))
                            app.dataset{i,j} = nan;
                        else 
                            continue
                        end
                    end
                end 
            else % source is not empty 
                if (contains(source, 'N/A'))
                    source = nan;
                end
            end
        end
        % -----------------------------------------------------------------
        function app = removeUTC(app) % removes GMT+0000 (Coordinated Universal Time)
            [row , ~] = size(app.dataset); 
            j = 1;
            for i = 1 : row
                app.dataset{i,j} = erase(app.dataset{i,j}, ' GMT+0000 (Coordinated Universal Time)');
            end
        end
        % -----------------------------------------------------------------
        function [app, source] = underscore2space(app, source) % replaces _ with space 
            if isempty(source) % source empty, full cleaning is requested 
                [~ , col] = size(app.dataset); 
                i = 1;
                for j = 1 : col
                    app.dataset{i,j} = strrep(app.dataset{i,j}, '_', ' '); 
                end 
            else % source is not empty 
                dummy = strrep(source, '_', ' ');
                source = dummy;
            end
        end
        % -----------------------------------------------------------------
        function [app, source] = dot2space(app, source) % replaces . with space
            if isempty(source) % source empty, full cleaning is requested 
                [~ , col] = size(app.dataset); 
                i = 1;
                for j = 1 : col
                    app.dataset{i,j} = strrep(app.dataset{i,j}, '.', ' '); 
                end 
            else % source is not empty 
                dummy = strrep(source, '.', ' '); 
                source = dummy;
            end
        end
        % -----------------------------------------------------------------
        function [app, color] = onoroff(app, descriptor) % checks of the descriptor exists in dataset and indicators on/off 
            [~, where] = locate_var(app, descriptor);
            if isempty(where) 
               color = [1 0 0]; % red - not present
           else
               color = [0 1 0]; % green - present 
            end
        end
        % -----------------------------------------------------------------
        function app = lampson(app, mode) % turns the instrument indicators on (Green) if descriptors exist, otherwise  red. 
            switch mode
                case 'update'
                    [app, color] = onoroff(app, "multieye"); % checking for multieye data availability
                    app.multieye.Color = color;
                    app.GO_multieye.BackgroundColor = color;
                    [app, color] = onoroff(app, "eyecon"); % checking for eyecon data availability
                    app.eyecon.Color = color;
                    app.GO_eyecon.BackgroundColor = color;
                case 'reset'
                    color = [1 0 0]; % red - not present
                    app.multieye.Color = color;
                    app.GO_multieye.BackgroundColor = color; 
                    app.eyecon.Color = color;
                    app.GO_eyecon.BackgroundColor = color;
            end
        end
        % -----------------------------------------------------------------
        function app = loadfile(app) % prompts user to select a file to import/analysis datasets 
            [file_data,path_data,~] = uigetfile('*.xlsx', 'Select a file');
            if isequal(file_data,0)
               disp('File import canceled');
            else
               disp(['User selected file: ', fullfile(path_data,file_data)]);
               % importing
               [~, ~, app.dataset] = xlsread([path_data, file_data]); 
               app.dataset_in = app.dataset;
               app.filename = file_data;
               app.filepath = path_data;
            end 
        end
        % -----------------------------------------------------------------
        function app = closeapp(app) % controls if should proceed with termonating the app 
            selection = uiconfirm(app.FBDtoolkitUI,'Close App?',...
                'Confirm Close',...
                        'Icon','warning');
            switch selection
                case 'OK' % closes the app
                    delete(app)
                case 'Cancel' % does nothing, return to app
                    return
            end
        end
        % ----------------------------------------------------------------- 
        function app = generatefilereport(app) % creates a summary of descriptors in dataset and write it to .xlsx file 
            % sheet = "default" lists the expected descriptors by defauls
            % sheet = dataset.name tailored for the dataset 
            % asks user to where write the summary file 
            [app.summary_file,app.summary_path] = uiputfile('content_description.xlsx');
            if isequal(app.summary_file,0)
                disp('Operation canceled');
            else 
                app = tags_default(app);
                % writing the defaults to summary file
                bar = uifigure;
                inbar = uiprogressdlg(bar,...
                    'Title','Writing default info to summary file, please wait ....',...
                    'Indeterminate','on');
                drawnow;
                generatefilereport_writes(app, fullfile(app.summary_path,app.summary_file));
                close(inbar);
                close(bar);
                % highlighting in GREEN the available descripors in created file
                bar = uifigure;
                inbar = uiprogressdlg(bar,...
                    'Title','highlighting in green available descriptors ....',...
                    'Indeterminate','on');
                drawnow;
                generatefilereport_highlights(app, fullfile(app.summary_path,app.summary_file));
                close(inbar);
                close(bar);
                % process file 
                % build a glossary first 
            end
        end
        % -----------------------------------------------------------------
        function generatefilereport_writes(app, file) % writes the defaults to summary file
            for item = 0 : length(app.default_tags)
                if item < 1 % write headers one time
                    xlswrite(file,{'tag name'},'Sheet1','A1');
                    xlswrite(file,{'description'},'Sheet1','B1');
                    xlswrite(file,{'sample data'},'Sheet1','C1');
                    xlswrite(file,{'extra info'},'Sheet1','D1');
                else
                    xlswrite(file,{(app.default_tags(item).tag)},'Sheet1',['A', num2str(item + 1)]);
                    xlswrite(file,{(app.default_tags(item).description)},'Sheet1',['B', num2str(item + 1)]);
                    if (contains(app.default_tags(item).tag, 'eyecon.numeric.histogram') || ...
                            contains(app.default_tags(item).tag, 'eyecon.volumetric.histogram') || ...
                            contains(app.default_tags(item).tag, 'multieye.ch1_absorbance') || ...
                            contains(app.default_tags(item).tag, 'multieye.ch1_bl_corr_bsorbance') || ...
                            contains(app.default_tags(item).tag, 'multieye.ch1_intensity') || ...
                            contains(app.default_tags(item).tag, 'multieye.ch1_reflectance'))
                        add_sample = num2str(app.default_tags(item).sample(1));
                        add_extra = num2str(app.default_tags(item).extra(1));
                        for ele = 2 : length(app.default_tags(item).sample)
                            add_sample = [add_sample, ',', num2str(app.default_tags(item).sample(ele))];
                            add_extra = [add_extra, ',', num2str(app.default_tags(item).extra(ele))];
                        end
                        xlswrite(file,{add_sample},'Sheet1',['C', num2str(item + 1)]);
                        xlswrite(file,{add_extra},'Sheet1',['D', num2str(item + 1)]);
                    else
                    xlswrite(file,{(app.default_tags(item).sample)},'Sheet1',['C', num2str(item + 1)]);
                    xlswrite(file,{(app.default_tags(item).extra)},'Sheet1',['D', num2str(item + 1)]); 
                    end
                end
            end
        end
        % -----------------------------------------------------------------
        function generatefilereport_writes_DELETED(app, file) % DO NOT USE: writes the defaults to summary file
            for item = 0 : length(app.default_tags)
                disp(item)
                Excel = actxserver('Excel.Application');
                set(Excel, 'Visible', 0);
                WB = Excel.Workbooks.Add;
                Sheets = Excel.ActiveWorkbook.Sheets;
                Sheet1 = Sheets.get('Item',1);
                Sheet1.Activate;
                if item < 1 % write headers one time
                    range = 'A1';
                    what = {'tag name'};
                    ActivesheetRange = get(Excel.Activesheet,'Range',range);
                    ActivesheetRange.Value = what;
                    range = 'B1';
                    what = {'description'};
                    ActivesheetRange = get(Excel.Activesheet,'Range',range);
                    ActivesheetRange.Value = what;
                    range = 'C1';
                    what = {'sample data'};
                    ActivesheetRange = get(Excel.Activesheet,'Range',range);
                    ActivesheetRange.Value = what;
                    range = 'D1';
                    what = {'extra info'};
                    ActivesheetRange = get(Excel.Activesheet,'Range',range);
                    ActivesheetRange.Value = what;
                else
                    range = ['A', num2str(item + 1)];
                    what = {(app.default_tags(item).tag)};
                    ActivesheetRange = get(Excel.Activesheet,'Range',range);
                    ActivesheetRange.Value = what;
                    range = ['B', num2str(item + 1)];
                    what = {(app.default_tags(item).description)};
                    ActivesheetRange = get(Excel.Activesheet,'Range',range);
                    ActivesheetRange.Value = what;
                    if (contains(app.default_tags(item).tag, 'eyecon.numeric.histogram') || ...
                            contains(app.default_tags(item).tag, 'eyecon.volumetric.histogram') || ...
                            contains(app.default_tags(item).tag, 'multieye.ch1_absorbance') || ...
                            contains(app.default_tags(item).tag, 'multieye.ch1_bl_corr_bsorbance') || ...
                            contains(app.default_tags(item).tag, 'multieye.ch1_intensity') || ...
                            contains(app.default_tags(item).tag, 'multieye.ch1_reflectance'))
                        add_sample = num2str(app.default_tags(item).sample(1));
                        add_extra = num2str(app.default_tags(item).extra(1));
                        for ele = 2 : length(app.default_tags(item).sample)
                            add_sample = [add_sample, ',', num2str(app.default_tags(item).sample(ele))];
                            add_extra = [add_extra, ',', num2str(app.default_tags(item).extra(ele))];
                        end
                        range = ['C', num2str(item + 1)];
                        what = {add_sample};
                        ActivesheetRange = get(Excel.Activesheet,'Range',range);
                        ActivesheetRange.Value = what;
                        range = ['D', num2str(item + 1)];
                        what = {add_extra};
                        ActivesheetRange = get(Excel.Activesheet,'Range',range);
                        ActivesheetRange.Value = what;
                    else
                    range = ['C', num2str(item + 1)];
                    what = {(app.default_tags(item).sample)};
                    ActivesheetRange = get(Excel.Activesheet,'Range',range);
                    ActivesheetRange.Value = what;
                    range = ['D', num2str(item + 1)];
                    what = {(app.default_tags(item).extra)};
                    ActivesheetRange = get(Excel.Activesheet,'Range',range);
                    ActivesheetRange.Value = what;
                    end
                end
            end
            SaveAs(WB,file)
            WB.Save();
            WB.Close();
            Excel.Quit();
        end
        % -----------------------------------------------------------------
        function generatefilereport_highlights(app, file) % highlights available descriptors as wrote to summary file 
            [~, col] = size(app.dataset);
            Excel = actxserver('Excel.Application');
            set(Excel, 'Visible', 0);
            WB = Excel.Workbooks.Open(file,0,false);
            range = 'A1:D1';
            WB.Worksheets.Item(1).Range(range).Interior.ColorIndex = 6; % yellow - first row 
            i = 1;
            for j = 1 : col
                for item = 1: length(app.default_tags)
                    if contains(app.dataset{i,j}, app.default_tags(item).tag)
                        range = ['A', num2str(item + 1), ':D', num2str(item + 1)];
                        WB.Worksheets.Item(1).Range(range).Interior.ColorIndex = 4; % green
                    %else
                    %    WB.Worksheets.Item(2).Range(range).Interior.ColorIndex = 3; % red 
                    end
                end
            end 
            WB.Save();
            WB.Close();
            Excel.Quit();
        end
        % -----------------------------------------------------------------
        function app = slaves_preallocate(app, mode, visible) % preallocate slave apps within the main, avoiding separate apps 
            app.slaves = struct(); 
            if isempty(mode) % default mode is modal - blocking interactions with main UI 
                mode = 'modal';
            end
            if isempty(visible) % default mode is off while allocating the slave apps  
                visible = 'off';
            end
            % eyecone 
            app.slaves(1).name = 'eyecone';
            app.slaves(1).appi = uifigure('Name','View eyecon data', ...
                            'Position', [100 100 1000 750], ...
                            'Visible', visible, 'WindowStyle', mode);
            [app, color] = onoroff(app, "eyecon"); 
            if isequal(color, [0 1 0]) % eyecon data exist 
                [~, where] = locate_var(app, "eyecon numeric hisogram"); % getting eyecon numeric histogram index 
                [~, app.slaves(1).numeric_hisogram_X] = parse_numbers(app, app.dataset{1,where}); % parsing histogram x values
                [row, ~] = size(app.dataset);  

                for i = 2 : row % collecting/parsing histograms 

                    [~, app.slaves(1).numeric_hisogram_Y] = parse_numbers(app, app.dataset{i,where});


                end 


                [~, where] = locate_var(app, "eyecon Dn 10"); % getting eyecon numeric density 10 
                [~, app.slaves(1).numeric_density_10] = app.dataset{2:end,where};
                [~, where] = locate_var(app, "eyecon Dn 50"); % getting eyecon numeric density 50 
                [~, app.slaves(1).numeric_density_50] = app.dataset{2:end,where}; 
                [~, where] = locate_var(app, "eyecon Dn 90"); % getting eyecon numeric density 90 
                [~, app.slaves(1).numeric_density_90] = app.dataset{2:end,where}; 

            else
                warning('eyecon data does not exist, building eyecon data app is ignored.');
            end

            % multieye
            app.slaves(2).name = 'multieye';
            app.slaves(2).appi = uifigure('Name','View multieye data', ...
                            'Position', [100 100 1000 750], ...
                            'Visible', visible, 'WindowStyle', mode);

        end
        % -----------------------------------------------------------------
        function my_write_up_area()


            function enable_slaves(app, which, mode)
                % mode = modal, normal, 
                if isempty(mode) % default mode is modal - blocking interactions with main UI 
                    mode = 'modal';
                end
                switch which % which = eyecon, multieye
                    case 'eyecon'
                    case 'multieye'
                end
            end
        end % -> my_write_up_area() 
        % single plotter 
        %function 
        % -----------------------------------------------------------------

        % -----------------------------------------------------------------

        % -----------------------------------------------------------------

        % ----------------------------------------------------------------- 

        % -----------------------------------------------------------------

        % -----------------------------------------------------------------
    end % methods 

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadFile
        function LoadFileSelected(app, event)
            app = loadfile(app);
            app = lampson(app, 'reset'); % turns the lamps red. default. 
            %app = slaves_preallocate(app, [], []); % calling to build/preallocate the slave apps - requesting with default settings 
        end

        % Menu selected function: Quit
        function QuitMenuSelected(app, event)
            app = closeapp(app);
        end

        % Menu selected function: Lights
        function LightsMenuSelected(app, event)
            app = lampson(app, 'update'); % turns the lamps on (Green) if descriptors exist, otherwise  red. 
        end

        % Menu selected function: Content
        function ContentMenuSelected(app, event)
            app = generatefilereport(app);
        end

        % Menu selected function: Export2WS
        function Export2WSMenuSelected(app, event)
            assignin('base','imported_dataset', app.dataset_in);
            %[app, ~] = NAwithNaN(app, []);
            [app, ~] = dot2space(app, []); 
            [app, ~] = underscore2space(app, []);
            app = removeUTC(app);
            assignin('base','cleaned_dataset', app.dataset);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FBDtoolkitUI = uifigure('Visible', 'off');
            app.FBDtoolkitUI.Name = 'FBD toolkit';
            app.FBDtoolkitUI.Icon = 'FBD.jpg';
            app.FBDtoolkitUI.Tag = 'Main window';
            app.File = uimenu(app.FBDtoolkitUI);
            app.File.Tooltip = {'Options to select a data file or quit the app'};
            app.File.Text = 'File ';
            app.File.Tag = 'File Menu';
            app.LoadFile = uimenu(app.File);
            app.LoadFile.MenuSelectedFcn = createCallbackFcn(app, @LoadFileSelected, true);
            app.LoadFile.Tooltip = {'Select the .xlsx file to parse data from'};
            app.LoadFile.Accelerator = 'L';
            app.LoadFile.Text = 'Load a File';
            app.LoadFile.Tag = 'Select and parse a data file';
            app.Quit = uimenu(app.File);
            app.Quit.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.Quit.Tooltip = {'Quits/Closes the app'};
            app.Quit.Accelerator = 'Q';
            app.Quit.Text = 'Quit App';
            app.Quit.Tag = 'Closes the app';
            app.Tools = uimenu(app.FBDtoolkitUI);
            app.Tools.Tooltip = {'Access the data  analytics tools (more on-the-way)'};
            app.Tools.Text = 'Tools';
            app.Tools.Tag = 'Data analytics tools and adds-on';
            app.Export2WS = uimenu(app.Tools);
            app.Export2WS.MenuSelectedFcn = createCallbackFcn(app, @Export2WSMenuSelected, true);
            app.Export2WS.Tooltip = {'Exports the imported dataset (as it is) to MATLAB workspace.'};
            app.Export2WS.Accelerator = 'V';
            app.Export2WS.Text = 'Available in WS?';
            app.Lights = uimenu(app.Tools);
            app.Lights.MenuSelectedFcn = createCallbackFcn(app, @LightsMenuSelected, true);
            app.Lights.Tooltip = {'Checks for availability of all descriptors in the dataset and turns the graphic indicators green (if present) otherwise red.'};
            app.Lights.Accelerator = 'O';
            app.Lights.Text = 'Indicators ON';
            app.Content = uimenu(app.Tools);
            app.Content.MenuSelectedFcn = createCallbackFcn(app, @ContentMenuSelected, true);
            app.Content.Tooltip = {'Generate a report of what descriptors exist in the imported file and write it to .xlsx file. '};
            app.Content.Accelerator = 'R';
            app.Content.Text = 'Create content report';
            app.Help = uimenu(app.FBDtoolkitUI);
            app.Help.Tooltip = {'Get help or know more about the app'};
            app.Help.Text = 'Help';
            app.Help.Tag = 'Help, Contact Us';
            app.Manuals = uimenu(app.Help);
            app.Manuals.Tooltip = {'Opens tutorials and user manual '};
            app.Manuals.Accelerator = 'H';
            app.Manuals.Text = 'Manuals';
            app.Manuals.Tag = 'Manual, Tutorials';
            app.Contributors = uimenu(app.Help);
            app.Contributors.Tooltip = {'The development team'};
            app.Contributors.Accelerator = 'M';
            app.Contributors.Text = 'Contributors';
            app.Contributors.Tag = 'Developers ';
            app.Image = uiimage(app.FBDtoolkitUI);
            app.Image.ImageSource = 'FBD.jpg';
            app.eyecon = uilamp(app.FBDtoolkitUI);
            app.eyecon.Tooltip = {'eyecone sensor. [Green if data present, othwerwise red].'};
            app.multieye = uilamp(app.FBDtoolkitUI);
            app.multieye.Tooltip = {'multieye sensor. [Green if data present, othwerwise red].'};
            app.GO_eyecon = uibutton(app.FBDtoolkitUI, 'push');
            app.GO_eyecon.Tooltip = {'Access eyecon data for further processing. [Green if available] '};
            app.GO_eyecon.Text = 'eycone';
            app.GO_multieye = uibutton(app.FBDtoolkitUI, 'push');
            app.GO_multieye.Tooltip = {'Access multieye data for further processing. [Green if available] '};
            app.GO_multieye.Text = 'mulieye';
            % Show the figure after all components are created
            app.FBDtoolkitUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FBD

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FBDtoolkitUI)
            else

                % Focus the running singleton app
                figure(runningApp.FBDtoolkitUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FBDtoolkitUI)
        end
    end
end