classdef toolkitUL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                       matlab.ui.Figure
        Button_tools_Spectroscopy  matlab.ui.control.Button
        Button_toolkit_on          matlab.ui.control.Button
        Button_file_whatsin        matlab.ui.control.Button
        Reports_Status             matlab.ui.control.TextArea
        MessageLabel               matlab.ui.control.Label
        Reports_files_List         matlab.ui.control.TextArea
        SelectedfileLabel          matlab.ui.control.Label
        Button_tools_Statistics    matlab.ui.control.Button
        Button_tools_Plot          matlab.ui.control.Button
        Button_files_Upload        matlab.ui.control.Button
        Button_files_Select        matlab.ui.control.Button
    end

    % properties shared within the app 
    % ---------------------------------------------------------------------
    properties (Access = private)
        dataset
        dataset_bckup
        default_tags
        import_file_names
        import_file_path
        tools
        ignore_list
        special_list
        bigTable 
        plots_list 
    end % end of properties 
    % ---------------------------------------------------------------------
    methods (Access = private)
        % -----------------------------------------------------------------
        function closeapp(app) % termonating the app ?
            selection = uiconfirm(app.main,'Close App?',...
                'Confirm Close',...
                        'Icon','warning');
            switch selection
                case 'OK' % closes the app
                    delete(app)
                case 'Cancel' % do nothing, return to app
                    app.Reports_Status.Value = 'User canceled closing app, App retuned to normal state. App ready to use.';
                    return
            end % end switch
        end % end function - closeapp 
        % -----------------------------------------------------------------
        function [app, export2WStext] = export2WS(app) % export datasets to worspace?
            selection = uiconfirm(app.main,'Make dataset(s) available in MATLAB Workspace?',...
                'Sending to Workspace ...',...
                        'Icon','question');
            switch selection
                case 'OK'
                    export2WStext = 'on';
                case 'Cancel'
                    export2WStext = 'off';
            end % end switch
        end % end function - export2WS
        % -----------------------------------------------------------------
        function app = export2WS_do(app) % export to WS - do
            app.Reports_Status.Value = 'Waiting for user selection ....';
            [app, export2WStext] = export2WS(app); % prompt user to see if should export to WS 
            if isequal(export2WStext, 'on')
                assignin('base','dataset', app.dataset);
                app.Reports_Status.Value = 'Exported dataset(s) to WS.';
            else
                app.Reports_Status.Value = 'Canceled dataset(s) export to WS.'; % do nothin 
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function [app, text] = files_what_types(app, operation) % file types
            xlsx = 0;
            csv = 0;
            if iscell(app.import_file_names)
                [~, thismanyfiles] = size(app.import_file_names);
                for file = 1 : thismanyfiles
                    if contains(app.import_file_names{1,file}, '.xlsx')
                        xlsx = xlsx + 1;
                    end % end if 
                    if contains(app.import_file_names{1,file}, '.csv')
                        csv = csv + 1;
                    end % end if 
                end % end for 
                if (csv > 0) && (xlsx > 0)
                    text = ...
                        [operation, ' ', ...
                        num2str(csv), ' csv file(s) and ', ...
                        num2str(xlsx), ' xlsx file(s).',];
                end % end if 
                if (csv == 0) && (xlsx > 0)
                    text = ...
                        [operation, ' ', ...
                        num2str(xlsx), ' xlsx file(s).',];
                end % end if 
                if (csv > 0) && (xlsx == 0)
                    text = ...
                        [operation, ' ', ...
                        num2str(csv), ' csv file(s).',];
                end % end if 
            else 
                if contains(app.import_file_names, '.xlsx')
                    text = ...
                    [operation, ' 1 xlsx file(s).'];
                end % end if 
                if contains(app.import_file_names, '.csv')
                    text = ...
                    [operation, ' 1 csv file(s).'];
                end % end if 
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function [app, selected_file_list_text] = files_list_filenames(app) % Reports_files_List
            if iscell(app.import_file_names)
                [~, thismanyfiles] = size(app.import_file_names);
                selected_file_list_text = cell(thismanyfiles,1);
                for file = 1 : thismanyfiles
                    selected_file_list_text{file, 1} = app.import_file_names{1, file};
                end  % end for 
            else
                selected_file_list_text = string(app.import_file_names);
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function [app, text] = files_select(app) % Button_files_Select
            app.Reports_Status.Value = 'Waiting for user to select file(s) ....';
            [app.import_file_names, app.import_file_path,~] = ...
                uigetfile({'*.xlsx;*.csv',...
                'Excel files (*.xlsx, *.csv)'}, ...
                'Select .xlsx file(s) to import ....', ...
                'MultiSelect', 'on');
            if isequal(app.import_file_names, 0)
                text = 'User canceled file(s) selection. Click "Add file(s)" to add file(s) for import.';
            else
                [app, text] = files_what_types(app, 'Selected');
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function app = files_upload_do(app) % do Button_files_Upload
            if iscell(app.import_file_names)
                [~, thismanyfiles] = size(app.import_file_names);
                for file = 1 : thismanyfiles
                    [~, ~, app.dataset{1, file}] = ...
                        xlsread([app.import_file_path, ...
                        app.import_file_names{1,file}]);
                end % end for 
            else 
                [~, ~, app.dataset{1, 1}] = ...
                    xlsread([app.import_file_path, ...
                    app.import_file_names]);
            end % end if 
        end % end function 
        % -----------------------------------------------------------------
        function app = files_upload(app) % Button_files_Upload
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
                app.dataset{1, thismanyfiles} = struct();
            else
                thismanyfiles = 1;
                app.dataset = cell(1);
            end % end if 
            bar = uifigure;
            bar.Position = [500 500 500 125];
            bar.WindowStyle = 'modal';
            inbar = uiprogressdlg(bar,...
                'Title',['Importing ', ...
                    num2str(thismanyfiles), ...
                    ' file(s) ....'],...
                'Indeterminate','on');
            drawnow;
            app = files_upload_do(app);
            close(inbar);
            close(bar);  
        end % end function 
        % -----------------------------------------------------------------
        function parsed = parser_comma(~, source) % extracts numbers from a mixed string+number+...
            splitted = regexp(source,',','split');
            dummy = splitted{1,1};
            dummy = dummy(2:end);
            splitted{1,1} = dummy;
            dummy = splitted{1,end};
            dummy = dummy(1:end-1);
            splitted{1,end} = dummy;
            [~, thismany] = size(splitted);
            dummy = zeros(1, thismany);
            for i = 1 : thismany
                dummy(1,i) = str2double(splitted{1,i});
            end % end for 
            parsed = dummy;
        end % end function 
        % -----------------------------------------------------------------
        function app = remove_UTC(app, file_indx) % removes UTC in selected files 
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [row , ~] = size(app.dataset{1,file});
                for i = 2 : row  
                    app.dataset{1,file}{i,1} = ...
                        erase(app.dataset{1,file}{i,1}, ...
                        ' GMT+0000 (Coordinated Universal Time)');
                end % end for 
            end % end for 
        end % end function 
        % -----------------------------------------------------------------
        function app = replace_NA_with_NaN(app, file_indx) % replaces N/A with NaN in selected files 
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [row , col] = size(app.dataset{1,file});
                for i = 1 : row  
                    for j = 1 : col
                        if ischar(app.dataset{1,file}{i,j})
                            if contains(app.dataset{1,file}{i,j}, 'N/A') 
                                app.dataset{1,file}{i,j} = nan;
                            else 
                                continue
                            end % end if 
                        else
                            continue
                        end % end if 
                    end % end for 
                end % end for 
            end % end for 
        end % end function       
        % -----------------------------------------------------------------
        function app = replace_underscore_with_space(app, file_indx) % replaces _ with space in selected files 
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [~ , col] = size(app.dataset{1,file});
                for j = 1 : col
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, '_', ' ');
                end % end for 
            end % end for 
        end % end function       
        % -----------------------------------------------------------------
        function app = replace_dot_with_space(app, file_indx) % replaces . with space in selected files 
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [~ , col] = size(app.dataset{1,file});
                for j = 1 : col
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, '.', ' ');
                end % end for 
            end % end for 
        end % end function       
        % -----------------------------------------------------------------
        function app = rename_tags(app, file_indx)
            thismany = length(file_indx);
            for indx = 1 : thismany 
                file = file_indx(indx); 
                [~ , col] = size(app.dataset{1,file});
                for j = 1 : col
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, 'Dn', 'numeric density ');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, 'Dv', 'volumetric density ');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, 'hg num', 'histogram - numeric');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, 'hg vol', 'histogram - volumetric');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ' temp ', ' temperature ');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ' ch', ' channel ');
                    app.dataset{1,file}{1,j} = ...
                        strrep(app.dataset{1,file}{1,j}, ' bl corr bsorbance', ' corrected absorbance ');
                end % end for 
            end % end for 

        end % end function 
        % -----------------------------------------------------------------
        function app = clean(app) % cleans tag names and N/A
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
                file_indx = 1 : thismanyfiles;
            else
                file_indx = 1;
            end % end if 
            app = remove_UTC(app, file_indx); 
            app = replace_NA_with_NaN(app, file_indx);
            app = replace_underscore_with_space(app, file_indx);
            app = replace_dot_with_space(app, file_indx); 
            app = rename_tags(app, file_indx);
        end % end function 
        % -----------------------------------------------------------------
        function app = create_file_var(app) % creates file(s) and variable(s) list
            app = ignorelist(app);
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
            else
                thismanyfiles = 1;
            end % end if 
            for file = 1 : thismanyfiles % listing filenames and variables .... 
                [~ , variables] = size(app.dataset{1,file}); 
                fid = ['db_f_', num2str(file)];
                app.tools{1}.(fid) = uitreenode(app.tools{1}.database);
                if thismanyfiles == 1
                    app.tools{1}.(fid).Text = ['Filename: ', app.import_file_names, ' ---|--- Batch name: ', app.dataset{1,1}{2,4}, ' ---|--- Batch id: ', app.dataset{1,1}{2,3}];
                else  
                    app.tools{1}.(fid).Text = ['Filename: ', app.import_file_names{1, file}, ' ---|--- Batch name: ', app.dataset{1,file}{2,4}, ' ---|--- Batch id: ', app.dataset{1,file}{2,3}];
                end % end if 
                for variable = 1 : variables 
                    if (~contains(app.ignore_list, app.dataset{1,file}{1,variable}))
                        vid = [fid,'_v_' num2str(variable)];
                        app.tools{1}.(vid) = uitreenode(app.tools{1}.(fid));
                        app.tools{1}.(vid).Text = app.dataset{1,file}{1,variable};
                        app.tools{1}.(vid).UserData = [file, variable];
                    end
                end % end for 
            end % end for 
        end % end function 
        % -----------------------------------------------------------------
        function app = ignorelist(app) % variables to ignore 
            app.ignore_list = "Unix Timestamp, " + ...
                "batch id, " + ...
                "batch name, " + ...
                "eyecon histogram - numeric max, " + ...
                "eyecon histogram - numeric min, " + ...
                "eyecon histogram - numeric nbins, " + ...
                "eyecon histogram - volumetric max, " + ...
                "eyecon histogram - volumetric min, " + ...
                "eyecon histogram - volumetric nbins, " + ...
                "eyecon image path, " + ...
                "fluid bed airflow bypass pid out ac, " + ...
                "fluid bed airflow on sp, " + ...
                "fluid bed airflow on sp ac, " + ...
                "fluid bed airflow pid out ac, " + ...
                "fluid bed airflow rate ac, " + ...
                "fluid bed airflow rate sp, " + ...
                "fluid bed airflow rate sp ac, " + ...
                "fluid bed alarm occured ac, " + ...
                "fluid bed atomising pressure ac, " + ...
                "fluid bed atomising pressure pid out ac, " + ...
                "fluid bed atomising pressure sp, " + ...
                "fluid bed atomising pressure sp ac, " + ...
                "fluid bed exhaust temperature ac, " + ...
                "fluid bed filter pressure differential ac, " + ...
                "fluid bed inlet air temperature ac, " + ...
                "fluid bed inlet air temperature pid out ac, " + ...
                "fluid bed inlet air temperature sp, " + ...
                "fluid bed main heater air temperature pid out ac, " + ...
                "fluid bed main heater air temperature sp ac, " + ...
                "fluid bed main heater temperature ac, " + ...
                "fluid bed phase number ac, " + ...
                "fluid bed phase time hours ac, " + ...
                "fluid bed phase time minutes ac, " + ...
                "fluid bed phase time secs ac, " + ...
                "fluid bed phase type ac, " + ...
                "fluid bed process mode on is ready ac, " + ...
                "fluid bed process mode on sp ac, " + ...
                "fluid bed process mode on sp do not use, " + ...
                "fluid bed product pressure differential ac, " + ...
                "fluid bed product temperature ac, " + ...
                "fluid bed remote on ac, " + ...
                "fluid bed remote on is ready ac, " + ...
                "fluid bed shake mode sp, " + ...
                "fluid bed shake mode sp ac, " + ...
                "fluid bed shake pause ac, " + ...
                "fluid bed shake pause sp," + ...
                "fluid bed shake pause sp ac, " + ...
                "fluid bed shake time ac, " + ...
                "fluid bed shake time sp, " + ...
                "fluid bed shake time sp ac, " + ...
                "fluid bed spray pump on is ready ac, " + ...
                "fluid bed spray pump on sp, " + ...
                "fluid bed spray pump on sp ac, " + ...
                "fluid bed spray pump speed ac, " + ...
                "fluid bed spray pump speed sp, " + ...
                "fluid bed spray pump speed sp ac', " + ...
                "fluid bed watchdog counter from plc ac, " + ...
                "fluid bed watchdog counter to plc sp, " + ...
                "fluid bed watchdog from plc ac, " + ...
                "fluid bed watchdog to plc sp, " + ...
                "level," + ...
                "log," + ...
                "Nozzle airflow sensor currentAirflowRate," + ...
                "Nozzle airflow sensor units," + ...
                "results path" + ...
                "scales connection alive," + ...
                "scales start," + ...
                "scales weight," + ...
                "thermoalert connection state," + ...
                "thermoalert humidity," + ...
                "thermoalert temperature";
        end
        % -----------------------------------------------------------------
        function app = assign_node_data(app) % Button_files_Upload
            bar = uifigure;
            bar.Position = [500 500 500 125];
            bar.WindowStyle = 'modal';
            inbar = uiprogressdlg(bar,...
                'Title','Building database ....',...
                'Indeterminate','on');
            drawnow;
            app = assign_node_data_do(app);
            close(inbar);
            close(bar);  
        end % end function 
        % -----------------------------------------------------------------
        function app = assign_node_data_do(app) % populates data to each node
            if iscell(app.import_file_names) 
                [~, thismanyfiles] = size(app.import_file_names);
            else
                thismanyfiles = 1;
            end % end if 
            for file = 1 : thismanyfiles % listing filenames and variables .... 
                [timestamps, variables] = size(app.dataset{1,file}); 
                fid = ['db_f_', num2str(file)];
                for variable = 1 : variables 
                    if (~contains(app.ignore_list, app.dataset{1,file}{1,variable}))
                    vid = [fid,'_v_' num2str(variable)];
                        % -------------------------------------------------
                        if (contains(app.tools{1}.(vid).Text, 'Time Stamp')) % special case 1 : it's human time 
                            date = cell(timestamps-1,1);
                            hours = cell(timestamps-1,1);
                            minutes = cell(timestamps-1,1);
                            seconds = cell(timestamps-1,1);
                            current_time = cell(timestamps-1,1);
                            for timestamp = 2 : timestamps
                                date{timestamp-1, 1} = app.dataset{1,file}{timestamp,variable}(1:end-9);
                                time = app.dataset{1,file}{timestamp,variable}(end-7:end);
                                hours{timestamp-1, 1} = str2double(time(1:2));
                                minutes{timestamp-1, 1} = str2double(time(4:5));
                                seconds{timestamp-1, 1} = str2double(time(7:8));
                                current_time{timestamp-1,1} = hours{timestamp-1, 1}*3600 + minutes{timestamp-1, 1}*60 + seconds{timestamp-1, 1};
                                %current_time{timestamp-1,1} = duration{timestamp-1,1} - current_time{1,1};
                            end % end for 
                            app.tools{1}.(vid).NodeData = {"date", "hours", "minutes", "seconds", "current time"; date, hours, minutes, seconds, current_time};
                        %end % end if 
                        % -------------------------------------------------
                        elseif (contains(app.tools{1}.(vid).Text, 'multieye')) % special case 2 : it's multieye sensor
                            timestamp = 1; % this is label
                            source = app.dataset{1,file}{timestamp,variable};
                            splitted = regexp(source,',','split');
                            [~, thismany] = size(splitted);
                            label = zeros(thismany, 1);
                            value_1 = splitted{1,1};
                            value_1 = regexp(value_1,'[','split');
                            label(1,1) = str2double(value_1{1,2});
                            value_end = splitted{1,end};
                            value_end = regexp(value_end,']','split');
                            label(end,1) = str2double(value_end{1,1});
                            for element = 2 : (thismany-1)
                                label(element, 1) = str2double(splitted{1,element});
                            end % end for 
                            MAT = zeros(thismany, timestamps-1);
                            for timestamp = 2 : timestamps
                                if timestamp > 2
                                    source = app.dataset{1,file}{timestamp,variable};
                                    splitted = regexp(source,',','split');
                                    [~, thismanys] = size(splitted);
                                    dummy = zeros(thismanys, 1);
                                    value_1 = splitted{1,1};
                                    value_1 = regexp(value_1,'[','split');
                                    dummy(1,1) = str2double(value_1{1,2});
                                    value_end = splitted{1,end};
                                    value_end = regexp(value_end,']','split');
                                    dummy(end,1) = str2double(value_end{1,1});
                                    for element = 2 : (thismanys-1)
                                        dummy(element, 1) = str2double(splitted{1,element});
                                    end % end for 
                                    MAT(:, timestamp-1) = dummy; 
                                end % end if 
                            end % end for 
                            app.tools{1}.(vid).NodeData = {"labels",  "data"; label, MAT}; 
                        %end % end if 
                        % -------------------------------------------------
                        elseif (contains(app.tools{1}.(vid).Text, 'eyecon numeric histogram') ...
                                || contains(app.tools{1}.(vid).Text, 'eyecon volumetric histogram')) % special case 2 : it's eyecon sensor 
                            timestamp = 1; % this is label
                            source = app.dataset{1,file}{timestamp,variable};
                            splitted = regexp(source,',','split');
                            [~, thismany] = size(splitted);
                            label = zeros(thismany, 1);
                            value_1 = splitted{1,1};
                            value_1 = regexp(value_1,'[','split');
                            label(1,1) = str2double(value_1{1,2});
                            value_end = splitted{1,end};
                            value_end = regexp(value_end,']','split');
                            label(end,1) = str2double(value_end{1,1});
                            for element = 2 : (thismany-1)
                                label(element, 1) = str2double(splitted{1,element});
                            end % end for 
                            MAT = zeros(thismany, timestamps - 1);
                            for timestamp = 2 : timestamps
                                source = app.dataset{1,file}{timestamp,variable};
                                splitted = regexp(source,',','split');
                                [~, thismanys] = size(splitted);
                                dummy = zeros(thismanys, 1);
                                value_1 = splitted{1,1};
                                value_1 = regexp(value_1,'[','split');
                                dummy(1,1) = str2double(value_1{1,2});
                                value_end = splitted{1,end};
                                value_end = regexp(value_end,']','split');
                                dummy(end,1) = str2double(value_end{1,1});
                                for element = 2 : (thismanys-1)
                                    dummy(element, 1) = str2double(splitted{1,element});
                                end % end for 
                                MAT(:, timestamp-1) = dummy;      
                            end % end for 
                            app.tools{1}.(vid).NodeData = {"labels",  "data"; label, MAT}; 
                        %end % end if 
                        % -------------------------------------------------
                        elseif (contains(app.tools{1}.(vid).Text, 'eyecon numeric density') || ...
                                contains(app.tools{1}.(vid).Text, 'eyecon volumetric density') || ...
                                contains(app.tools{1}.(vid).Text, 'fluid bed'))
                        %else
                            dummy = zeros(timestamps-1,1);                             
                            for timestamp = 2 : timestamps
                                dummy(timestamp-1, 1) = app.dataset{1,file}{timestamp,variable};
                            end % end for 
                            app.tools{1}.(vid).NodeData = dummy;
                        end % end if 
                        % -------------------------------------------------
                    end % end if 
                end % end for 
            end % end for 
        end % function 
        % -----------------------------------------------------------------
        function app = features_plottool(app) % creates plottool
            plotter.plottool = uifigure('Visible', 'off', WindowStyle = 'normal');
            plotter.plottool.CloseRequestFcn = @(src,event)close_plotter(src);
            plotter.plottool.AutoResizeChildren = 'off';
            plotter.plottool.Position = [100 100 1153 547];
            plotter.plottool.Name = 'Plot tool ';
            plotter.plottool.Resize = 'off';
            plotter.database = uitree(plotter.plottool, 'checkbox'); % create property database
            plotter.database.CheckedNodesChangedFcn = @nodechange;
            plotter.database.Position = [36 59 1106 441];
            plotter.Reports_varfile = uilabel(plotter.plottool); % Create Reports_varfile
            plotter.Reports_varfile.Position = [37 508 511 24];
            plotter.Reports_varfile.Text = 'Select file(s) and variable(s) for plotting';
            plotter.Reports_varfile.Tooltip = {'Shows imported files and the variables in each file. Select variable(s) for plotting.'};
            plotter.Button_plot_detailed = uibutton(plotter.plottool, 'state'); % Create Button_plot_detailed
            plotter.Button_plot_detailed.ValueChangedFcn = @pushed_Button_plot_detailed;
            plotter.Button_plot_detailed.Enable = 'off';
            plotter.Button_plot_detailed.Value = 0;
            plotter.Button_plot_detailed.Position = [986 18 157 30];
            plotter.Button_plot_detailed.Text = 'Go to detailed plot(s)';
            plotter.MessageTextAreaLabel = uilabel(plotter.plottool); % Creates Message Text Area Label
            plotter.MessageTextAreaLabel.FontAngle = 'italic';
            plotter.MessageTextAreaLabel.Position = [36 23 58 22];
            plotter.MessageTextAreaLabel.Text = 'Message:';
            plotter.Reports_Status = uitextarea(plotter.plottool); % Creates Reports_Status
            plotter.Reports_Status.Editable = 'off';
            plotter.Reports_Status.FontWeight = 'bold';
            plotter.Reports_Status.Value = {'Plot tool ready. Select variable(s) for plotting.'};
            plotter.Reports_Status.Position = [93 18 887 29];
            app.tools{1} = plotter;
            % -------------------------------------------------------------
            function close_plotter(fig) % close plotter  
                plotter.Reports_Status.Value = 'Closing plotter, waiting for user selection ....';
                msg = 'Closing plot tool?';
                title = 'Confirm close';
                selection = uiconfirm(fig,msg,title, ...
                    'Options',{'Close','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',2);
                switch selection
                    case 'Close'
                        app.tools{1}.plottool.Visible = 'off';
                        plotter.Reports_Status.Value = 'Plot tool closed.';
                        return
                    case 'Cancel'
                        plotter.Reports_Status.Value = 'Closing plot tool canceled.';
                        return
                end % end switch
            end % end function
            % -------------------------------------------------------------
            function nodechange(src, event) % checks selected nodes  
                style_var = uistyle('FontAngle','italic', 'FontColor','r', 'FontWeight','bold');
                if (~isempty(event.PreviousCheckedNodes)) 
                    removeStyle(app.tools{1}.database);
                end
                if (~isempty(event.CheckedNodes)) 
                    plotter.eventCheckedNodes = event.CheckedNodes;
                    plotter.Button_plot_detailed.Enable = 'on';
                    [ticked_nodes, ~] = size(event.CheckedNodes);
                    plotter.Reports_Status.Value = ['# ', num2str(ticked_nodes), ' variable(s) currently selected.'];
                    for tick = 1 : ticked_nodes
                        if (~isempty(event.CheckedNodes(tick, 1).UserData))
                            file = event.CheckedNodes(tick, 1).UserData(1,1);
                            variable = event.CheckedNodes(tick, 1).UserData(1,2);
                            fid = ['db_f_', num2str(file)];
                            vid = [fid,'_v_' num2str(variable)];
                            if contains(app.tools{1}.(vid).Text, event.CheckedNodes(tick, 1).Text)
                                addStyle(app.tools{1}.database,style_var,'node',app.tools{1}.(vid));
                            end % end if 
                        end % end if 
                    end % end for
                else % is empty 
                    plotter.Button_plot_detailed.Enable = 'off';
                    plotter.Reports_Status.Value = 'No variable(s) selected. Select variable(s) to activate detailed plot(s) button again.';
                end % end if 
            end % end function - nodechange
            % -------------------------------------------------------------
            function pushed_Button_plot_detailed(src, event) % checks state of button 
                if plotter.Button_plot_detailed.Value == 1 % pushed 
                    plotter.Button_plot_detailed.Enable = 'off';
                    plotter.Button_plot_detailed.Value = 0;
                    plotter.Reports_Status.Value = 'Plot selected variable(s) in a new window.';
                    % 
                    % 
                    plot_selected_data(src);
                    % 
                    %
                    plot_2D_listed = app.plots_list{1};
                    plot_3D_listed = app.plots_list{2};


                    current_fig = 0;
                    timestamp_clicked_old = 0;
                    while (~isempty(current_fig))
                        current_fig = get(groot,'CurrentFigure');
                        [timestamp_clicked, ~] = ginput(1);
                        if (~timestamp_clicked == timestamp_clicked_old)
                            disp(timestamp_clicked);
                        end
                        timestamp_clicked_old = timestamp_clicked;
                        pause(0.1)
                    end % end while
                    
% 
% 
%                     
% 
%                     app.instances{instance, 1} = fig_2D_dcm;
%                     app.instances{instance, 2} = fig_3D_dcm;
% 
%                     
% 
%                     fig_3D_dcm.(plot_3d)
%                     fig_2D_dcm.(plot_2d)


% 
%                     
%                         
%                         
%                      


                else % released 
                    plotter.Button_plot_detailed.Enable = 'on';
                end % end if 
            end % end function - pushed_Button_plot_detailed
            % -------------------------------------------------------------
            function close_fig(fig) % close, save or return to the figure 
                plotter.Reports_Status.Value = 'Closing figure, waiting for user selection ....';
                msg = 'Close figure without saving?';
                title = 'Confirm close';
                selection = uiconfirm(fig,msg,title, ...
                    'Options',{'Close','Save','Cancel'}, ...
                    'DefaultOption',2,'CancelOption',3);
                switch selection
                    case 'Close'
                        plotter.Reports_Status.Value = 'Closed figure without saving(!).';
                        delete(fig)
                        plotter.Reports_Status.Value = 'Plot tool is ready. Select variable(s) to activate detailed plot(s) button.';
                    case 'Save'
                        plotter.Reports_Status.Value = 'Closing figure with save, waiting for user to select file save location ....';
                        [~,~] = uiputfile('*.fig','Save figure');
                        plotter.Reports_Status.Value = 'Plot tool is ready. Select variable(s) to activate detailed plot(s) button.';
                    case 'Cancel'
                        plotter.Reports_Status.Value = 'Closing figure canceled, retuned to figure.';
                        return
                end % end switch
            end % end function
            % -------------------------------------------------------------
            function plot_selected_data(src) % opens plotted data in a new figure 
                indx_2D = 1;
                indx_3D = 1;
                indx_time = 1;
                plot_3D_vid{indx_3D,1} = [];
                plot_2D_vid{indx_2D,1} = [];
                plot_time{indx_time,1} = [];
                [ticked_nodes, ~] = size(plotter.eventCheckedNodes);
                fileID = []; 
                for tick = 1 : ticked_nodes % collecting info on what's selected 
                    if (~isempty(plotter.eventCheckedNodes(tick, 1).UserData))
                        file = plotter.eventCheckedNodes(tick, 1).UserData(1,1);
                        fileID = [fileID; file];
                        variable = plotter.eventCheckedNodes(tick, 1).UserData(1,2);
                        fid = ['db_f_', num2str(file)];
                        vid = [fid,'_v_' num2str(variable)];
                        if (contains(app.tools{1}.(vid).Text, 'Time'))  % special case 1 : it's human time
                            plot_time{indx_time,1} = vid;
                            indx_time = indx_time + 1;
                        elseif (contains(app.tools{1}.(vid).Text, 'multieye')) % special case 2 : it's multieye sensor
                            plot_3D_vid{indx_3D, 1} = vid;
                            indx_3D = indx_3D + 1;
                        elseif (contains(app.tools{1}.(vid).Text, 'eyecon numeric histogram') ...
                                || contains(app.tools{1}.(vid).Text, 'eyecon volumetric histogram')) % special case 2 : it's eyecon sensor 
                            plot_3D_vid{indx_3D, 1} = vid;
                            indx_3D = indx_3D + 1;
                        else 
                            plot_2D_vid{indx_2D, 1} = vid;
                            indx_2D = indx_2D + 1;
                        end % end if
                    end % end if 
                end % end for 
                if (~isempty(fileID)) 
                    if diff(fileID)>0 % this means multi files 
                        % not coded yet
                    else % this means single file 
                        [plot_2ds, ~] = size(plot_2D_vid);
                        [plot_3ds, ~] = size(plot_3D_vid);
                        file = fileID(1);
                        if isempty(plot_time{1,1}) % means user didn't select time 
                            variable = 1;
                            fid = ['db_f_', num2str(file)];
                            vid = [fid,'_v_' num2str(variable)];
                            plot_time{1,1} = vid;
                        end
                        time_as_x = cell2mat(app.tools{1}.(plot_time{1,1}).NodeData{2,5});
                        time_as_x = time_as_x - time_as_x(1);
                        x = time_as_x; 
                        if (~isempty(plot_2D_vid{1,1}))
                            fig_2D = uifigure('Visible', 'off', WindowStyle = 'normal');
                            fig_2D.CloseRequestFcn = @(src,event)close_fig(src);
                            fig_2D.Position = [100 100 1150 550];
                            fig_2D.Name = '2D plot of selected variables versus time (by default) ....';
                            p_2D = uipanel(fig_2D,'Position',[5 5 1000 545]);
                            ax_2D = uiaxes(p_2D,'Position',[1 1 999 540], 'NextPlot','add');
                            hold(ax_2D, 'on');
                            for plot_2d = 1 : plot_2ds
                                vid = plot_2D_vid{plot_2d, 1};
                                plot_2D_listed(this) = plot(ax_2D, x, app.tools{1}.(vid).NodeData, 'o', 'MarkerEdgeColor', [rand rand rand], 'DisplayName', app.tools{1}.(plot_2D_vid{plot_2d,1}).Text);
                            end % end for
                            xlabel(ax_2D, 'Time [sec]');
                            ylabel(ax_2D, 'Selected variable [values]');
                            xlim(ax_2D, [min(min(x)) max(max(x))]);
                            title(ax_2D, ['Batch id: ', app.dataset{1,file}{2,3}]);
                            hold(ax_2D, 'off');
                            legend(ax_2D)
                            fig_2D_dcm = datacursormode(fig_2D);
                            fig_2D_dcm.Enable = 'on'; 
                            fig_2D.Visible = 'on';
                        end % end if 
                        if (~isempty(plot_3D_vid{1,1}))
                            for plot_3d = 1 : plot_3ds
                                vid = plot_3D_vid{plot_3d, 1};
                                fig_3D = uifigure('Visible', 'off', WindowStyle = 'normal');
                                fig_3D.CloseRequestFcn = @(src,event)close_fig(src);
                                fig_3D.Position = [100 100 1150 550];
                                fig_3D.Name = '3D plot of selected variables versus time (by default) ....';
                                p_3D = uipanel(fig_3D,'Position',[5 5 1000 545]);
                                ax_3D = uiaxes(p_3D,'Position',[1 1 999 540], 'View', [45 45]);
                                hold(ax_3D, 'on');
                                [thismany, timestamps] = size(app.tools{1}.(vid).NodeData{2,2}); 
                                for this = 1 : thismany
                                    y = ones(timestamps,1) * app.tools{1}.(vid).NodeData{2,1}(this);
                                    z = app.tools{1}.(vid).NodeData{2,2}(this, :);
                                    plot_3D_listed(this) = plot3(ax_3D, x, y, z, '.', 'MarkerEdgeColor', [rand rand rand], 'DisplayName', num2str(app.tools{1}.(vid).NodeData{2,1}(this)));
                                end % end for 
                                xlabel(ax_3D, 'Time [sec]');
                                ylabel(ax_3D, 'Special feature of selected variable');
                                zlabel(ax_3D, 'Selected variable [values]');
                                xlim(ax_3D, [min(min(x)) max(max(x))]);
                                plot_legend = regexp(app.tools{1}.(vid).Text,'[','split');
                                plot_legend = plot_legend{1,1};
                                title(ax_3D, {plot_legend; ['Batch id: ', app.dataset{1,file}{2,3}]});
                                hold(ax_3D, 'off');
                                fig_3D_dcm = datacursormode(fig_3D);
                                fig_3D_dcm.Enable = 'on'; 
                                fig_3D.Visible = 'on';
                            end % end for 
                        end % end if 
                    end % end if 
                end % end if 
                app.plots_list{1} = plot_2D_listed;
                app.plots_list{2} = plot_3D_listed;
            end % end function - plot_selected_data
        % -----------------------------------------------------------------
%             function txt = clicked_data_2D(src,event,ax_2D)
%                 txt =  ['X: ', num2str(event.Position(1)), ' , Y: ', num2str(event.Position(2))];
%             end 
        % -----------------------------------------------------------------
        end % end function - features_plottool
        % -----------------------------------------------------------------
        function app = tags_default(app) % default tags 
            app.default_tags = struct();
            item = 1;
            app.default_tags(item).tag{1} = 'Time';
            app.default_tags(item).tag{2} = 'Stamp';
            app.default_tags(item).description = 'STRING: This is a human readable time stamp.';
            item = 2;
            app.default_tags(item).tag{1} = 'Unix';
            app.default_tags(item).tag{2} = 'Timestamp';
            app.default_tags(item).description = 'DOUBLE: This a machine readable time stamp. To convert to human readable time use: datestr(x/86400 + 719529)';
            item = 3;
            app.default_tags(item).tag{1} = 'batch_id';
            app.default_tags(item).description = 'STRING: This the batch id generated by system.';
            item = 4;
            app.default_tags(item).tag{1} = 'batch_name';
            app.default_tags(item).description = 'STRING: This the batch name supplied by user once running an experiment.';
            item = 5;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'Dn10';
            app.default_tags(item).description = 'DOUBLE: The numeric density for size 10 as measured by eyecon.';
            item = 6;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'Dn50';
            app.default_tags(item).description = 'DOUBLE: The numeric density for size 50 as measured by eyecon.';
            item = 7;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'Dn90';
            app.default_tags(item).description = 'DOUBLE: The numeric density for size 90 as measured by eyecon.';
            item = 8;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'Dv10';
            app.default_tags(item).description = 'DOUBLE: The volumetric density for size 10 as measured by eyecon.';
            item = 9;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'Dv25';
            app.default_tags(item).description = 'DOUBLE: The volumetric density for size 25 as measured by eyecon.';
            item = 10;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'Dv50';
            app.default_tags(item).description = 'DOUBLE: The volumetric density for size 50 as measured by eyecon.';
            item = 11;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'Dv75';
            app.default_tags(item).description = 'DOUBLE: The volumetric density for size 75 as measured by eyecon.';
            item = 12;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'Dv90';
            app.default_tags(item).description = 'DOUBLE: The volumetric density for size 90 as measured by eyecon.';
            item = 13;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'hg';
            app.default_tags(item).tag{3} = 'num';
            app.default_tags(item).tag{4} = 'max';
            app.default_tags(item).description = 'DOUBLE: The maximum value in the numeric histogram as measured by eyecon.';
            item = 14;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'hg';
            app.default_tags(item).tag{3} = 'num';
            app.default_tags(item).tag{4} = 'min';
            app.default_tags(item).description = 'DOUBLE: The minimum value in the numeric histogram as measured by eyecon.';
 	        item = 15;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'hg';
            app.default_tags(item).tag{3} = 'num';
            app.default_tags(item).tag{4} = 'nbins';
            app.default_tags(item).description = 'DOUBLE: The number of bins used in the numeric histogram as measured by eyecon.';
            item = 16;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'hg';
            app.default_tags(item).tag{3} = 'vol';
            app.default_tags(item).tag{4} = 'max';
            app.default_tags(item).description = 'DOUBLE: The maximum value in the volumetric histogram as measured by eyecon.';
            item = 17;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'hg';
            app.default_tags(item).tag{3} = 'vol';
            app.default_tags(item).tag{4} = 'min';
            app.default_tags(item).description = 'DOUBLE: The minimum value in the volumetric histogram as measured by eyecon.';
            item = 18;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'hg';
            app.default_tags(item).tag{3} = 'vol';
            app.default_tags(item).tag{4} = 'nbins';
            app.default_tags(item).description = 'DOUBLE: The number of bins used in the volumetric histogram as measured by eyecon.';
            item = 19;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'image';
            app.default_tags(item).tag{3} = 'path';
            app.default_tags(item).description = 'STRING: path to image file as captured by eyecon?';
            item = 20;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'numeric';
            app.default_tags(item).tag{3} = 'histogram';
            app.default_tags(item).description = 'VECTOR DOUBLE: The numeric histogram as measured by eyecon.';
            item = 21;
            app.default_tags(item).tag{1} = 'eyecon';
            app.default_tags(item).tag{2} = 'volumetric';
            app.default_tags(item).tag{3} = 'histogram';
            app.default_tags(item).description = 'VECTOR DOUBLE: The volumetric histogram as measured by eyecon.';
            item = 22;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'airflow';
            app.default_tags(item).tag{3} = 'bypass';
            app.default_tags(item).tag{4} = 'pid';
            app.default_tags(item).tag{5} = 'out';
            app.default_tags(item).tag{6} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The bypass airflow in fluid bed.';
            item = 23;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'airflow';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'DOUBLE: The airflow in fluid bed, on (1) or off (0).';
            item = 24;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'airflow';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The airflow in fluid bed, on (1) or off (0).';
            item = 25;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'airflow';
            app.default_tags(item).tag{3} = 'pid';
            app.default_tags(item).tag{4} = 'out';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The airflow in fluid bed.';
            item = 26;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'airflow';
            app.default_tags(item).tag{3} = 'rate';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The airflow rate in fluid bed.';
            item = 27;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'airflow';
            app.default_tags(item).tag{3} = 'rate';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'DOUBLE: The airflow rate in fluid bed.';
            item = 28; 
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'airflow';
            app.default_tags(item).tag{3} = 'rate';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The airflow rate in fluid bed.';
            item = 29;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'alarm';
            app.default_tags(item).tag{3} = 'occured';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: If alarm occured, yes (1) or no (0).';
	        item = 30;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'atomising';
            app.default_tags(item).tag{3} = 'pressure';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The atomising pressure in fluid bed.';
            item = 31;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'atomising';
            app.default_tags(item).tag{3} = 'pressure';
            app.default_tags(item).tag{4} = 'pid';
            app.default_tags(item).tag{5} = 'out';
            app.default_tags(item).tag{6} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The atomising pressure in fluid bed.';
            item = 32;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'atomising';
            app.default_tags(item).tag{3} = 'pressure';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'DOUBLE: The atomising pressure in fluid bed.';
            item = 33;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'atomising';
            app.default_tags(item).tag{3} = 'pressure';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The atomising pressure in fluid bed.';
	        item = 34;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'exhaust';
            app.default_tags(item).tag{3} = 'temp';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The exhaust temperature in fluid bed.';
            item = 35;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'filter';
            app.default_tags(item).tag{3} = 'pressure';
            app.default_tags(item).tag{4} = 'differential';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE SIGNED: The differential pressure in fluid bed.';
            item = 36;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'inlet';
            app.default_tags(item).tag{3} = 'air';
            app.default_tags(item).tag{4} = 'temp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The inlet airflow temperature in fluid bed.';
            item = 37;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'inlet';
            app.default_tags(item).tag{3} = 'air';
            app.default_tags(item).tag{4} = 'temp';
            app.default_tags(item).tag{5} = 'pid';
            app.default_tags(item).tag{6} = 'out';
            app.default_tags(item).tag{7} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The inlet airflow temperature in fluid bed.';
            item = 38;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'inlet';
            app.default_tags(item).tag{3} = 'air';
            app.default_tags(item).tag{4} = 'temp';
            app.default_tags(item).tag{5} = 'sp';
            app.default_tags(item).description = 'DOUBLE: The inlet airflow temperature in fluid bed.';
            item = 39;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'main';
            app.default_tags(item).tag{3} = 'heater';
            app.default_tags(item).tag{4} = 'air';
            app.default_tags(item).tag{5} = 'temp';
            app.default_tags(item).tag{6} = 'pid';
            app.default_tags(item).tag{7} = 'out';
            app.default_tags(item).tag{8} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The main heater airflow temperature in fluid bed.';
            item = 40;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'main';
            app.default_tags(item).tag{3} = 'heater';
            app.default_tags(item).tag{4} = 'air';
            app.default_tags(item).tag{5} = 'temp';
            app.default_tags(item).tag{6} = 'sp';
            app.default_tags(item).tag{7} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The main heater airflow temperature in fluid bed.';
	        item = 41;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'main';
            app.default_tags(item).tag{3} = 'heater';
            app.default_tags(item).tag{4} = 'temp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The main heater airflow temperature in fluid bed.';
            item = 42;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'phase_number';
            app.default_tags(item).tag{3} = 'ac';
            app.default_tags(item).description = 'NONE';
	        item = 43;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'phase_time';
            app.default_tags(item).tag{3} = 'hours';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'NONE';
            item = 44;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'phase_time';
            app.default_tags(item).tag{3} = 'minutes';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'NONE';
            item = 45;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'phase_time';
            app.default_tags(item).tag{3} = 'secs';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'NONE';
            item = 46;
            app.default_tags(item).tag = 'fluid_bed';
            app.default_tags(item).tag = 'phase_type';
            app.default_tags(item).tag = 'ac';
            app.default_tags(item).description = 'NONE';
            item = 47;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'process_mode';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'is_ready';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'NONE';
            item = 48;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'process_mode';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'NONE';
	        item = 49;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'process_mode';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'do_not_use';
            app.default_tags(item).description = 'NONE';
            item = 50;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'product';
            app.default_tags(item).tag{3} = 'pressure';
            app.default_tags(item).tag{4} = 'differential';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The product differential pressure in fluid bed.';
            item = 51;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'product';
            app.default_tags(item).tag{3} = 'temp';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The product temperature in fluid bed.';
            item = 52;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'remote';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: Remote access to fluid bed is on (1) or off (0).';
            item = 53;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'remote';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'is_ready';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: Remote client to fluid bed is ready (1) or not (0).';
            item = 54;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'shake';
            app.default_tags(item).tag{3} = 'mode';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'NONE';
            item = 55;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'shake';
            app.default_tags(item).tag{3} = 'mode';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'NONE';
            item = 56;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'shake';
            app.default_tags(item).tag{3} = 'pause';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The shake pause time to remove material from the filters, seconds.';
            item = 57;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'shake';
            app.default_tags(item).tag{3} = 'pause';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'DOUBLE: The shake pause time to remove material from the filters, seconds.';
            item = 58;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'shake';
            app.default_tags(item).tag{3} = 'pause';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The shake pause time to remove material from the filters, seconds.';
            item = 59;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'shake';
            app.default_tags(item).tag{3} = 'time';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The shake time to remove material from the filters, seconds.';
            item = 60;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'shake';
            app.default_tags(item).tag{3} = 'time';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'DOUBLE: The shake time to remove material from the filters, seconds.';
            item = 61;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'shake';
            app.default_tags(item).tag{3} = 'time';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The shake time to remove material from the filters, seconds.';
            item = 62;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'spray_pump';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'is_ready';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The spray pump is ready (1) or not (0).';
            item = 63;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'spray_pump';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'DOUBLE: The spray pump.';
            item = 64;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'spray_pump';
            app.default_tags(item).tag{3} = 'on';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The spray pump.';
	        item = 65;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'spray_pump';
            app.default_tags(item).tag{3} = 'speed';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The spray pump speed.';
            item = 66;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'spray_pump';
            app.default_tags(item).tag{3} = 'speed';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'DOUBLE: The spray pump speed.';
            item = 67;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'spray_pump';
            app.default_tags(item).tag{3} = 'speed';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'DOUBLE: The spray pump speed.';
            item = 68;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'watchdog';
            app.default_tags(item).tag{3} = 'counter';
            app.default_tags(item).tag{4} = 'from_plc';
            app.default_tags(item).tag{5} = 'ac';
            app.default_tags(item).description = 'NONE';
            item = 69;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'watchdog';
            app.default_tags(item).tag{3} = 'counter';
            app.default_tags(item).tag{4} = 'to_plc';
            app.default_tags(item).tag{5} = 'sp';
            app.default_tags(item).description = 'NONE';
            item = 70;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'watchdog';
            app.default_tags(item).tag{3} = 'from_plc';
            app.default_tags(item).tag{4} = 'ac';
            app.default_tags(item).description = 'NONE';
            item = 71;
            app.default_tags(item).tag{1} = 'fluid_bed';
            app.default_tags(item).tag{2} = 'watchdog';
            app.default_tags(item).tag{3} = 'to_plc';
            app.default_tags(item).tag{4} = 'sp';
            app.default_tags(item).description = 'NONE';
            item = 72;
            app.default_tags(item).tag{1} = 'level';
            app.default_tags(item).description = 'NONE';
            item = 73;
            app.default_tags(item).tag{1} = 'log';
            app.default_tags(item).description = 'NONE';
            item = 74;
            app.default_tags(item).tag{1} = 'multieye';
            app.default_tags(item).tag{2} = 'ch';
            app.default_tags(item).tag{3} = 'absorbance';
            app.default_tags(item).description = 'VECTOR DOUBLE: The absorbance as measured by multieye.';
            item = 75;
            app.default_tags(item).tag{1} = 'multieye';
            app.default_tags(item).tag{2} = 'ch';
            app.default_tags(item).tag{3} = 'bl';
            app.default_tags(item).tag{4} = 'corr_bsorbance';
            app.default_tags(item).description = 'VECTOR DOUBLE: The corrected absorbance 1 as measured by multieye.';
            item = 76;
            app.default_tags(item).tag{1} = 'multieye';
            app.default_tags(item).tag{2} = 'ch';
            app.default_tags(item).tag{3} = 'intensity';
            app.default_tags(item).description = 'VECTOR DOUBLE: The intensity as measured by multieye.';
            item = 77;
            app.default_tags(item).tag{1} = 'multieye';
            app.default_tags(item).tag{2} = 'ch';
            app.default_tags(item).tag{3} = 'reflectance';
            app.default_tags(item).description = 'VECTOR DOUBLE: The reflectance as measured by multieye.';
            item = 78;
            app.default_tags(item).tag{1} = 'Nozzle';
            app.default_tags(item).tag{2} = 'airflow_sensor';
            app.default_tags(item).tag{3} = 'current';
            app.default_tags(item).tag{4} = 'AirflowRate';
            app.default_tags(item).description = 'DOUBLE SIGNED: The nozzle airflow in fluid bed probed by sensor.';
            item = 79;
            app.default_tags(item).tag{1} = 'Nozzle';
            app.default_tags(item).tag{2} = 'airflow_sensor';
            app.default_tags(item).tag{3} = 'units';
            app.default_tags(item).description = 'NONE';
            item = 80;
            app.default_tags(item).tag{1} = 'results_path';
            app.default_tags(item).description = 'NONE';
	        item = 81;
            app.default_tags(item).tag{1} = 'scales';
            app.default_tags(item).tag{2} = 'connection';
            app.default_tags(item).tag{3} = 'alive';
            app.default_tags(item).description = 'DOUBLE: Connection is alive (1) or not (0).';
            item = 82; 
            app.default_tags(item).tag{1} = 'scales';
            app.default_tags(item).tag{2} = 'start';
            app.default_tags(item).description = 'DOUBLE: Scales is started (1) or not (0).';
            item = 83; 
            app.default_tags(item).tag{1} = 'scales';
            app.default_tags(item).tag{2} = 'weight';
            app.default_tags(item).description = 'DOUBLE: Measured weight.';
	        item = 84;
            app.default_tags(item).tag{1} = 'thermoalert';
            app.default_tags(item).tag{2} = 'connection';
            app.default_tags(item).tag{3} = 'state';
            app.default_tags(item).description = 'DOUBLE: Connection is alive (1) or not (0).';
            item = 85; 
            app.default_tags(item).tag{1} = 'thermoalert';
            app.default_tags(item).tag{2} = 'humidity';
            app.default_tags(item).description = 'DOUBLE: Humidity';
            item = 86; 
            app.default_tags(item).tag{1} = 'thermoalert';
            app.default_tags(item).tag{2} = 'temperature';
            app.default_tags(item).description = 'DOUBLE: Temperature';
        end % end function 
        % -----------------------------------------------------------------
        function app = features_varUser(app) % creates variable parsing selector 
            varUser.varUser = uifigure('Visible', 'off');
            varUser.varUser.Position = [100 100 1067 644];
            varUser.varUser.Name = 'Reporting/Selecting variables to be included for further processing ....';
            varUser.varUser.Scrollable = 'on';
            varUser.varUser.CloseRequestFcn = @(src,event)close_varUser(src);
            varUser.out = uitable(varUser.varUser);
            varUser.out.ColumnName = {'Variable tag'; 'Short description of variable'; 'Filename'; 'Ignore(?)'};
            varUser.out.RowName = {};
            varUser.out.ColumnEditable = [false false false true];
            varUser.out.ColumnSortable = [true false true true];
            varUser.out.Position = [21 17 1035 469];
            varUser.Directions = uilabel(varUser.varUser);
            varUser.Directions.FontWeight = 'bold';
            varUser.Directions.Position = [22 610 72 22];
            varUser.Directions.Text = 'Directions:';
            varUser.info = uitextarea(varUser.varUser);
            varUser.info.Editable = 'off';
            varUser.info.Position = [22 495 1036 107];
            varUser.info.Value = {''; 
                ['The list of variable(s) collected from the ' ...
                'user selected file(s) are shown bellow. ']; ''; 
                ['Please select variable(s) to be excluded ' ...
                'from further processing using the "Ignore(?)" tab, ' ...
                'or confirm to continue with default settings. ']; ''; 
                ['If you close the current window without confirming ' ...
                'your selections once prompted, the default settings will be used.']};
            app.tools{2} = varUser;
            % ------------------------------------------------------------
            function close_varUser(fig) % close plotter  
                msg = 'Continue with defaults or apply user selection(s)?';
                title = 'Confirm close';
                selection = uiconfirm(fig,msg,title, ...
                    'Options',{'Close/Continue','Confirm change(s) - if any','Cancel'}, ...
                    'DefaultOption',1,'CancelOption',3);
                switch selection
                    case 'Close/Continue'
                        app = ignorelist(app);
                        app.tools{2}.varUser.Visible = 'off';
                    case 'Confirm change(s) - if any'
                        app = ignorelist(app);
                        app.tools{2}.varUser.Visible = 'off';
                        return % not implemented yet
                    case 'Cancel'
                        return
                end % end switch
            end % end function
            % ------------------------------------------------------------
        end % end of function 
        % -----------------------------------------------------------------
        function app = user_sets_ignore_list(app) % populates data to each node
                app = tags_default(app); % populates default tags
                [~, thismanytags] = size(app.default_tags); 
                if iscell(app.import_file_names) 
                    [~, thismanyfiles] = size(app.import_file_names);
                    rows = 0; 
                    for file = 1 : thismanyfiles
                        [~, variables] = size(app.dataset_bckup{1,file});
                        rows = rows + variables;
                    end % end for 
                else
                    file = 1;
                    thismanyfiles = 1;
                    [~, variables] = size(app.dataset_bckup{1,file});
                    rows = variables;
                end % end if
                app.bigTable = cell(rows, 4);
                row = 0;
                for file = 1 : thismanyfiles
                    [~, variables] = size(app.dataset_bckup{1,file});
                    for variable = 1 : variables
                        row = row + 1;
                        app.bigTable{row,1} = app.dataset_bckup{1,file}{1,variable};
                        app.bigTable{row,4} = 'Yes/No?';
                        if iscell(app.import_file_names) 
                            app.bigTable{row,3} = app.import_file_names{1,file};
                        else 
                            app.bigTable{row,3} = app.import_file_names;
                        end % end if 
                        for item = 1 : thismanytags
                            if iscell(app.default_tags(item).tag) 
                                [~, ele] = size(app.default_tags(item).tag);
                                counter = 0;
                                for el = 1 : ele
                                    if contains(app.dataset_bckup{1,file}{1,variable}, app.default_tags(item).tag{el})
                                        counter = counter + 1;
                                    end % end if 
                                end % end for  
                                if counter == ele
                                    app.bigTable{row,2} = app.default_tags(item).description; 
                                    break;
                                end % end for
                            else
                                if contains(app.dataset_bckup{1,file}{1,variable}, app.default_tags(item).tag)
                                    app.bigTable{row,2} = app.default_tags(item).description;
                                    break; 
                                end % end if 
                            end % end if 
                        end % end for 
                    end  % end for 
                end % end for 
        end % function
        % -----------------------------------------------------------------
        function app = activate_toolkits(app) % activates toolkits after ignore list is updated 
            app = clean(app); 
            %app = export2WS_do(app);    
            %app.Reports_Status.Value = [text, ' Activating toolkits ....'];
            %app.Reports_Status.Value = 'Enabling plot tool ....';
            app = features_plottool(app); 
            app = create_file_var(app);
            app = assign_node_data(app);
            app.Button_tools_Plot.Enable = 'on';
            %app.Reports_Status.Value = 'Enabled plot tool.';
            %app.Reports_Status.Value = 'Enabling statistics tool ...';
            %app.tools{2} = enable_statisics(app);
            %app.Reports_Status.Value = 'Enabled statistics tool ...';
            %app.Button_tools_Statistics.Enable = 'on';
            app.Reports_Status.Value = 'App ready to use.';
        end % end function 
        %------------------------------------------------------------------
        
        % -----------------------------------------------------------------
        
        % -----------------------------------------------------------------
        
        % -----------------------------------------------------------------
    end % end methods
    % 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_files_Select
        function app_button_files_select_pushed(app, event)
             [app, text] = files_select(app);
             app.Reports_Status.Value = text;
             if contains(text, 'cancel')
                 app.Reports_files_List.Value = 'no file(s) selected! Click "Add files(s)" button to add file(s).';
                 app.Reports_Status.Value = text;
                 app.Button_files_Upload.Enable = 'off';
             else
                 [app, app.Reports_files_List.Value] = files_list_filenames(app);
                 app.Reports_Status.Value = [text, ' Click "Upload file(s)" to import data.'];
                 app.Button_files_Upload.Enable = 'on';
             end % end if 
             app.Button_file_whatsin.Enable = 'off';
             app.Button_toolkit_on.Enable = 'off';
             if (~isempty(app.tools)) % 
                 app.Button_tools_Plot.Enable = 'off';
             end % end if 
        end

        % Button pushed function: Button_files_Upload
        function app_button_files_upload_pushed(app, event)
            app.Reports_Status.Value = 'Importing file(s) ....';
            app = files_upload(app);
            [app, text] = files_what_types(app, 'Imported');
            app.Reports_Status.Value = text;
            app.Reports_Status.Value = 'Use "(Re)view tags" to see/update tags otherwise "Activate toolkits".';
            app.dataset_bckup = app.dataset; % shadow copy - backup
            app.Button_files_Upload.Enable = 'off';
            app.Button_files_Select.Enable = 'on';
            app.Button_file_whatsin.Enable = 'on';
            app.Button_toolkit_on.Enable = 'on';
        end

        % Close request function: main
        function app_main_close(app, event)
            app.Reports_Status.Value = 'Closing app, waiting for user confirmation ....';
            closeapp(app);
        end

        % Button pushed function: Button_tools_Plot
        function app_main_tools_plotter_pushed(app, event)
            app.tools{1}.plottool.Visible = 'on'; % plot tool button : Show the figure after all components are create 
            app.Reports_Status.Value = 'Plot tool in-use ....';
            while (ishandle(app.tools{1}.plottool)) % if plotter is active
                pause(0.001);
                if isequal(app.tools{1}.plottool.Visible, 'off')
                    app.Reports_Status.Value = 'Closed plot tool, returned to main app. App ready to use.';
                    break
                end % end if 
            end % end while 
            app.Reports_Status.Value = 'Closed plot tool, returned to main app. App ready to use.';
            %app = enable_plottool(app);
            %app.tools{1}.plottool.Visible = 'off';
        end

        % Button pushed function: Button_file_whatsin
        function app_button_files_whatsin_pushed(app, event)
            app.Button_files_Upload.Enable = 'off';
            app.Button_files_Select.Enable = 'on';
            app.Button_file_whatsin.Enable = 'on';
            app.Reports_Status.Value = '(Re)view tag tool in use ....';
            app = features_varUser(app); 
            app = user_sets_ignore_list(app);
            app.tools{2}.out.ColumnFormat = {[] [] [] {'Yes' 'No'}}; 
            app.tools{2}.out.Data = app.bigTable; 
            app.tools{2}.varUser.Visible = 'on';
            while (ishandle(app.tools{2}.varUser)) % if varUser is active
                pause(0.001);
                if isequal(app.tools{2}.varUser.Visible, 'off')
                    if (~isempty(app.tools{1})) % 
                        app.Reports_Status.Value = 'Closed "(Re)view tag" tool.';
                        break;
                    else % activate 
                        app.Reports_Status.Value = 'Closed "(Re)view tag" tool. Activating toolkits ....';
                        app.Button_toolkit_on.Enable = 'off';
                        app = activate_toolkits(app);
                        break;
                    end % end of if  
                end  % end if 
            end % end while 
        end

        % Button pushed function: Button_toolkit_on
        function app_button_toolkit_on_pushed(app, event)
            app.Reports_Status.Value = 'Activating toolkits [:> with default] ....';
            app.Button_files_Upload.Enable = 'off';
            app.Button_files_Select.Enable = 'on';
            app.Button_file_whatsin.Enable = 'on';
            app.Button_toolkit_on.Enable = 'off';
            app.Reports_Status.Value = 'Activating toolkits ....';
            app = ignorelist(app); % temporary until table is updated 
            app = activate_toolkits(app);
        end

        % Button pushed function: Button_tools_Spectroscopy
        function app_button_tools_spectroscopy_pushed(app, event)
            % empty for now 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'Data Analytics Tool';
            app.main.CloseRequestFcn = createCallbackFcn(app, @app_main_close, true);
            app.Button_files_Select = uibutton(app.main, 'push');
            app.Button_files_Select.ButtonPushedFcn = createCallbackFcn(app, @app_button_files_select_pushed, true);
            app.Button_files_Select.Tooltip = {'Opens a new window to select files. '};
            app.Button_files_Select.Text = 'Add file(s)';
            app.Button_files_Upload = uibutton(app.main, 'push');
            app.Button_files_Upload.ButtonPushedFcn = createCallbackFcn(app, @app_button_files_upload_pushed, true);
            app.Button_files_Upload.Enable = 'off';
            app.Button_files_Upload.Tooltip = {'Click to import selected files. '};
            app.Button_files_Upload.Text = 'Upload file(s)';
            app.Button_tools_Plot = uibutton(app.main, 'push');
            app.Button_tools_Plot.ButtonPushedFcn = createCallbackFcn(app, @app_main_tools_plotter_pushed, true);
            app.Button_tools_Plot.Enable = 'off';
            app.Button_tools_Plot.Tooltip = {'Opens plot tool window. '};
            app.Button_tools_Plot.Text = 'Plot tool';
            app.Button_tools_Statistics = uibutton(app.main, 'push');
            app.Button_tools_Statistics.Enable = 'off';
            app.Button_tools_Statistics.Tooltip = {'Opens statistics tool window. '};
            app.Button_tools_Statistics.Text = 'Statistics tool';
            app.SelectedfileLabel = uilabel(app.main);
            app.SelectedfileLabel.Text = 'List of selected file(s):';
            app.Reports_files_List = uitextarea(app.main);
            app.Reports_files_List.Editable = 'off';
            app.Reports_files_List.Value = {'No file(s) selected!'};
            app.MessageLabel = uilabel(app.main);
            app.MessageLabel.Text = 'Message:';
            app.Reports_Status = uitextarea(app.main);
            app.Reports_Status.Editable = 'off';
            app.Reports_Status.Value = {'Click "Select file(s)" button to select and add file(s).'};
            app.Button_file_whatsin = uibutton(app.main, 'push');
            app.Button_file_whatsin.ButtonPushedFcn = createCallbackFcn(app, @app_button_files_whatsin_pushed, true);
            app.Button_file_whatsin.Enable = 'off';
            app.Button_file_whatsin.Tooltip = {'Click to import selected files. '};
            app.Button_file_whatsin.Text = '(Re)view tags';
            app.Button_toolkit_on = uibutton(app.main, 'push');
            app.Button_toolkit_on.ButtonPushedFcn = createCallbackFcn(app, @app_button_toolkit_on_pushed, true);
            app.Button_toolkit_on.Enable = 'off';
            app.Button_toolkit_on.Tooltip = {'Click to import selected files. '};
            app.Button_toolkit_on.Text = 'Activate toolkit';
            app.Button_tools_Spectroscopy = uibutton(app.main, 'push');
            app.Button_tools_Spectroscopy.ButtonPushedFcn = createCallbackFcn(app, @app_button_tools_spectroscopy_pushed, true);
            app.Button_tools_Spectroscopy.Enable = 'off';
            app.Button_tools_Spectroscopy.Tooltip = {'Opens statistics tool window. '};
            app.Button_tools_Spectroscopy.Text = 'Spectroscopy tool';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = toolkitUL

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.main)
            else

                % Focus the running singleton app
                figure(runningApp.main)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end